classdef BounceDiagramFigureCreation2 < matlab.apps.AppBase
    %BOUNCEDIAGRAMFIGURECREATION2 Bounce Diagram Simulation
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2025
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia Furse
    %   (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   Simulates a bounce diagram with fully customizable load and source
    %   resistances. Allows full control over the time and designated
    %   position for the Voltage vs. Time and Voltages vs. Position graphs.
    %   Can alternate between a step or pulse signal (1 V).
    %   Assumes that the velocity of propagation is (2/3)*c m/s.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        RLLabel             matlab.ui.control.Label
        RSLabel             matlab.ui.control.Label
        up23cLabel          matlab.ui.control.Label
        Z050Label           matlab.ui.control.Label
        BOUNCEDIAGRAMLabel  matlab.ui.control.Label
        UIAxesBD            matlab.ui.control.UIAxes
        UIAxesVT            matlab.ui.control.UIAxes
        UIAxesVZ            matlab.ui.control.UIAxes
        UIAxesVZ_2          matlab.ui.control.UIAxes
        UIAxesVZ_3          matlab.ui.control.UIAxes
        UIAxesVZ_4          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % PLOTS===========================================================

        % Animated line objects are used to improve animation performance.
        % The slower plot objects will only appear when the animation is
        % paused or finished, allowing the user to select data points. 

        BD % Bounce diagram animated line object
        BD_negative % Bounce diagram animated line object for pulse waves
        temp_BD % temporary Bounce Diagram plot for point interactivity

        % Designated Z position dotted line. Appears on the Bounce Diagram
        Z_des_plot
        Zdp_x  % X values for Designated Z position
        Zdp_y  % Y values for Designated Z position

        % Moments where Voltages changes at the designated Z position
        VChangedPltsBD % Plot handles for Bounce Diagram
        VChangedPltsVT % Plot handles for V vs. T graph
        VChangedTimes

        VT % Voltage vs. Time graph animated line object
        temp_vt % Temporary V vs. t plot for point interactivity

        VZ1 % Voltage vs. Position line object.
        VZ2 
        VZ3
        VZ4

        % V vs. T vs. Z span 3 dimensions. The user may alter t_des and
        % z_des using the sliders to see each "slice" of this 3-dimensional
        % data.
        t_des = 0;  % Designated time for plotting (V vs. z)
        t_des_idx = 1; % Initialize the corresponding index for t_des.
        z_des = 0; % Designated position for plotting (V vs. t)
        z_des_idx = 1; % Initialize the corresponding index for z_des


        % SIMULATION CHARACTERISTICS======================================
        Z0 = 50; % Characteristic impedance of transmission line (Ω)
        RL = 100;  % Resistance at the load (Ω)
        RS = 25;  % Resistance at the source (Ω)
        rC_L  % Reflection coefficient at the load
        rC_s  % Reflection coefficient at the source

        bounceType = "Step";

        Vs = 1;  % Source Voltage (V)
        V_ref_ary = zeros(1, 8) % Array of reflected voltages for the simulation

        V_tot = 0;  % Current total voltage. Initialized at zero

        % DATA============================================================

        % Voltage vs. Position data:
        zData
        vData

        BD_ary  % 2D Array that holds incident voltage vs. time information
        BD_negative_ary % 2D Array that holds incident negative voltgate vs time information. Helps show the pulse wave. 
        VZT_ary  % 3D Array that holds V vs. z vs t information
        t_size = 801 % Number of time increments
        z_size = 100  % Number of spatial increments
        t_ary % Holds all time

        % Determines if the simulation is running or not
        Play = false

    end
    
    methods (Access = private)
        
        % Animates the bounce diagram, V vs. t and V vs. z graphs
        % simultaneously by incrementing through a time array and plotting
        % the corresponding graphs.
        function startAnimation(app)
            % Plays the animation starting at where t_des is. 
            for t = app.t_des_idx:app.t_size

                % Simulation pause/play control
                if ~app.Play
                    break;
                end

                % Update t_des and its corresponding index:
                app.t_des = app.t_ary(t);
                app.t_des_idx = t;

                % Bounce diagram animated line update:
                addpoints(app.BD, app.BD_ary(t, 2), app.BD_ary(t, 1));

                % Negative forward voltage animated line update: 
                if app.bounceType == "Pulse"
                    addpoints(app.BD_negative, app.BD_negative_ary(t, 2), app.BD_negative_ary(t, 1));
                end

                % Draw V vs. t diagram:
                addpoints(app.VT, app.VZT_ary(app.z_des_idx, 3, t), app.VZT_ary(app.z_des_idx, 1, t))

                % Draw V vs. Z diagram:
                app.VZ.XData = app.VZT_ary(:, 2, t);
                app.VZ.YData = app.VZT_ary(:, 3, t);

                % Update the time slider:
                app.TimeTnsSlider.Value = 40 - app.t_des;

                % Update the dotted lines that indicate when the voltage
                % changes at the designated position:
                set(app.VChangedPltsBD(app.VChangedTimes < app.t_des), 'Visible', 'on')
                set(app.VChangedPltsBD(app.VChangedTimes > app.t_des), 'Visible', 'off')

                % Update the dotted lines for V vs. t graph:
                set(app.VChangedPltsVT(app.VChangedTimes < app.t_des), 'Visible', 'on')
                set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

                % The pause function partially dictates the framerate of
                % the animation and facilitates smoother performance. "drawnow
                % limitrate" updates the graphics while also limiting the
                % framerate to ensure performance. If you want a slower or
                % faster animation and if your computer can handle it,
                % consider changing the value in pause and/or removing
                % "limitrate".
                pause(0.02)
                drawnow limitrate;
            end

            if app.t_des == 40
                % At end of animation, the voltage vs time graph should be
                % interactable and the bounce diagram:
                x_ary = squeeze(app.VZT_ary(app.z_des_idx, 3, 1:app.t_des_idx));
                y_ary = squeeze(app.VZT_ary(app.z_des_idx, 1, 1:app.t_des_idx));
                set(app.temp_vt,'XData', x_ary, 'YData', y_ary);

                set(app.temp_BD, 'XData', app.BD_ary(1:app.t_des_idx,2), 'YData', app.BD_ary(1:app.t_des_idx,1));
    
                % Ensure the temporary Voltage vs. time plot is
                % interactable and updated as well as temporary bounce
                % diagram plot:
                set(app.temp_vt, 'Visible', 'on');
                set(app.temp_BD, 'Visible', 'on');
    
                % Disable the play/pause button:
                app.PLAYButton.Enable = 'off';
            end

        end

        % Computes the data for the bounce diagram, the V vs. t vs. z
        % array, and for the reflected voltages. 
        function initializeProperties(app)
            % Compute reflection coefficients:
            app.rC_L = (app.RL - app.Z0)/(app.RL + app.Z0);
            app.rC_s = (app.RS - app.Z0)/(app.RS + app.Z0);

            % Compute reflected voltages:

            % Initialize V_ref (V1+):
            app.V_ref_ary(1) = app.Vs * (app.Z0/(app.Z0 + app.RS));

            % Reflected voltages initialized into an array
            for n = 2:8
                % If even index: (toward source)
                if (mod(n,2) == 0)
                    app.V_ref_ary(n) = app.V_ref_ary(n-1) * app.rC_L;
                else  % odd index condition: (toward load)
                    app.V_ref_ary(n) = app.V_ref_ary(n-1) * app.rC_s;
                end
            end

            % Initialize V_tot:
            app.V_tot = 0;

            % Construct VZT Array:
            if app.bounceType == "Step"
                createVZTArray(app)
            elseif app.bounceType == "Pulse"
                createVZTArrayPulse(app)
            end

            % Construct t_ary:
            app.t_ary = app.VZT_ary(1, 1, :);

        end
        
        % Written by ChatGPT: Searches through an array to find the closest
        % value and returns the index of that value. Allows variable t_des
        % and z_des by the user.

        function idx = findClosestIndex(~, array, value)
            [~, idx] = min(abs(array - value)); % Find the index of the minimum absolute difference
        end

        
        % Function that looks through the bounce diagram and determines
        % all the times where the voltage at z_des changes. Returns the
        % array of times where the voltage at z_des changes.
        function results = findVChangeTimes(app)
            % Since z_des won't equal exactly a position value in BD_ary, I
            % introduce some tolerance:
            tolerance = .01;

            % Find all instances where BD_ary is this position and return:
            % results_idx = find(app.BD_ary(:,2) == intersect_position)
            results_idx = abs(app.BD_ary(:,2) - app.z_des) <= tolerance;

            results = app.BD_ary(results_idx(:),1);
        end
        
    function createVZTArray(app)
            % VZT_ary is a 3D array that holds all voltage, position, and
            % time data for a given load and source resistances. I have
            % constructed it by concatenating 2D array "slices" where each
            % slice is a given time with its corresponding voltages at
            % their designated positions along the transmission line.
            % Constructing this 3D array allows 1 data structure to exist
            % as a reference for animating, changing the designated time
            % and position values, and allowing plotting for both the V vs.
            % z and V vs. t graphs.

            % Construct slices of VZT_ary (V vs. Z) to make 3D array:
            % "A" will be the temporary "slice" that will be concatenated
            % to construct the 3D array:
            A = zeros(app.z_size, 3);
            timeary = linspace(0,40,app.t_size);

            % Establish V_tot:
            app.V_tot = app.V_ref_ary(1);

            % The second column of the slice will remain constant
            % throughout the whole process:
            A(:,2) = linspace(0,1,app.z_size);

            % Each "bounce" will be handled separately.

            % Notice that Each slice A is constructed as follows:
            % The first column is a single time value for the slice.
            % The second column holds all the position values from 0 to 1.
            % The third column will hold the voltage values.
            %
            % Every position in the second column has its own corresponding
            % voltage in the right column. An individual voltage is updated
            % every time a slice is created. In essence, the code is only
            % concerned about updating the leading voltage back and forth
            % beetween the bounces and ensuring the corresponding time is
            % updated as well.

            % First bounce
            for t = 1:100
                A(:,1) = timeary(t);
                A(t,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(2);

            % Fix endpoint:
            app.VZT_ary(end, 3, 100) = app.V_tot;

            % Second bounce
            for t = 101:200
                A(:,1) = timeary(t);
                A(200 - t + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(3);

            % Fix endpoint:
            app.VZT_ary(1,3,200) = app.V_tot;

            % Third bounce
            for t = 201:300
                A(:,1) = timeary(t);
                A(t - 201 + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(4);

            % Fix endpoint:
            app.VZT_ary(end,3,300) = app.V_tot;

            % Fourth bounce
            for t = 301:400
                A(:,1) = timeary(t);
                A(400 - t + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(5);

            % Fix endpoint:
            app.VZT_ary(1,3,400) = app.V_tot;

            % Fifth bounce
            for t = 401:500
                A(:,1) = timeary(t);
                A(t - 401 + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(6);

            % Fix endpoint:
            app.VZT_ary(end,3,500) = app.V_tot;

            % Sixth bounce
            for t = 501:600
                A(:,1) = timeary(t);
                A(600 - t + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(7);

            % Fix endpoint:
            app.VZT_ary(1,3,600) = app.V_tot;

            % Seventh bounce
            for t = 601:700
                A(:,1) = timeary(t);
                A(t - 601 + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(8);

            % Fix endpoint:
            app.VZT_ary(end,3,700) = app.V_tot;

            % Eight bounce
            for t = 701:800
                A(:,1) = timeary(t);
                A(800 - t + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Final slice:
            A(:,1) = timeary(801);
            app.VZT_ary = cat(3,app.VZT_ary,A);

            % To see waht each "slice" looks like, uncomment the line below
            % and run the app.

            % disp(app.VZT_ary(:,:,300)) % Slice at time index = 300.
        end

        
        function createVZTArrayPulse(app)
            % Creates the pulse by adding a shifted, negative version of
            % the voltage column of VZT_ary back onto itself. The shift is
            % the pulse width.

            createVZTArray(app)

            width = 2.5;  % Pulse width in ns (See below about adjusting)
            n = width * 20; % Shifting index

            VZT_negative = -1 * cat(3, zeros(100,1,n), app.VZT_ary(:, 3, 1:(end-n)));

            app.VZT_ary(:,3,:) = app.VZT_ary(:,3,:) + VZT_negative(:,1,:); % SHOULD ONLY ADD THE VOLTAGES, NOT ANYTHING ELSE

            % % Uncomment to automaticlaly adjust pulse width for bounce diagram:
            % % Fill in the BD_ary information for the pulse:
            % app.BD_negative_ary = zeros(app.t_size, 2);  % Initialize the array size
            % app.BD_negative_ary(:,1) = linspace(0, 40, app.t_size); % First column will have the time stamps
            % app.BD_negative_ary(:,2) = cat(1, zeros(n, 1), app.BD_ary(1:end-n, 2));

        end
        
        function AdjustAxesVT(app)
            % Automatically adjusts the axes of the voltage vs. time graph

            maxVoltage = max(app.VZT_ary(app.z_des_idx,3,:));
            minVoltage = min(app.VZT_ary(app.z_des_idx,3,:));

            padding = .1;

            app.UIAxesVT.XLim = [minVoltage-padding maxVoltage+padding];
        end
        
        function stillCreation(app)
            % STILL CREATION PARAMETERS:
            app.t_des = 40; % Designated time
            app.t_des_idx = findClosestIndex(app, app.t_ary, app.t_des);
            app.z_des = 0.5; % Designated Position
            z_ary = app.VZT_ary(:, 2, 1);
            app.z_des_idx = findClosestIndex(app, z_ary, app.z_des);
            app.RL = 75;
            app.bounceType = 'Pulse';

            % Designated times for V vs. Z graphs:
            t1 = 3.25;
            t2 = 8.25;
            t3 = 13.25;
            t4 = 18.25;

            % Update figure lables:
            app.RLLabel.Text = "RL = " + app.RL + " Ω";
            app.RSLabel.Text = "RS = " + app.RS + " Ω";

            app.UIAxesVT.Title.String = "V(t) at z = " + app.z_des + " (m)";
            app.UIAxesVZ.Title.String = "V(z) at t = " + app.t_des + " (ns)";

            % Reusing these functions (edited) to update graphics according
            % to parameters:
            DropDownValueChanged(app);
            PositionZSliderValueChanging(app);
            PositionZSliderValueChanged(app);

            % Update each V vs. Z graph according to ti
            app.VZ1.XData = app.VZT_ary(:, 2, findClosestIndex(app, app.t_ary, t1));
            app.VZ1.YData = app.VZT_ary(:, 3, findClosestIndex(app, app.t_ary, t1));

            app.VZ2.XData = app.VZT_ary(:, 2, findClosestIndex(app, app.t_ary, t2));
            app.VZ2.YData = app.VZT_ary(:, 3, findClosestIndex(app, app.t_ary, t2));

            app.VZ3.XData = app.VZT_ary(:, 2, findClosestIndex(app, app.t_ary, t3));
            app.VZ3.YData = app.VZT_ary(:, 3, findClosestIndex(app, app.t_ary, t3));

            app.VZ4.XData = app.VZT_ary(:, 2, findClosestIndex(app, app.t_ary, t4));
            app.VZ4.YData = app.VZT_ary(:, 3, findClosestIndex(app, app.t_ary, t4));

            % Set the titles of each of the graphs:
            formattedStr = sprintf("V(z) at t = %.2f (ns)", t1);
            title(app.UIAxesVZ, formattedStr);
            formattedStr = sprintf("V(z) at t = %.2f (ns)", t2);
            title(app.UIAxesVZ_2, formattedStr);
            formattedStr = sprintf("V(z) at t = %.2f (ns)", t3);
            title(app.UIAxesVZ_3, formattedStr);
            formattedStr = sprintf("V(z) at t = %.2f (ns)", t4);
            title(app.UIAxesVZ_4, formattedStr);

            % Ensure the axis show the full wave:
            AdjustAxesVZ(app)

        end

        function AdjustAxesVZ(app)
            % Adjusts the y-limits of the VZ graph for better display.

            maxVoltage = max(max(app.VZT_ary(:,3,:)));
            minVoltage = min(min(app.VZT_ary(:,3,:)));

            padding = .1;

            app.UIAxesVZ.YLim = [minVoltage - padding maxVoltage + padding];
            app.UIAxesVZ_2.YLim = [minVoltage - padding maxVoltage + padding];
            app.UIAxesVZ_3.YLim = [minVoltage - padding maxVoltage + padding];
            app.UIAxesVZ_4.YLim = [minVoltage - padding maxVoltage + padding];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ensures that the v vs. z graph show all the data: (shows the
            % pulses better)
            % axis(app.UIAxesVZ, 'normal')
            
            % Ensure axes are locked at these bounds:  
            app.UIAxesBD.XLim = [0 1];
            app.UIAxesVZ.XLim = [0 1];

            % Ensures The Bounce diagram can show z_des and
            % intersections. Ensures that the temporary plot can show on
            % the V vs. t graph as well. 
            hold(app.UIAxesBD, 'on')
            hold(app.UIAxesVT, 'on')

            % Ensure you cannot click and drag the BD diagram:
            zoom(app.UIAxesBD, 'off');   % Disable zooming
            pan(app.UIAxesBD, 'off');    % Disable panning

            % Animated line object initialization:

            % Bounce Diagram animated line object:
            app.BD = animatedline(app.UIAxesBD, 'Color', 'b', 'LineWidth', 2);
            app.BD_negative = animatedline(app.UIAxesBD, 'Color', [0.68, 0.85, 0.9, .75], 'LineWidth', 2);

            % Construct Bounce Diagram array: The first column will have
            % all the time values. The second column will have the position
            % of the incident voltage. 
            app.BD_ary = zeros(app.t_size, 2);  % Initialize the array size
            app.BD_ary(:,1) = linspace(0, 40, app.t_size); % First column will have the time stamps

            % This block of code fills in the position of the incident
            % voltage. It "draws" a line going back and forth between the
            % ends of the transmission line.
            app.BD_ary(1:101, 2) = linspace(0,1,101);
            app.BD_ary(101:201, 2) = linspace(1,0,101);
            app.BD_ary(201:301, 2) = linspace(0,1,101);
            app.BD_ary(301:401, 2) = linspace(1,0,101);
            app.BD_ary(401:501, 2) = linspace(0,1,101);
            app.BD_ary(501:601, 2) = linspace(1,0,101);
            app.BD_ary(601:701, 2) = linspace(0,1,101);
            app.BD_ary(701:801, 2) = linspace(1,0,101);

            % Fill in the BD_ary information for the pulse:
            app.BD_negative_ary = zeros(app.t_size, 2);  % Initialize the array size
            app.BD_negative_ary(:,1) = linspace(0, 40, app.t_size); % First column will have the time stamps

            n = 50; % Shift by 50 to the right
            app.BD_negative_ary(:,2) = cat(1, zeros(n, 1), app.BD_ary(1:end-n, 2));

            % Voltage vs. time animated line object
            app.VT = animatedline(app.UIAxesVT, 'Color', 'b', 'LineWidth', 2);

            % Temporary voltage vs. time plot object:
            app.temp_vt = plot(app.UIAxesVT, nan, nan, 'Color', 'b', 'LineWidth', 2);
            % Customize V vs. position data tips:
            app.temp_vt.DataTipTemplate.DataTipRows(1).Label = 'Voltage (V):';
            app.temp_vt.DataTipTemplate.DataTipRows(2).Label = 'Time (ms):';

            % Temporary bounce diagram plot object:
            app.temp_BD = plot(app.UIAxesBD, nan, nan, 'Color', 'b', 'LineWidth', 2);
            % Customize data tips:
            app.temp_BD.DataTipTemplate.DataTipRows(1).Label = 'Position (m):';
            app.temp_BD.DataTipTemplate.DataTipRows(2).Label = 'Time (ms):';

            % (Voltage vs. position) plot object (plot allows custom data
            % tips, while the LINE object does not).
            t = linspace(0, 5, 5/.2);
            app.zData = zeros(1, length(t));
            app.vData = zeros(1, length(app.zData));
            app.VZ1 = plot(app.UIAxesVZ, app.zData, app.vData, 'Color', 'b', 'LineWidth', 2);
            app.VZ2 = plot(app.UIAxesVZ_2, app.zData, app.vData, 'Color', 'b', 'LineWidth', 2);
            app.VZ3 = plot(app.UIAxesVZ_3, app.zData, app.vData, 'Color', 'b', 'LineWidth', 2);
            app.VZ4 = plot(app.UIAxesVZ_4, app.zData, app.vData, 'Color', 'b', 'LineWidth', 2);

            % % Customize V vs. position data tips:
            % app.VZ.DataTipTemplate.DataTipRows(1).Label = 'Position (m):';
            % app.VZ.DataTipTemplate.DataTipRows(2).Label = 'Voltage (V):';

            % Plot Z_des on bounce diagram:
            app.Zdp_x = ones(100) * app.z_des;
            app.Zdp_y = linspace(0, 40, size(app.Zdp_x, 1));
            app.Z_des_plot = plot(app.UIAxesBD, app.Zdp_x, app.Zdp_y, 'PickableParts', 'none');

            % Compute BD and VTZ data:
            initializeProperties(app)

            % Ensure the plot handles for horizontile lines that intersect z_des are
            % initialized:

            % Establish when the voltage at z_des changes 
            app.VChangedTimes = findVChangeTimes(app);
            app.VChangedPltsBD = NaN(1, length(app.VChangedTimes)); % Initialize plot handles
            app.VChangedPltsVT = NaN(1, length(app.VChangedTimes)); % Initialize plot handles

            % Plot the dashed lines:
            z_vals = linspace(app.z_des, 1);
            for i = 1:length(app.VChangedPltsBD)
                t_idx = findClosestIndex(app, app.t_ary, app.VChangedTimes(i));
                v_vals = linspace(-4, app.VZT_ary(app.z_des_idx, 3, t_idx));

                app.VChangedPltsBD(i) = plot(app.UIAxesBD, z_vals, app.VChangedTimes(i).*ones(1,length(z_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7], 'PickableParts', 'none');
                app.VChangedPltsVT(i) = plot(app.UIAxesVT, v_vals, app.VChangedTimes(i).*ones(1,length(v_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7], 'PickableParts', 'none');
            end

            % Set the visibility for each plot to be in line with the
            % current time (all are off at the start):
            set(app.VChangedPltsBD(app.VChangedTimes > app.t_des), 'Visible', 'off')
            set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

            % Create figure:
            stillCreation(app);


        end

        % Callback function
        function PLAYButtonPushed(app, event)
            if ~app.Play
                app.Play = true;
                disp("PLAY button pushed!")
                app.PLAYButton.Text = "PAUSE";
    
                % Remove the temp_vt plot and boucne diagram plot:
                set(app.temp_vt, 'Visible', 'off');
                set(app.temp_BD, 'Visible', 'off');

                startAnimation(app);
            else
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
                disp("PAUSE button pushed")

                % Plot the data for the temporary voltage vs time graph:
                x_ary = squeeze(app.VZT_ary(app.z_des_idx, 3, 1:app.t_des_idx));
                y_ary = squeeze(app.VZT_ary(app.z_des_idx, 1, 1:app.t_des_idx));
                set(app.temp_vt,'XData', x_ary, 'YData', y_ary);
                set(app.temp_BD, 'XData', app.BD_ary(1:app.t_des_idx,2), 'YData', app.BD_ary(1:app.t_des_idx,1));

                % Ensure the temporary Voltage vs. time plot is
                % interactable and updated as well as the temporary bounce diagram plot:      
                set(app.temp_vt, 'Visible', 'on');
                set(app.temp_BD, 'Visible', 'on');
            end
        end

        % Callback function
        function RLEditFieldValueChanged(app, event)
            % Pause the simulation:
            if app.Play == true
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
            end
            
            % Set the new load resistance:
            app.RL = app.RLEditField.Value;

            % Delete current VZT_ary:
            app.VZT_ary = [];

            % Re-initialize the VZT_ary:
            initializeProperties(app)

            % Update graphs:
            clearpoints(app.BD)
            clearpoints(app.VT)

            % Update bounce diagram
            addpoints(app.BD, app.BD_ary(1:app.t_des_idx, 2), app.BD_ary(1:app.t_des_idx, 1));

            % Update V vs. T graph:
            x_ary = squeeze(app.VZT_ary(app.z_des_idx, 3, 1:app.t_des_idx));
            y_ary = squeeze(app.VZT_ary(app.z_des_idx, 1, 1:app.t_des_idx));
            addpoints(app.VT, x_ary, y_ary)

            % Update V vs. Z graph:
            app.VZ.XData = app.VZT_ary(:, 2, app.t_des_idx);
            app.VZ.YData = app.VZT_ary(:, 3, app.t_des_idx);

            % Update interactable temp_vt plots:
            set(app.temp_vt,'XData', x_ary, 'YData', y_ary);

            % Ensure the temp_vt plot and temp_BD plot are visible:
            if ~get(app.temp_vt, 'Visible')
                set(app.temp_vt, 'Visible', 'on');
            end

            % Reset VChangedPltsVT:

            % Reset plots (V vs. T graph):
            if ishandle(app.VChangedPltsVT)
                delete(app.VChangedPltsVT)
            end

            % Initialize plot handles
            app.VChangedPltsVT = NaN(1, length(app.VChangedTimes));

            % Set the plots:
            for i = 1:length(app.VChangedPltsBD)
                t_idx = findClosestIndex(app, app.t_ary, app.VChangedTimes(i));
                v_vals = linspace(-4, app.VZT_ary(app.z_des_idx, 3, t_idx));
                app.VChangedPltsVT(i) = plot(app.UIAxesVT, v_vals, app.VChangedTimes(i).*ones(1,length(v_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7], 'PickableParts', 'none');
            end

            % Set visibility for dotted lines on v vs. t graph:
            set(app.VChangedPltsVT(app.VChangedTimes < app.t_des), 'Visible', 'on')
            set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

            % Adjust limits on V vs. t graph:
            AdjustAxesVT(app)

            % Update graphics
            drawnow limitrate
        end

        % Callback function
        function RSEditFieldValueChanged(app, event)
            % Pause the simulation:
            if app.Play == true
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
            end

            % Get the new Source resistance value:
            app.RS = app.RSEditField.Value;

            % Delete current VZT_ary to create a new one:
            app.VZT_ary = [];

            % Re-establish reflected voltages and compute 3D array:
            initializeProperties(app)

            % Update graphs:
            clearpoints(app.BD)
            clearpoints(app.VT)

            % Update bounce diagram
            addpoints(app.BD, app.BD_ary(1:app.t_des_idx, 2), app.BD_ary(1:app.t_des_idx, 1));

            % Update V vs. T graph:
            x_ary = squeeze(app.VZT_ary(app.z_des_idx, 3, 1:app.t_des_idx));
            y_ary = squeeze(app.VZT_ary(app.z_des_idx, 1, 1:app.t_des_idx));
            addpoints(app.VT, x_ary, y_ary)

            % Update V vs. Z graph:
            app.VZ.XData = app.VZT_ary(:, 2, app.t_des_idx);
            app.VZ.YData = app.VZT_ary(:, 3, app.t_des_idx);

            % Update the temporary Voltage vs. time plot and bound diagram:
            set(app.temp_vt,'XData', x_ary, 'YData', y_ary);

            % Ensure the temp_vt plot is visible and bounce diagram:
            if ~get(app.temp_vt, 'Visible')
                set(app.temp_vt, 'Visible', 'on');
            end

            % Reset VChangedPltsVT:

            % Reset plots (V vs. T graph):
            if ishandle(app.VChangedPltsVT)
                delete(app.VChangedPltsVT)
            end

            % Initialize plot handles
            app.VChangedPltsVT = NaN(1, length(app.VChangedTimes));

            % Set the plots:
            for i = 1:length(app.VChangedPltsBD)
                t_idx = findClosestIndex(app, app.t_ary, app.VChangedTimes(i));
                v_vals = linspace(-4, app.VZT_ary(app.z_des_idx, 3, t_idx));
                app.VChangedPltsVT(i) = plot(app.UIAxesVT, v_vals, app.VChangedTimes(i).*ones(1,length(v_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7], 'PickableParts', 'none');
            end

            % Set visibility for dotted lines on v vs. t graph:
            set(app.VChangedPltsVT(app.VChangedTimes < app.t_des), 'Visible', 'on')
            set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

            % Adjust limits on V vs. t graph:
            AdjustAxesVT(app)

            % Update graphics
            drawnow limitrate
        end

        % Callback function
        function RESETButtonPushed(app, event)
            % Reset t_des and its corresponding index:
            app.t_des = 0;
            app.t_des_idx = 1;
            
            % Reset the slider:
            app.TimeTnsSlider.Value = 40 - app.t_des;

            % Disable Temporary plots:
            set(app.temp_vt, 'Visible', 'off')
            set(app.temp_BD, 'Visible', 'off')

            clearpoints(app.BD) % Clear all points on Bounce diagram
            clearpoints(app.BD_negative) 
            clearpoints(app.VT)  % Clear points on V vs. T graph

            % Set V vs. Z data back to the first "slice"
            app.VZ.XData = app.VZT_ary(:, 2, 1);
            app.VZ.YData = app.VZT_ary(:, 3, 1);

            % Pause the simulation
            app.Play = false;
            app.PLAYButton.Text = "PLAY";

            % Remove all dotted lines:
            set(app.VChangedPltsBD(app.VChangedTimes > app.t_des), 'Visible', 'off')
            set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

            % Ensure the play/pause button is enabled:
            app.PLAYButton.Enable = 'on';

            drawnow;
            

        end

        % Callback function
        function TimeTnsSliderValueChanging(app, event)
            % Pause the simulation:
            if app.Play == true
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
            end

            % Make the temporary plots invisible if it's visible:
            if get(app.temp_vt, 'Visible')
                set(app.temp_vt, 'Visible', 'off')
            end

            if get(app.temp_BD, 'Visible')
                set(app.temp_BD, 'Visible', 'off')
            end
            
            % Establish what the new t_des value is:
            new_t_des = 40 - event.Value;

            % Find the corresponding index to new_t_des
            new_idx = findClosestIndex(app, app.t_ary, new_t_des);

            % Update the graphs: If the new value is greater than the prior,
            % then simply add more points onto the current animated line.
            % If the new value is smaller, then reset the animated line and
            % draw up until the new designated time.
            if new_t_des > app.t_des
                % Update bounce diagram
                addpoints(app.BD, app.BD_ary(app.t_des_idx:new_idx, 2), app.BD_ary(app.t_des_idx:new_idx, 1));

                % Negative forward voltage animated line update: 
                if app.bounceType == "Pulse"
                    addpoints(app.BD_negative, app.BD_negative_ary(app.t_des_idx:new_idx, 2), app.BD_negative_ary(app.t_des_idx:new_idx, 1));
                end

                % Update V vs. T graph:
                x_ary = squeeze(app.VZT_ary(app.z_des_idx, 3, app.t_des_idx:new_idx));
                y_ary = squeeze(app.VZT_ary(app.z_des_idx, 1, app.t_des_idx:new_idx));
                addpoints(app.VT, x_ary, y_ary)

                % Update graphics
                drawnow limitrate
            elseif 40 - event.Value < app.t_des
                % Reset the animated lines:
                clearpoints(app.BD)
                clearpoints(app.BD_negative)
                clearpoints(app.VT)

                % Update bounce diagram
                addpoints(app.BD, app.BD_ary(1:new_idx, 2), app.BD_ary(1:new_idx, 1));

                % Negative forward voltage animated line update:
                if app.bounceType == "Pulse"
                    addpoints(app.BD_negative, app.BD_negative_ary(1:new_idx, 2), app.BD_negative_ary(1:new_idx, 1));
                end

                % Update V vs. T graph:
                x_ary = squeeze(app.VZT_ary(app.z_des_idx, 3, 1:new_idx));
                y_ary = squeeze(app.VZT_ary(app.z_des_idx, 1, 1:new_idx));
                addpoints(app.VT, x_ary, y_ary)

                % Update graphics
                drawnow limitrate
            end

            % Update t_des and its corresponding index:
            app.t_des = new_t_des;
            app.t_des_idx = new_idx;

            % Update V vs. Z graph:
            app.VZ.XData = app.VZT_ary(:, 2, new_idx);
            app.VZ.YData = app.VZT_ary(:, 3, new_idx);

            % Update the dotted lines that indicate when the voltage
            % changes at the designated position:
            set(app.VChangedPltsBD(app.VChangedTimes < new_t_des), 'Visible', 'on')
            set(app.VChangedPltsBD(app.VChangedTimes > new_t_des), 'Visible', 'off')

            % Set visibility for horizontile lines on V vs. T graph:
            set(app.VChangedPltsVT(app.VChangedTimes < new_t_des), 'Visible', 'on')
            set(app.VChangedPltsVT(app.VChangedTimes > new_t_des), 'Visible', 'off')

            
        end

        % Callback function
        function PositionZSliderValueChanging(app, event)
            % Pause the simulation:
            if app.Play == true
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
            end

            % Updated designated Z-position and its corresponding index
            % app.z_des = event.Value;
            z_ary = app.VZT_ary(:, 2, 1);
            app.z_des_idx = findClosestIndex(app, z_ary, app.z_des);

            % Ensure Voltage changed dashed lines are gone on Bounce Diagram:
            if ishandle(app.VChangedPltsBD)
                delete(app.VChangedPltsBD)
            end

            % Ensure Voltage changed dashed lines are gone on V vs. T graph:
            if ishandle(app.VChangedPltsVT)
                delete(app.VChangedPltsVT)
            end
            
            % Ensure that temp_vt and temp_BD plot is interactable:
            set(app.temp_vt, 'Visible', 'on');
            set(app.temp_BD, 'Visible', 'on');

            % Update dotted line of where z_des is on bounce diagram
            app.Zdp_x = ones(100, 1) .* app.z_des;
            app.Zdp_y = linspace(0, 40, size(app.Zdp_x, 1));
            set(app.Z_des_plot, 'XData', app.Zdp_x, 'YData', app.Zdp_y);

            % Update V vs. T graph:
            clearpoints(app.VT) 
            x_ary = squeeze(app.VZT_ary(app.z_des_idx, 3, 1:app.t_des_idx));
            y_ary = squeeze(app.VZT_ary(app.z_des_idx, 1, 1:app.t_des_idx));
            addpoints(app.VT, x_ary, y_ary);

            % Replot the data voltage vs. time data for interactivity:
            set(app.temp_vt,'XData', x_ary, 'YData', y_ary);

            drawnow;
        end

        % Callback function
        function TimeTnsSliderValueChanged(app, event)
            % Get the new t_des value and set its corresponding index:
            app.t_des = 40 - app.TimeTnsSlider.Value;
            app.t_des_idx = findClosestIndex(app, app.t_ary, app.t_des);

            x_ary = squeeze(app.VZT_ary(app.z_des_idx, 3, 1:app.t_des_idx));
            y_ary = squeeze(app.VZT_ary(app.z_des_idx, 1, 1:app.t_des_idx));

            % Update V vs. Z graph:
            app.VZ.XData = app.VZT_ary(:, 2, app.t_des_idx);
            app.VZ.YData = app.VZT_ary(:, 3, app.t_des_idx);

            % Replot the data:
            set(app.temp_vt,'XData', x_ary, 'YData', y_ary);
            set(app.temp_BD, 'XData', app.BD_ary(1:app.t_des_idx,2), 'YData', app.BD_ary(1:app.t_des_idx,1));

            % Ensure that temp_vt and temp_BD is turned back on:
            if ~get(app.temp_vt, 'Visible')
                set(app.temp_vt, 'Visible', 'on');
            end

            if ~get(app.temp_BD, 'Visible')
                set(app.temp_BD, 'Visible', 'on');
            end

            % Ensure the play button is enabled:
            app.PLAYButton.Enable = 'on';

        end

        % Callback function
        function PositionZSliderValueChanged(app, event)
            % Updated horizontile dotted lines where z_des intersects with
            % the bounce diagram:
            app.VChangedTimes = findVChangeTimes(app);

            % Reset plots (Bounce Diagram):
            if ishandle(app.VChangedPltsBD)
                delete(app.VChangedPltsBD)
            end

            % Reset plots (V vs. T graph):
            if ishandle(app.VChangedPltsVT)
                delete(app.VChangedPltsVT)
            end

            % Initialize plot handles
            app.VChangedPltsBD = NaN(1, length(app.VChangedTimes));
            app.VChangedPltsVT = NaN(1, length(app.VChangedTimes));

            % Plot the dashed lines:
            z_vals = linspace(app.z_des, 1);
            for i = 1:length(app.VChangedPltsBD)
                t_idx = findClosestIndex(app, app.t_ary, app.VChangedTimes(i));
                v_vals = linspace(-4, app.VZT_ary(app.z_des_idx, 3, t_idx));

                app.VChangedPltsBD(i) = plot(app.UIAxesBD, z_vals, app.VChangedTimes(i).*ones(1,length(z_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7], 'PickableParts', 'none');
                app.VChangedPltsVT(i) = plot(app.UIAxesVT, v_vals, app.VChangedTimes(i).*ones(1,length(v_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7], 'PickableParts', 'none');
            end

            % Set the visibility for each plot to be in line with the
            % current time (Bounce Diagram):
            set(app.VChangedPltsBD(app.VChangedTimes < app.t_des), 'Visible', 'on')
            set(app.VChangedPltsBD(app.VChangedTimes > app.t_des), 'Visible', 'off')

            % Set the visibility for each plot to be in line with the
            % current time (V vs. T graph):
            set(app.VChangedPltsVT(app.VChangedTimes < app.t_des), 'Visible', 'on')
            set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

            % Adjust limits on V vs. t graph:
            AdjustAxesVT(app)

        end

        % Callback function
        function DropDownValueChanged(app, event)
            % Pause the simulation:
            if app.Play == true
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
            end
            
            % app.bounceType = app.DropDown.Value;
            app.VZT_ary = [];
            initializeProperties(app) % Re-establish VZT_ary

            if app.bounceType == "Step"
                clearpoints(app.BD_negative)
            else
                addpoints(app.BD_negative, app.BD_negative_ary(1:app.t_des_idx, 2), app.BD_negative_ary(1:app.t_des_idx, 1));
            end

            clearpoints(app.VT)

            % Update V vs. T graph:
            x_ary = squeeze(app.VZT_ary(app.z_des_idx, 3, 1:app.t_des_idx));
            y_ary = squeeze(app.VZT_ary(app.z_des_idx, 1, 1:app.t_des_idx));
            addpoints(app.VT, x_ary, y_ary)

            % % Update V vs. Z graph:
            % app.VZ.XData = app.VZT_ary(:, 2, app.t_des_idx);
            % app.VZ.YData = app.VZT_ary(:, 3, app.t_des_idx);

            % Update interactable temp_vt and temp_BD plot:
            set(app.temp_vt,'XData', x_ary, 'YData', y_ary);
            set(app.temp_BD, 'XData', app.BD_ary(1:app.t_des_idx,2), 'YData', app.BD_ary(1:app.t_des_idx,1));

            % Ensure the temp_vt and temp_BD plot is visible:
            if ~get(app.temp_vt, 'Visible')
                set(app.temp_vt, 'Visible', 'on');
            end

            if ~get(app.temp_BD, 'Visible')
                set(app.temp_BD, 'Visible', 'on');
            end

            % Reset VChangedPltsVT:

            % Reset VChangedPlts (V vs. T graph):
            if ishandle(app.VChangedPltsVT)
                delete(app.VChangedPltsVT)
            end

            % Initialize plot handles
            app.VChangedPltsVT = NaN(1, length(app.VChangedTimes));

            % Set the plots:
            for i = 1:length(app.VChangedPltsBD)
                t_idx = findClosestIndex(app, app.t_ary, app.VChangedTimes(i));
                v_vals = linspace(-4, app.VZT_ary(app.z_des_idx, 3, t_idx));
                app.VChangedPltsVT(i) = plot(app.UIAxesVT, v_vals, app.VChangedTimes(i).*ones(1,length(v_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7], 'PickableParts', 'none');
            end

            % Set visibility for dotted lines on v vs. t graph:
            set(app.VChangedPltsVT(app.VChangedTimes < app.t_des), 'Visible', 'on')
            set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

            AdjustAxesVT(app)

            % Update graphics
            drawnow limitrate
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxesVZ_4 = uiaxes(app.GridLayout);
            title(app.UIAxesVZ_4, 'V(z) at t = T')
            xlabel(app.UIAxesVZ_4, 'Position (z)')
            ylabel(app.UIAxesVZ_4, 'Voltage (V)')
            zlabel(app.UIAxesVZ_4, 'Z')
            app.UIAxesVZ_4.YLim = [-1 1.5];
            app.UIAxesVZ_4.YDir = 'reverse';
            app.UIAxesVZ_4.XAxisLocation = 'top';
            app.UIAxesVZ_3 = uiaxes(app.GridLayout);
            title(app.UIAxesVZ_3, 'V(z) at t = T')
            xlabel(app.UIAxesVZ_3, 'Position (z)')
            ylabel(app.UIAxesVZ_3, 'Voltage (V)')
            zlabel(app.UIAxesVZ_3, 'Z')
            app.UIAxesVZ_3.YLim = [-1 1.5];
            app.UIAxesVZ_3.YDir = 'reverse';
            app.UIAxesVZ_3.XAxisLocation = 'top';
            app.UIAxesVZ_2 = uiaxes(app.GridLayout);
            title(app.UIAxesVZ_2, 'V(z) at t = T')
            xlabel(app.UIAxesVZ_2, 'Position (z)')
            ylabel(app.UIAxesVZ_2, 'Voltage (V)')
            zlabel(app.UIAxesVZ_2, 'Z')
            app.UIAxesVZ_2.YLim = [-1 1.5];
            app.UIAxesVZ_2.YDir = 'reverse';
            app.UIAxesVZ_2.XAxisLocation = 'top';
            app.UIAxesVZ = uiaxes(app.GridLayout);
            title(app.UIAxesVZ, 'V(z) at t = T')
            xlabel(app.UIAxesVZ, 'Position (z)')
            ylabel(app.UIAxesVZ, 'Voltage (V)')
            zlabel(app.UIAxesVZ, 'Z')
            app.UIAxesVZ.YLim = [-1 1.5];
            app.UIAxesVZ.YDir = 'reverse';
            app.UIAxesVZ.XAxisLocation = 'top';
            app.UIAxesVT = uiaxes(app.GridLayout);
            title(app.UIAxesVT, 'V(t) at z = Z')
            xlabel(app.UIAxesVT, 'Voltage (V)')
            ylabel(app.UIAxesVT, 'Time (t)')
            zlabel(app.UIAxesVT, 'Z')
            app.UIAxesVT.XLim = [-1 1.5];
            app.UIAxesVT.YLim = [0 40];
            app.UIAxesVT.YDir = 'reverse';
            app.UIAxesVT.XAxisLocation = 'top';
            app.UIAxesBD = uiaxes(app.GridLayout);
            title(app.UIAxesBD, 'Bounce Diagram')
            xlabel(app.UIAxesBD, 'Position (z) (m)')
            ylabel(app.UIAxesBD, 'Time (t) (ns)')
            zlabel(app.UIAxesBD, 'Z')
            app.UIAxesBD.YLim = [0 40];
            app.UIAxesBD.YDir = 'reverse';
            app.UIAxesBD.XAxisLocation = 'top';
            app.BOUNCEDIAGRAMLabel = uilabel(app.GridLayout);
            app.BOUNCEDIAGRAMLabel.Text = 'BOUNCE DIAGRAM';
            app.Z050Label = uilabel(app.GridLayout);
            app.Z050Label.Text = 'Z0 = 50 (Ω)';
            app.up23cLabel = uilabel(app.GridLayout);
            app.up23cLabel.Text = 'up = (2/3)*c';
            app.RSLabel = uilabel(app.GridLayout);
            app.RSLabel.Text = 'RS = ';
            app.RLLabel = uilabel(app.GridLayout);
            app.RLLabel.Text = 'RL = ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BounceDiagramFigureCreation2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end