classdef BounceDiagramSimulationUNFINISHED < matlab.apps.AppBase
    %BOUNCEDIAGRAMSIMULATIONUNFINISHED Bounce Diagram Simulation
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2024
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia Furse
    %   (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   Simulates a bounce diagram with fully customizable load and source
    %   resistances. Allows full control over the time and designated
    %   position for the Voltage vs. Time and Voltages vs. Position graphs.
    %   Assumes that the velocity of propagation is (2/3)*c m/s.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        PositionZmLabel              matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        PositionZSlider              matlab.ui.control.Slider
        UIAxesBD                     matlab.ui.control.UIAxes
        RESETButton                  matlab.ui.control.Button
        up23cLabel                   matlab.ui.control.Label
        Z050Label                    matlab.ui.control.Label
        RSEditField                  matlab.ui.control.NumericEditField
        RSEditFieldLabel             matlab.ui.control.Label
        RLEditField                  matlab.ui.control.NumericEditField
        RLEditFieldLabel             matlab.ui.control.Label
        TimeTnsSlider                matlab.ui.control.Slider
        TimeTnsLabel                 matlab.ui.control.Label
        PLAYButton                   matlab.ui.control.Button
        BOUNCEDIAGRAMSIMULATORLabel  matlab.ui.control.Label
        UIAxesVT                     matlab.ui.control.UIAxes
        UIAxesVZ                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % PLOTS===========================================================

        % TODO : consistent variable style (camel or pycase)

        % Animated line objects are used to improve animation performance.
        % The slower plot objects will only appear when the animation is
        % paused or finished, allowing the user to select data points. 

        BD % Bounce diagram animated line object
        % TODO add interactivity for BD "temp_bd"

        % Designated Z position dotted line. Appears on the Bounce Diagram
        Z_des_plot
        Zdp_x  % X values for Designated Z position
        Zdp_y  % Y values for Designated Z position

        % Moments where Voltages changes at the designated Z position
        VChangedPltsBD % Plot handles for Bounce Diagram
        VChangedPltsVT % Plot handles for V vs. T graph
        VChangedTimes

        % TODO Intersection line(s)

        VT % Voltage vs. Time graph animated line object
        temp_vt % Temporary V vs. t plot for point interactivity

        VZ % Voltage vs. Position line object.

        % V vs. T vs. Z span 3 dimensions. The user may alter t_des and
        % z_des using the sliders to see each "slice" of this 3-dimensional
        % data.
        t_des = 0;  % Designated time for plotting (V vs. z)
        t_des_idx = 1; % Initialize the corresponding index for t_des. TODO
        z_des = 0; % Designated position for plotting (V vs. t)
        z_des_idx = 1; % Initialize the corresponding index for z_des TODO


        % SIMULATION CHARACTERISTICS======================================
        Z0 = 50; % Characteristic impedance of transmission line (Ω)
        RL = 100;  % Resistance at the load (Ω)
        RS = 25;  % Resistance at the source (Ω)
        rC_L  % Reflection coefficient at the load
        rC_s  % Reflection coefficient at the source

        Vs = 1;  % Source Voltage (V)
        V_ref_ary = zeros(1, 8) % Array of reflected voltages for the simulation

        V_tot = 0;  % Current total voltage. Initialized at zero

        % DATA============================================================

        % Voltage vs. Position data:
        zData
        vData

        BD_ary  % 2D Array that holds incident voltage vs. time information
        VZT_ary  % 3D Array that holds V vs. z vs t information TODO finish description
        t_size = 801 % Number of time increments
        z_size = 100  % Number of spatial increments
        t_ary % Holds all time

        % Determines if the simulation is running or not
        Play = false

    end
    
    methods (Access = private)
        
        % Animates the bounce diagram, V vs. t and V vs. z graphs
        % simultaneously by incrementing through a time array and plotting
        % the corresponding graphs.
        function startAnimation(app)
            t_des_idx = findClosestIndex(app, app.t_ary, app.t_des);
            
            % Plays the animation starting at where t_des is. 
            for t = t_des_idx:app.t_size

                % Simulation pause/play control
                if ~app.Play
                    break;
                end

                % Bounce diagram animated line update:
                addpoints(app.BD, app.BD_ary(t, 2), app.BD_ary(t, 1));

                % Draw V vs. t diagram:
                z_ary = app.VZT_ary(:, 2, 1);
                z_idx = findClosestIndex(app, z_ary, app.z_des);
                addpoints(app.VT, app.VZT_ary(z_idx, 3, t), app.VZT_ary(z_idx, 1, t))

                % Draw V vs. Z diagram:
                app.t_des = app.t_ary(t);
                app.VZ.XData = app.VZT_ary(:, 2, t);
                app.VZ.YData = app.VZT_ary(:, 3, t);


                % Update the time slider:
                app.TimeTnsSlider.Value = 40 - app.t_des;

                % Update the dotted lines that indicate when the voltage
                % changes at the designated position:
                set(app.VChangedPltsBD(app.VChangedTimes < app.t_des), 'Visible', 'on')
                set(app.VChangedPltsBD(app.VChangedTimes > app.t_des), 'Visible', 'off')

                % Update the dotted lines for V vs. t graph:
                set(app.VChangedPltsVT(app.VChangedTimes < app.t_des), 'Visible', 'on')
                set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

                % The pause function partially dictates the framerate of
                % the animation and facilitates smoother performance. "drawnow
                % limitrate" updates the graphics while also limiting the
                % framerate to ensure performance. If you want a slower or
                % faster animation and if your computer can handle it,
                % consider changing the value in pause and/or removing
                % "limitrate".
                pause(0.02)
                drawnow limitrate;
            end

        end

        % Computes the data for the bounce diagram, the V vs. t vs. z
        % array, and for the reflected voltages. 
        function initializeProperties(app)
            % Compute reflection coefficients:
            app.rC_L = (app.RL - app.Z0)/(app.RL + app.Z0);
            app.rC_s = (app.RS - app.Z0)/(app.RS + app.Z0);

            % Compute reflected voltages:

            % Initialize V_ref (V1+):
            app.V_ref_ary(1) = app.Vs * (app.Z0/(app.Z0 + app.RS));

            % Reflected voltages initialized into an array
            for n = 2:8
                % If even index: (toward source)
                if (mod(n,2) == 0)
                    app.V_ref_ary(n) = app.V_ref_ary(n-1) * app.rC_L;
                else  % odd index condition: (toward load)
                    app.V_ref_ary(n) = app.V_ref_ary(n-1) * app.rC_s;
                end
            end

            % Initialize V_tot:
            app.V_tot = 0;


            % Construct Bounce Diagram array: The first column will have
            % all the time values. The second column will have the position
            % of the incident voltage. 
            app.BD_ary = zeros(app.t_size, 2);  % Initialize the array size
            app.BD_ary(:,1) = linspace(0, 40, app.t_size); % First column will have the time stamps

            % This block of code fills in the position of the incident
            % voltage. It "draws" a line going back and forth between the
            % ends of the transmission line.
            app.BD_ary(1:101, 2) = linspace(0,1,101);
            app.BD_ary(101:201, 2) = linspace(1,0,101);
            app.BD_ary(201:301, 2) = linspace(0,1,101);
            app.BD_ary(301:401, 2) = linspace(1,0,101);
            app.BD_ary(401:501, 2) = linspace(0,1,101);
            app.BD_ary(501:601, 2) = linspace(1,0,101);
            app.BD_ary(601:701, 2) = linspace(0,1,101);
            app.BD_ary(701:801, 2) = linspace(1,0,101);

            % VZT_ary is a 3D array that holds all voltage, position, and
            % time data for a given load and source resistances. I have
            % constructed it by concatenating 2D array "slices" where each
            % slice is a given time with its corresponding voltages at
            % their designated positions along the transmission line.
            % Constructing this 3D array allows 1 data structure to exist
            % as a reference for animating, changing the designated time
            % and position values, and allowing plotting for both the V vs.
            % z and V vs. t graphs.

            % Construct slices of VZT_ary (V vs. Z) to make 3D array:
            % "A" will be the temporary "slice" that will be concatenated
            % to construct the 3D array:
            A = zeros(app.z_size, 3);
            timeary = linspace(0,40,app.t_size);

            % Establish V_tot:
            app.V_tot = app.V_ref_ary(1);

            % The second column of the slice will remain constant
            % throughout the whole process:
            A(:,2) = linspace(0,1,app.z_size);

            % Each "bounce" will be handled separately.

            % Notice that Each slice A is constructed as follows: 
            % The first column is a single time value for the slice.
            % The second column holds all the position values from 0 to 1.
            % The third column will hold the voltage values.
            % 
            % Every position in the second column has its own corresponding
            % voltage in the right column. An individual voltage is updated
            % every time a slice is created. In essence, the code is only
            % concerned about updating the leading voltage back and forth
            % beetween the bounces and ensuring the corresponding time is
            % updated as well.

            % First bounce
            for t = 1:100
                A(:,1) = timeary(t);
                A(t,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(2);

            % Second bounce
            for t = 101:200
                A(:,1) = timeary(t);
                A(200 - t + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(3);

            % Third bounce
            for t = 201:300
                A(:,1) = timeary(t);
                A(t - 201 + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(4);

            % Fourth bounce
            for t = 301:400
                A(:,1) = timeary(t);
                A(400 - t + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(5);

            % Fifth bounce
            for t = 401:500
                A(:,1) = timeary(t);
                A(t - 401 + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(6);

            % Third bounce
            for t = 501:600
                A(:,1) = timeary(t);
                A(600 - t + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(7);

            % Seventh bounce
            for t = 601:700
                A(:,1) = timeary(t);
                A(t - 601 + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(8);

            % Eight bounce
            for t = 701:800
                A(:,1) = timeary(t);
                A(800 - t + 1,3) = app.V_tot;
                app.VZT_ary = cat(3, app.VZT_ary, A);
            end

            % Final slice:
            A(:,1) = timeary(801);
            app.VZT_ary = cat(3,app.VZT_ary,A);

            % To see waht each "slice" looks like, uncomment the line below
            % and run the app.

            % disp(app.VZT_ary(:,:,300)) % Slice at time index = 300.

            % Construct t_ary for dynamic sliders:
            app.t_ary = app.VZT_ary(1, 1, :);

        end
        
        % Written by ChatGPT: Searches through an array to find the closest
        % value and returns the index of that value. Allows variable t_des
        % and z_des by the user.

        function idx = findClosestIndex(~, array, value)
            [~, idx] = min(abs(array - value)); % Find the index of the minimum absolute difference
        end

        
        % Function that looks through the bounce diagram and determines
        % all the times where the voltage at z_des changes. Returns the
        % array of times where the voltage at z_des changes.
        function results = findVChangeTimes(app)
            % Since z_des won't equal exactly a position value in BD_ary, I
            % introduce some tolerance:
            tolerance = .01;

            % Find all instances where BD_ary is this position and return:
            % results_idx = find(app.BD_ary(:,2) == intersect_position)
            results_idx = abs(app.BD_ary(:,2) - app.z_des) <= tolerance;

            results = app.BD_ary(results_idx(:),1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ensure axes on diagrams are rigid and unchanging.   
            app.UIAxesBD.XLim = [0 1];
            app.UIAxesVZ.XLim = [0 1];

            % Ensures The Bounce diagram can also show z_des and TODO
            % (intersections). Ensures that the temporary plot can show on
            % the V vs. t graph as well. 
            hold(app.UIAxesBD, 'on')
            hold(app.UIAxesVT, 'on')

            % Ensure you cannot click and drag the BD diagram:
            disableDefaultInteractivity(app.UIAxesBD);

            % Animated line object initialization:

            % Bounce Diagram animated line object:
            app.BD = animatedline(app.UIAxesBD, 'Color', 'b', 'LineWidth', 2);

            % Voltage vs. time animated line object
            app.VT = animatedline(app.UIAxesVT, 'Color', 'b', 'LineWidth', 2);

            % Temporary voltage vs. time plot object:
            app.temp_vt = plot(app.UIAxesVT, nan, nan, 'Color', 'b', 'LineWidth', 2); % TODO NOW fix
            % Customize V vs. position data tips:
            app.temp_vt.DataTipTemplate.DataTipRows(1).Label = 'Voltage (V):';
            app.temp_vt.DataTipTemplate.DataTipRows(2).Label = 'Time (ms):';

            % (Voltage vs. position) plot object (plot allows custom data
            % tips, while the LINE object does not).
            t = linspace(0, 5, 5/.2);
            app.zData = zeros(1, length(t));
            app.vData = zeros(1, length(app.zData));
            app.VZ = plot(app.UIAxesVZ, app.zData, app.vData, 'Color', 'b', 'LineWidth', 2);

            % Customize V vs. position data tips:
            app.VZ.DataTipTemplate.DataTipRows(1).Label = 'Position (m):';
            app.VZ.DataTipTemplate.DataTipRows(2).Label = 'Voltage (V):';

            % Plot Z_des on bounce diagram:
            app.Zdp_x = ones(100) * app.z_des;
            app.Zdp_y = linspace(0, 40, size(app.Zdp_x, 1));
            app.Z_des_plot = plot(app.UIAxesBD, app.Zdp_x, app.Zdp_y);

            % Compute BD and VTZ data:
            initializeProperties(app)

            % Ensure the plot handles for horizontile lines that intersect z_des are
            % initialized:

            % Establish when the voltage at z_des changes 
            app.VChangedTimes = findVChangeTimes(app);
            app.VChangedPltsBD = NaN(1, length(app.VChangedTimes)); % Initialize plot handles
            app.VChangedPltsVT = NaN(1, length(app.VChangedTimes)); % Initialize plot handles

            % Plot the dashed lines:
            z_vals = linspace(app.z_des, 1);
            z_ary = app.VZT_ary(:, 2, 1);
            t_des_idx = findClosestIndex(app, app.t_ary, app.t_des);
            z_des_idx = findClosestIndex(app, z_ary, app.z_des);
            for i = 1:length(app.VChangedPltsBD)
                t_des_idx = findClosestIndex(app, app.t_ary, app.VChangedTimes(i));
                v_vals = linspace(0, app.VZT_ary(z_des_idx, 3, t_des_idx));

                app.VChangedPltsBD(i) = plot(app.UIAxesBD, z_vals, app.VChangedTimes(i).*ones(1,length(z_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7]);
                app.VChangedPltsVT(i) = plot(app.UIAxesVT, v_vals, app.VChangedTimes(i).*ones(1,length(v_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7]);
            end

            % Set the visibility for each plot to be in line with the
            % current time (all are off at the start):
            set(app.VChangedPltsBD(app.VChangedTimes > app.t_des), 'Visible', 'off')
            set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

            % Move the app to the center of the user's screen: TODO test
            % with focused/full screen mode:
            movegui(app.UIFigure, 'onscreen');  % or 'center'?


        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            % TODO clean up data? cleaner way to do this?
            new_idx = findClosestIndex(app, app.t_ary, app.t_des);
            z_ary = app.VZT_ary(:, 2, 1);
            z_idx = findClosestIndex(app, z_ary, app.z_des);
            x_ary = squeeze(app.VZT_ary(z_idx, 3, 1:new_idx));
            y_ary = squeeze(app.VZT_ary(z_idx, 1, 1:new_idx));
            
            if ~app.Play
                app.Play = true;
                disp("PLAY button pushed!")
                app.PLAYButton.Text = "PAUSE";
    
                % Remove the temp_vt plot:
                set(app.temp_vt, 'Visible', 'off');

                startAnimation(app);
            else
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
                disp("PAUSE button pushed")

                % Allow interactivity data selecting for V vs. T and Bounce
                % Diagram: TODO

                % Plot the data:
                set(app.temp_vt,'XData', x_ary, 'YData', y_ary);

                % Ensure the temporary Voltage vs. time plot is
                % interactable and updated:
                
                set(app.temp_vt, 'Visible', 'on');
            end
        end

        % Value changed function: RLEditField
        function RLEditFieldValueChanged(app, event)
            % Pause the simulation:
            if app.Play == true
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
            end
            
            % Set the new load resistance:
            app.RL = app.RLEditField.Value;

            % Delete current VZT_ary:
            app.VZT_ary = [];

            % Re-initialize the VZT_ary:
            initializeProperties(app)

            % Track indices
            new_idx = findClosestIndex(app, app.t_ary, app.t_des);

            % Update graphs:
            clearpoints(app.BD)
            clearpoints(app.VT)

            % Update bounce diagram
            addpoints(app.BD, app.BD_ary(1:new_idx, 2), app.BD_ary(1:new_idx, 1));

            % Update V vs. T graph:
            z_ary = app.VZT_ary(:, 2, 1);
            z_idx = findClosestIndex(app, z_ary, app.z_des);
            x_ary = squeeze(app.VZT_ary(z_idx, 3, 1:new_idx));
            y_ary = squeeze(app.VZT_ary(z_idx, 1, 1:new_idx));
            addpoints(app.VT, x_ary, y_ary)

            % Update V vs. Z graph:
            app.VZ.XData = app.VZT_ary(:, 2, new_idx);
            app.VZ.YData = app.VZT_ary(:, 3, new_idx);

            % Update interactable temp_vt plot:
            set(app.temp_vt,'XData', x_ary, 'YData', y_ary);

            % Ensure the temp_vt plot is visible:
            if ~get(app.temp_vt, 'Visible')
                set(app.temp_vt, 'Visible', 'on');
            end

            % Reset VChangedPltsVT:

            % Reset plots (V vs. T graph):
            if ishandle(app.VChangedPltsVT)
                delete(app.VChangedPltsVT)
            end

            % Initialize plot handles
            app.VChangedPltsVT = NaN(1, length(app.VChangedTimes));

            % Set the plots:
            for i = 1:length(app.VChangedPltsBD) % TODO change indices. 
                t_idx = findClosestIndex(app, app.t_ary, app.VChangedTimes(i));
                v_vals = linspace(0, app.VZT_ary(z_idx, 3, t_idx));
                app.VChangedPltsVT(i) = plot(app.UIAxesVT, v_vals, app.VChangedTimes(i).*ones(1,length(v_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7]);
            end

            % Set visibility for dotted lines on v vs. t graph:
            set(app.VChangedPltsVT(app.VChangedTimes < app.t_des), 'Visible', 'on')
            set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

            % Update graphics
            drawnow limitrate
        end

        % Value changed function: RSEditField
        function RSEditFieldValueChanged(app, event)
            % Pause the simulation:
            if app.Play == true
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
            end

            % Get the new Source resistance value:
            app.RS = app.RSEditField.Value;

            % Delete current VZT_ary to create a new one:
            app.VZT_ary = [];

            % Re-establish reflected voltages and compute 3D array:
            initializeProperties(app)

            % Track indices: TODO chnage to app.t_des or something.
            new_idx = findClosestIndex(app, app.t_ary, app.t_des);

            % Update graphs:
            clearpoints(app.BD)
            clearpoints(app.VT)

            % Update bounce diagram
            addpoints(app.BD, app.BD_ary(1:new_idx, 2), app.BD_ary(1:new_idx, 1));

            % Update V vs. T graph:
            z_ary = app.VZT_ary(:, 2, 1);
            z_idx = findClosestIndex(app, z_ary, app.z_des);
            x_ary = squeeze(app.VZT_ary(z_idx, 3, 1:new_idx));
            y_ary = squeeze(app.VZT_ary(z_idx, 1, 1:new_idx));
            addpoints(app.VT, x_ary, y_ary)

            % Update V vs. Z graph:
            app.VZ.XData = app.VZT_ary(:, 2, new_idx);
            app.VZ.YData = app.VZT_ary(:, 3, new_idx);

            % Update the temporary Voltage vs. time plot:
            set(app.temp_vt,'XData', x_ary, 'YData', y_ary);

            % Ensure the temp_vt plot is visible:
            if ~get(app.temp_vt, 'Visible')
                set(app.temp_vt, 'Visible', 'on');
            end

            % Reset VChangedPltsVT:

            % Reset plots (V vs. T graph):
            if ishandle(app.VChangedPltsVT)
                delete(app.VChangedPltsVT)
            end

            % Initialize plot handles
            app.VChangedPltsVT = NaN(1, length(app.VChangedTimes));

            % Set the plots:
            for i = 1:length(app.VChangedPltsBD) % TODO change indices.
                t_idx = findClosestIndex(app, app.t_ary, app.VChangedTimes(i));
                v_vals = linspace(0, app.VZT_ary(z_idx, 3, t_idx));
                app.VChangedPltsVT(i) = plot(app.UIAxesVT, v_vals, app.VChangedTimes(i).*ones(1,length(v_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7]);
            end

            % Set visibility for dotted lines on v vs. t graph:
            set(app.VChangedPltsVT(app.VChangedTimes < app.t_des), 'Visible', 'on')
            set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

            % Update graphics
            drawnow limitrate
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.t_des = 0;  % Reset time to zero
            app.TimeTnsSlider.Value = 40 - app.t_des;

            % Delete Disable Temporary plot:
            set(app.temp_vt, 'Visible', 'off')


            clearpoints(app.BD) % Clear points on Bounce diagram TODO
            % delete?

            clearpoints(app.VT)  % Clear points on V vs. T graph

            % Set V vs. Z data back to the first "slice"
            app.VZ.XData = app.VZT_ary(:, 2, 1);
            app.VZ.YData = app.VZT_ary(:, 3, 1);

            % Pause the simulation
            app.Play = false;
            app.PLAYButton.Text = "PLAY";

            % Remove all dotted lines:
            set(app.VChangedPltsBD(app.VChangedTimes > app.t_des), 'Visible', 'off')
            set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

            drawnow;
            

        end

        % Value changing function: TimeTnsSlider
        function TimeTnsSliderValueChanging(app, event)
            % Pause the simulation:
            if app.Play == true
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
            end

            % Make the temporary plot invisible if it's visible:
            if get(app.temp_vt, 'Visible')
                set(app.temp_vt, 'Visible', 'off')
            end
            
            % Establish what the new t_des value is:
            new_t_des = 40 - event.Value;

            % Update the dotted lines that indicate when the voltage
            % changes at the designated position:
            set(app.VChangedPltsBD(app.VChangedTimes < new_t_des), 'Visible', 'on')
            set(app.VChangedPltsBD(app.VChangedTimes > new_t_des), 'Visible', 'off')

            % Set visibility for horizontile lines on V vs. T graph:
            set(app.VChangedPltsVT(app.VChangedTimes < new_t_des), 'Visible', 'on')
            set(app.VChangedPltsVT(app.VChangedTimes > new_t_des), 'Visible', 'off')

            % Track indices:
            recent_t_des_idx = findClosestIndex(app, app.t_ary, app.t_des); % TODO: change to global t_des_idx?
            new_idx = findClosestIndex(app, app.t_ary, new_t_des);  % Current t_des_idx

            % Update the graphs: If the new value is greater than the prior,
            % then simply add more points onto the current animated line.
            % If the new value is smaller, then reset the animated line and
            % draw up until the new designated time.
            if new_t_des > app.t_des
                % Update bounce diagram
                addpoints(app.BD, app.BD_ary(recent_t_des_idx:new_idx, 2), app.BD_ary(recent_t_des_idx:new_idx, 1));

                % Update V vs. T graph:
                z_ary = app.VZT_ary(:, 2, 1);
                z_idx = findClosestIndex(app, z_ary, app.z_des);
                x_ary = squeeze(app.VZT_ary(z_idx, 3, recent_t_des_idx:new_idx));
                y_ary = squeeze(app.VZT_ary(z_idx, 1, recent_t_des_idx:new_idx));
                addpoints(app.VT, x_ary, y_ary)

                % Update graphics
                drawnow limitrate
            elseif 40 - event.Value < app.t_des
                clearpoints(app.BD)
                clearpoints(app.VT)

                % Update bounce diagram
                addpoints(app.BD, app.BD_ary(1:new_idx, 2), app.BD_ary(1:new_idx, 1));

                % Update V vs. T graph:
                z_ary = app.VZT_ary(:, 2, 1);
                z_idx = findClosestIndex(app, z_ary, app.z_des);
                x_ary = squeeze(app.VZT_ary(z_idx, 3, 1:new_idx));
                y_ary = squeeze(app.VZT_ary(z_idx, 1, 1:new_idx));
                addpoints(app.VT, x_ary, y_ary)

                % Update graphics
                drawnow limitrate
            end

            % Update t_des
            app.t_des = new_t_des;

            % Update V vs. Z graph:
            app.VZ.XData = app.VZT_ary(:, 2, new_idx);
            app.VZ.YData = app.VZT_ary(:, 3, new_idx);
            
        end

        % Value changing function: PositionZSlider
        function PositionZSliderValueChanging(app, event)
            % Pause the simulation:
            if app.Play == true
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
            end

            % Ensure Voltage changed dashed lines are gone on Bounce Diagram:
            if ishandle(app.VChangedPltsBD)
                delete(app.VChangedPltsBD)
            end

            % Ensure Voltage changed dashed lines are gone on V vs. T graph:
            if ishandle(app.VChangedPltsVT)
                delete(app.VChangedPltsVT)
            end
            
            % Ensure that temp_vt plot is interactable:
            set(app.temp_vt, 'Visible', 'on');
            
            % Updated designated Z-position
            app.z_des = event.Value; 
            
            % Update dotted line of where z_des is on bounce diagram
            app.Zdp_x = ones(100, 1) .* app.z_des;
            app.Zdp_y = linspace(0, 40, size(app.Zdp_x, 1));
            set(app.Z_des_plot, 'XData', app.Zdp_x, 'YData', app.Zdp_y);

            % Track indices:
            t_idx = findClosestIndex(app, app.t_ary, app.t_des);  % updates t_des to new value INDEX TODO

            % Update V vs. T graph:

            % Clear points and re-construct Voltage vs. time animated line
            % TODO optional?
            clearpoints(app.VT) 
            z_ary = app.VZT_ary(:, 2, 1);
            z_idx = findClosestIndex(app, z_ary, app.z_des);
            x_ary = squeeze(app.VZT_ary(z_idx, 3, 1:t_idx));
            y_ary = squeeze(app.VZT_ary(z_idx, 1, 1:t_idx));

            addpoints(app.VT, x_ary, y_ary);

            % Replot the data voltage vs. time data for interactivity:
            set(app.temp_vt,'XData', x_ary, 'YData', y_ary);

            drawnow;
        end

        % Value changed function: TimeTnsSlider
        function TimeTnsSliderValueChanged(app, event)
            % Get the new t_des value:
            app.t_des = 40 - app.TimeTnsSlider.Value;

            % TODO this function should: update the animated line 

            % While the slider is moving, the temp_vt plot should be
            % disactivated (not appear or off or deleted)

            % When the slider is done moving, temp_vt should come back. 

            % Establish the new t_idx:
            t_idx = findClosestIndex(app, app.t_ary, app.t_des);

            z_ary = app.VZT_ary(:, 2, 1);
            z_idx = findClosestIndex(app, z_ary, app.z_des);
            x_ary = squeeze(app.VZT_ary(z_idx, 3, 1:t_idx));
            y_ary = squeeze(app.VZT_ary(z_idx, 1, 1:t_idx));

            % Update V vs. Z graph:
            app.VZ.XData = app.VZT_ary(:, 2, t_idx);
            app.VZ.YData = app.VZT_ary(:, 3, t_idx);

            % Replot the data:
            set(app.temp_vt,'XData', x_ary, 'YData', y_ary);

            % Ensure that temp_vt is turned back on:
            if ~get(app.temp_vt, 'Visible')
                set(app.temp_vt, 'Visible', 'on');
            end

        end

        % Value changed function: PositionZSlider
        function PositionZSliderValueChanged(app, event)
            % Updated horizontile dotted lines where z_des intersects with
            % the bounce diagram:
            app.VChangedTimes = findVChangeTimes(app);

            % Reset plots (Bounce Diagram):
            if ishandle(app.VChangedPltsBD)
                delete(app.VChangedPltsBD)
            end

            % Reset plots (V vs. T graph):
            if ishandle(app.VChangedPltsVT)
                delete(app.VChangedPltsVT)
            end

            % Initialize plot handles
            app.VChangedPltsBD = NaN(1, length(app.VChangedTimes));
            app.VChangedPltsVT = NaN(1, length(app.VChangedTimes));

            % Plot the dashed lines:
            z_vals = linspace(app.z_des, 1); % Plots from z_des to the end TODO rename to lineBD and lineVT?
            % TODO make t_des_idx global like app.t_des, app.t_des_idx
            z_ary = app.VZT_ary(:, 2, 1);
            t_des_idx = findClosestIndex(app, app.t_ary, app.t_des);
            z_des_idx = findClosestIndex(app, z_ary, app.z_des);
           % TODO now
            for i = 1:length(app.VChangedPltsBD)
                t_des_idx = findClosestIndex(app, app.t_ary, app.VChangedTimes(i));
                v_vals = linspace(0, app.VZT_ary(z_des_idx, 3, t_des_idx));

                app.VChangedPltsBD(i) = plot(app.UIAxesBD, z_vals, app.VChangedTimes(i).*ones(1,length(z_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7]);
                app.VChangedPltsVT(i) = plot(app.UIAxesVT, v_vals, app.VChangedTimes(i).*ones(1,length(v_vals)), 'Linestyle', '--', 'Color', [.7 .7 .7]);
            end

            % Set the visibility for each plot to be in line with the
            % current time (Bounce Diagram):
            set(app.VChangedPltsBD(app.VChangedTimes < app.t_des), 'Visible', 'on')
            set(app.VChangedPltsBD(app.VChangedTimes > app.t_des), 'Visible', 'off')

            % Set the visibility for each plot to be in line with the
            % current time (V vs. T graph):
            set(app.VChangedPltsVT(app.VChangedTimes < app.t_des), 'Visible', 'on')
            set(app.VChangedPltsVT(app.VChangedTimes > app.t_des), 'Visible', 'off')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxesVZ = uiaxes(app.GridLayout);
            title(app.UIAxesVZ, 'V(z) at t = T')
            xlabel(app.UIAxesVZ, 'Position (z)')
            ylabel(app.UIAxesVZ, 'Voltage (V)')
            zlabel(app.UIAxesVZ, 'Z')
            app.UIAxesVZ.YLim = [0 1.5];
            app.UIAxesVZ.YDir = 'reverse';
            app.UIAxesVZ.XAxisLocation = 'top';
            app.UIAxesVT = uiaxes(app.GridLayout);
            title(app.UIAxesVT, 'V(t) at z = Z')
            xlabel(app.UIAxesVT, 'Voltage (V)')
            ylabel(app.UIAxesVT, 'Time (t)')
            zlabel(app.UIAxesVT, 'Z')
            app.UIAxesVT.XLim = [0 1.5];
            app.UIAxesVT.YLim = [0 40];
            app.UIAxesVT.YDir = 'reverse';
            app.UIAxesVT.XAxisLocation = 'top';
            app.BOUNCEDIAGRAMSIMULATORLabel = uilabel(app.GridLayout);
            app.BOUNCEDIAGRAMSIMULATORLabel.Text = 'BOUNCE DIAGRAM SIMULATOR';
            app.PLAYButton = uibutton(app.GridLayout, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.TimeTnsLabel = uilabel(app.GridLayout);
            app.TimeTnsLabel.Text = 'Time (T) (ns):';
            app.TimeTnsSlider = uislider(app.GridLayout);
            app.TimeTnsSlider.Limits = [0 40];
            app.TimeTnsSlider.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.TimeTnsSlider.MajorTickLabels = {'40', '35', '30', '25', '20', '15', '10', '5', '0'};
            app.TimeTnsSlider.ValueChangedFcn = createCallbackFcn(app, @TimeTnsSliderValueChanged, true);
            app.TimeTnsSlider.ValueChangingFcn = createCallbackFcn(app, @TimeTnsSliderValueChanging, true);
            app.TimeTnsSlider.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11 11.5 12 12.5 13 13.5 14 14.5 15 15.5 16 16.5 17 17.5 18 18.5 19 19.5 20 20.5 21 21.5 22 22.5 23 23.5 24 24.5 25 25.5 26 26.5 27 27.5 28 28.5 29 29.5 30 30.5 31 31.5 32 32.5 33 33.5 34 34.5 35 35.5 36 36.5 37 37.5 38 38.5 39 39.5 40];
            app.TimeTnsSlider.Value = 40;
            app.RLEditFieldLabel = uilabel(app.GridLayout);
            app.RLEditFieldLabel.Text = 'RL (Ω): ';
            app.RLEditField = uieditfield(app.GridLayout, 'numeric');
            app.RLEditField.Limits = [0 Inf];
            app.RLEditField.ValueChangedFcn = createCallbackFcn(app, @RLEditFieldValueChanged, true);
            app.RLEditField.Value = 100;
            app.RSEditFieldLabel = uilabel(app.GridLayout);
            app.RSEditFieldLabel.Text = 'RS (Ω): ';
            app.RSEditField = uieditfield(app.GridLayout, 'numeric');
            app.RSEditField.Limits = [0 Inf];
            app.RSEditField.ValueChangedFcn = createCallbackFcn(app, @RSEditFieldValueChanged, true);
            app.RSEditField.Value = 25;
            app.Z050Label = uilabel(app.GridLayout);
            app.Z050Label.Text = 'Z0 = 50 (Ω)';
            app.up23cLabel = uilabel(app.GridLayout);
            app.up23cLabel.Text = 'up = (2/3)*c';
            app.RESETButton = uibutton(app.GridLayout, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.Panel = uipanel(app.GridLayout);
            app.UIAxesBD = uiaxes(app.Panel);
            title(app.UIAxesBD, 'Bounce Diagram')
            xlabel(app.UIAxesBD, 'Position (z) (m)')
            ylabel(app.UIAxesBD, 'Time (t) (ns)')
            zlabel(app.UIAxesBD, 'Z')
            app.UIAxesBD.YLim = [0 40];
            app.UIAxesBD.YDir = 'reverse';
            app.UIAxesBD.XAxisLocation = 'top';
            app.PositionZSlider = uislider(app.Panel);
            app.PositionZSlider.Limits = [0 1];
            app.PositionZSlider.ValueChangedFcn = createCallbackFcn(app, @PositionZSliderValueChanged, true);
            app.PositionZSlider.ValueChangingFcn = createCallbackFcn(app, @PositionZSliderValueChanging, true);
            app.PositionZmLabel = uilabel(app.GridLayout);
            app.PositionZmLabel.Text = 'Position (Z) (m):';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BounceDiagramSimulationUNFINISHED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end