classdef EHFieldSim < matlab.apps.AppBase
    %EHFIELDSIM Simulates a transmission line with varying loads
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2024
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia
    %   Furse (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   TODO : names to add : Shaun Mckellar, Richard Nagel, James

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        UpLabel                  matlab.ui.control.Label
        Epsilon_REditField       matlab.ui.control.NumericEditField
        Epsilon_REditFieldLabel  matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        PLAYButton               matlab.ui.control.Button
        Title                    matlab.ui.control.Label
        lineEField               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Physical Constants
        eps_0 = 8.85418782E-12;  %  Permittivity of free space (F/m).
        mu_0 = 4.0*pi*1E-7;      %  Permeability of free space (H/m).
        eta_0 = 376.730;          %  Square root of mu_0/eps_0   
        c = 3e8;                 %  Speed of light

        % Simulation parameters
        dz = 0.002;                % Grid resolution (m)
        kmax = 500;              % Number of grid points to model 1 m of transmission line

        % Arrays for wave data

        % E x H wave:
        E
        H

        % Reflected wave data set
        E_reflected
        H_reflected

        % Source parameters
        amp = 1.0;                   % Amplitude (A)
        freq = 1e9;                 % Frequency for the source 1 GHz

        epsilon_r = 1;
     
        % Angular frequency
        w 


        % Intrinsic Impedance
        eta
       
        % Phase constant
        Beta

        % Wave propagation speed
        up

        % Time step increment
        dt

        % X array
        Xarray

        % Simulation Control
        Play = false; % State of the simulation (boolean)
        time = 0;  % Current time
        
        % Plotting
        T_fr = 3;  % Time steps per plot update
        E_wave
        H_wave

        %Plots 
        Eplot 
        Hplot
      
      %%  lineExHIncidentPlot
       %% lineExHReflectedPlot
      

    end
    
    methods (Access = private)
        

        function initializeProperties(app)
        app.Xarray = linspace(0,1, app.kmax);
        app.time = 0;
        app.dt = 1/ (200*app.freq);
        app.calculateValues(app.epsilon_r);
        end


        function calculateValues(app, epsilon_r)


        % Solving For the Electric Field
        app.w = 2 * pi * app.freq;          % Calucate angular frequency
        app.up = app.c / sqrt(epsilon_r);   % Calculate Wave Propagation Speed
        app.Beta = app.w/app.up;            % Calcaulate Phase Constant         
        app.E_wave = app.amp * sin(app.w * app.time - app.Beta * app.Xarray); % Combine Each Element to Create Electric Field Equation


        % Solving for the Magnetic Field 
        app.eta = app.eta_0 * (1/sqrt(epsilon_r)); % Calculate intrinsic impedance
        app.H_wave = (1/app.eta) * app.E_wave;     % Calculate Magnetic Field equation

        end

        function updatePlots(app)
            app.Eplot.XData = app.Xarray;
            app.Eplot.YData = app.E_wave;
            app.Eplot.ZData = zeros(size(app.Xarray));

            app.Hplot.XData = app.Xarray;
            app.Hplot.YData = zeros(size(app.Xarray));
            app.Hplot.ZData = app.H_wave;

            app.UpLabel.Text = sprintf("Up = %.2f", app.up);

            %  Eplot = plot3(app.lineEField, app.Xarray, app.E_wave, zeros(size(app.Xarray)),'b', 'LineWidth', 2);
            % plot3(app.lineEField, app.Xarray, zeros(size(app.Xarray)), app.H_wave, 'r', 'LineWidth', 2);

            drawnow limitrate;

        end


        % Runs the simulation
        function PlaySimulation(app)

            while app.Play
                app.time = app.time + app.dt;
                app.calculateValues(app.epsilon_r);
                app.updatePlots();
                pause(0.01);
  
                % Simulation control
                % if ~app.Play
                %     break;
                % end
            end
        end


  end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the variable in the app
            initializeProperties(app)
            hold(app.lineEField, 'on')
 
            app.Eplot = plot3(app.lineEField, app.Xarray, app.E_wave, zeros(size(app.Xarray)), 'b', 'LineWidth', 2);
            app.Hplot = plot3(app.lineEField, app.Xarray, zeros(size(app.Xarray)), app.H_wave, 'r', 'LineWidth', 2);
            axis(app.lineEField, 'tight');
            view(app.lineEField, 45,45);

            app.updatePlots();


            % % Initialize the graphs to be zero:
            % yData = zeros(1, length(app.z_voltage));
            % 
            % % TODO evaluated animated line efficiency
            % app.lineVIncidentPlot = plot(app.lineEField, app.z_voltage, yData, 'Color', 'b', 'linewidth', 2);
            % app.lineVReflectedPlot = plot(app.lineHField, app.z_voltage, yData, 'Color', 'r', 'linewidth', 2);
            % hold(app.lineV, 'on')
            % app.lineVPlot = plot(app.lineV, app.z_voltage, yData, 'Color', 'k', 'linewidth', 2);

          
            % Set up the animatedline objects:
            % app.lineVIncidentPlot = animatedline(app.lineEField, 'Color', 'b', 'LineWidth', 2);
            % app.lineVReflectedPlot = animatedline(app.lineHField, 'Color', 'r', 'LineWidth', 2);
            % app.lineVPlot = animatedline(app.lineV, 'Color', 'k', 'LineWidth', 2);
            % app.lineVEnvelopePlot = animatedline(app.lineV, 'Color', 'k', 'Linestyle', ':', 'LineWidth', 2, 'visible', 'off');
            % 
            % % Initialize the lines TODO
            % addpoints(app.lineVIncidentPlot, app.z_voltage, yData);
            % addpoints(app.lineVReflectedPlot, app.z_voltage, yData);
            % addpoints(app.lineVPlot, app.z_voltage, yData);
            % addpoints(app.lineVEnvelopePlot, app.z_voltage, yData);

            % drawnow;
            
        end

        % Button pushed function: PLAYButton
        function PausePlaySimulation(app, event)
            if ~app.Play
                app.Play = true;
                app.PLAYButton.Text = "PAUSE";
                PlaySimulation(app);
            else
                app.PLAYButton.Text = "PLAY";
                app.Play = false;
            end
        end

        % Button pushed function: ResetButton
        function ResetSim(app, event)
            
            app.Play = false;
            app.time = 0;
            app.calculateValues(app.epsilon_r);
            app.updatePlots();
            
           
        end

        % Callback function
        function ChangeLoad(app, event)
            % value = app.LoadTypeDropDown.Value;
            % app.load = value;
            % app.Title.Text = "Transmission for " + value + " Load (1GHz)";
            % 
            % switch value
            %     case 'MATCHED'
            %         % Matched Load: vReflected should be 0 (no reflection)
            %         app.reflectionCoefficient = 0;
            % 
            %     case 'OPEN'
            %         % Open Load: vReflected should be 0 (open circuit)
            %         app.reflectionCoefficient = 1;
            % 
            %     case 'SHORT'
            %         % Short Load: vReflected should be -i * Z0 (short circuit reflection)
            %         app.reflectionCoefficient = -1;
            % 
            %     case 'RESISTIVE (27 Ω)'
            %         % Resistive Load: Example resistance value
            %         app.reflectionCoefficient = .33;
            % 
            %     otherwise
            %         % Handle unknown load types
            %         error('Unknown load type: %s', loadType);
            % end
            % 

            
        end

        % Callback function
        function EnvelopeCheckBoxValueChanged(app, event)
          
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Value changed function: Epsilon_REditField
        function Epsilon_REditFieldValueChanged(app, event)

            app.epsilon_r = app.Epsilon_REditField.Value;
            app.calculateValues(app.epsilon_r);
            app.updatePlots();
            
        end

        % Callback function
        function lineVReflectedButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.lineEField = uiaxes(app.GridLayout);
            title(app.lineEField, 'Electric Field (E)')
            xlabel(app.lineEField, 'Postion (m)')
            ylabel(app.lineEField, 'E (V/m)')
            zlabel(app.lineEField, 'Z')
            app.lineEField.ZLim = [-1 1];
            app.lineEField.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.lineEField.XTickLabel = {'1'; '0.9'; '0.8'; '0.7'; '0.6'; '0.5'; '0.4'; '0.3'; '0.2'; '0.1'; '0'};
            app.Title = uilabel(app.GridLayout);
            app.Title.Text = 'E X H Field Simulation';
            app.PLAYButton = uibutton(app.GridLayout, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PausePlaySimulation, true);
            app.PLAYButton.Text = 'PLAY';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSim, true);
            app.ResetButton.Text = 'Reset';
            app.Epsilon_REditFieldLabel = uilabel(app.GridLayout);
            app.Epsilon_REditFieldLabel.Text = 'Epsilon_R';
            app.Epsilon_REditField = uieditfield(app.GridLayout, 'numeric');
            app.Epsilon_REditField.Limits = [0 Inf];
            app.Epsilon_REditField.ValueChangedFcn = createCallbackFcn(app, @Epsilon_REditFieldValueChanged, true);
            app.UpLabel = uilabel(app.GridLayout);
            app.UpLabel.Text = 'Up = ?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EHFieldSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end