classdef EnvelopeCalculator < matlab.apps.AppBase
    %ENVELOPECALCULATOR Shows envelope for given load
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2024
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia Furse
    %   (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   Calculates the envelope for a perfectly conducting transmission
    %   line for a 1V amplitude sine wave at 600 MHz. The source is
    %   perfectly matched with the line. The transmission line has a
    %   characteristic impedance of 50 Ohms.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        ClickonthegraphabovetofindmagnitudesandlocationsLabel  matlab.ui.control.Label
        MHzSignalLabel           matlab.ui.control.Label
        LoadResistanceEditField  matlab.ui.control.NumericEditField
        LoadResistanceLabel      matlab.ui.control.Label
        Z050OhmsLabel            matlab.ui.control.Label
        StandingWaveEnvelopeCalculatorLabel  matlab.ui.control.Label
        EnvelopePlot             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        envelopeYData
        z_array = linspace(0, 1, 1000)
        envelopePlot
        rC % Reflection Coefficient
        waveAmplitude = 1
        Beta = 2*pi/.5  % Wavelength = .5m
        z0 = 50;  % characteristic impedance of the transmission line
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Disable the interactive toolbar
           app.EnvelopePlot.Toolbar.Visible = 'off';

        end

        % Value changed function: LoadResistanceEditField
        function LoadResistanceEditFieldValueChanged(app, event)
            % Get the resistance of the load from the user
            value = app.LoadResistanceEditField.Value;

            % Calculate the reflection coefficient:
            if value == inf
                app.rC = 1;
            else 
                app.rC = (value - app.z0)/(value + app.z0);
            end

            % Calculate the new envelopeYData:
            app.envelopeYData = app.waveAmplitude .* sqrt(1 + abs(app.rC)^2 + 2.*abs(app.rC).*cos(2.*app.Beta.*app.z_array + angle(app.rC)));

            % Plot the new envelopeData:
            plot(app.EnvelopePlot, app.z_array, app.envelopeYData, 'Color', 'k', 'linewidth', 2, 'LineStyle', ':');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EnvelopePlot = uiaxes(app.GridLayout);
            title(app.EnvelopePlot, 'Voltage Envelope')
            xlabel(app.EnvelopePlot, 'Position (m)')
            ylabel(app.EnvelopePlot, 'Voltage')
            zlabel(app.EnvelopePlot, 'Z')
            app.EnvelopePlot.YLim = [0 2];
            app.EnvelopePlot.XDir = 'reverse';
            app.EnvelopePlot.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.StandingWaveEnvelopeCalculatorLabel = uilabel(app.GridLayout);
            app.StandingWaveEnvelopeCalculatorLabel.Text = 'Standing Wave Envelope Calculator';
            app.Z050OhmsLabel = uilabel(app.GridLayout);
            app.Z050OhmsLabel.Text = 'Z0 = 50 Ohms';
            app.LoadResistanceLabel = uilabel(app.GridLayout);
            app.LoadResistanceLabel.Text = 'Load Resistance (Ω): ';
            app.LoadResistanceEditField = uieditfield(app.GridLayout, 'numeric');
            app.LoadResistanceEditField.Limits = [0 Inf];
            app.LoadResistanceEditField.ValueChangedFcn = createCallbackFcn(app, @LoadResistanceEditFieldValueChanged, true);
            app.LoadResistanceEditField.Tooltip = {'Enter the resistance for the load'};
            app.MHzSignalLabel = uilabel(app.GridLayout);
            app.MHzSignalLabel.Text = '600 MHz Signal';
            app.ClickonthegraphabovetofindmagnitudesandlocationsLabel = uilabel(app.GridLayout);
            app.ClickonthegraphabovetofindmagnitudesandlocationsLabel.Text = 'Click on the graph above to find magnitudes and locations of peaks and nulls.';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ACVoltage.png');
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Resistor.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnvelopeCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end