classdef QuarterWave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SourceLocationEditField       matlab.ui.control.NumericEditField
        SourceLocationEditFieldLabel  matlab.ui.control.Label
        PLAYButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % This "properties" section declares properties of the app

        % Rule: You cannot compute things in the "properties" section
        % Rule: You cannot initialize a variable here using another
        % variable.

        % Parameters and initialization
        ey
        hz
        nx = 100; % Number of spatial steps
        nstep = 1000; % Total number of time steps


        eyold_1=0;eyold_2=0;eyold_nx=0;eyold_nx1=0; % Mur boundary conditions

        % define source type and parameters
        ipulse = 0; % Continuous wave (CW)
        F = 1e6; % Frequency in Hz

        w % Angular frequency

        inc =10; % Source location
        vpo = 2.996e8; % Velocity of propagation in air
        wavelength_o
        B

        %set up model

        er
        sig
        eo = 8.854e-12;
        uo = 1.26e-6;
        eta = 377;
        % model for a quarter wave transformer
        % A quarter wave transformer should remove reflections caused by hitting a
        % material. Assume material 1 is air; material Tx is transformer; material
        % 2 is on the right side (and touches the boundary)
        % Note: quarter wave transformers are for non-conducting, nonmagnetic materials
        er1=1; % air
        er2=8; % distilled water

        k2
        vp2
        wavelength2
        erTx
        kTx
        vpTx
        wavelengthTx

        wavelength_min
        dx
        dt
        nTx

        d1
        c1
        c2

        Play = false
        n = 0 % Counter for FDTD loop

    end
    
    methods (Access = private)

        % Function to generate the wave (incident waveform)
        function value = wave(~, t, x, w, B, F, ipulse)
            % t = time (seconds)
            % x = distance (meters)
            % w = angular frequency (radians/sec)
            % B = (beta) propagation constant
            % F = frequency (Hz)
            % ipulse = signal type where 0=CW, 1=raised cosine, 2=rectangular pulse
            if ipulse == 0         % Continuous wave (CW)
                value = sin(w*t - B*x);
            elseif ipulse==1        % Raised cosine pulse
                if t <= 1 / F
                    value = 1 - cos(w * t- B*x);
                else
                    value = 0;
                end
            elseif ipulse==2 %rectangular pulse
                if t <= 10
                    value = 1;
                elseif t<=20
                    value=-1;
                else
                    value=0;
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.w = 2 * pi * app.F; % Angular frequency
            app.ey = zeros(1, app.nx); % Ey field array
            app.hz = zeros(1, app.nx); % Hz field array

            app.wavelength_o = app.vpo/app.F; % meters (for air)
            app.B = 2*pi/app.wavelength_o; % (beta) propagation constant (radians / meter)
            app.er = ones(1, app.nx); % Relative permittivity (air = 1)
            app.sig = zeros(1, app.nx); % Conductivity (air = 0)

            app.k2 = app.w*sqrt(app.uo*app.eo*app.er2); % propagation constant
            app.vp2 = app.w/app.k2; % velocity of propagation (m/s)
            app.wavelength2 = app.vp2/app.F;
            app.erTx = sqrt(app.er1*app.er2); % quarter wave transformer
            app.kTx = app.w*sqrt(app.uo*app.eo*app.erTx); % propagation constant
            app.vpTx = app.w/app.kTx; % velocity of propagation (m/s)
            app.wavelengthTx = app.vpTx/app.F;
            app.wavelength_min = min(app.wavelength_o, app.wavelength2);
            app.wavelength_min = min(app.wavelength_min,app.wavelengthTx);
            app.dx = app.wavelength_min / 20; % spatial step size (meters)
            app.dt = app.dx / (2*app.vpo); % Time step for stability

            app.nTx = round(app.wavelengthTx/(4*app.dx)); % number of cells in Tx
            app.er(app.inc+30:app.inc+30+app.nTx)=app.erTx;
            app.er(app.inc+30+app.nTx+1:app.nx)=app.er2;

            % Calculate constants
            for i = 1:app.nx
                app.d1(i) = -app.dt / (app.uo * app.dx);
                app.c1(i) = (1 - app.sig(i) * app.dt / (2 * app.er(i) * app.eo)) / (1 + app.sig(i) * app.dt / (2 * app.er(i) * app.eo));
                app.c2(i) = app.dt / (app.dx * app.er(i)* app.eo) / (1 + app.sig(i) * app.dt / (2 * app.er(i) * app.eo));
            end

        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            % FDTD main loop
            if app.Play == true
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
            elseif app.Play == false
                app.Play = true;
                app.PLAYButton.Text = "PAUSE";
            end

            while app.Play % iterate time

                % Added Ey source
                app.ey(app.inc) = app.ey(app.inc) + wave(app, (app.n* app.dt), 0, app.w, app.B, app.F, app.ipulse);
                % Forced Ey source
                %ey(inc) = wave(n * dt, 0.0, w, B, F, ipulse);

                % Update Ey field at n+1 and i-1/2
                for i = 2:app.nx
                    app.ey(i) = app.c1(i) * app.ey(i) + app.c2(i) * (app.hz(i - 1) - app.hz(i));
                end

                % Boundary conditions for Ey
                % --LEFT side--
                %ey(1) = 0. % metal wall
                % Mur 1st-order
                RBC_constL = (app.vpo * app.dt - app.dx) / (app.vpo * app.dt + app.dx);
                app.ey(1) = app.eyold_2 + RBC_constL*(app.ey(2) - app.eyold_1);
                app.eyold_1 = app.ey(1);
                app.eyold_2 = app.ey(2);

                %  --RIGHT side--
                %ey(nx) = 0. % metal
                % --Mur 1st order --
                RBC_constR = (app.vp2 * app.dt - app.dx) / (app.vp2 * app.dt + app.dx);
                app.ey(app.nx) = app.eyold_nx1 + RBC_constR*(app.ey(app.nx-1) - app.eyold_nx);
                app.eyold_nx1 = app.ey(app.nx-1);
                app.eyold_nx = app.ey(app.nx);

                plot(app.UIAxes, app.ey); 
                axis(app.UIAxes, [1 app.nx -1.5 1.5]); 

                drawnow limitrate; % TODO adjust

                % Update Hz field at n+1/2 and i+1/2
                for i = 1:app.nx - 1
                    app.hz(i) = app.hz(i) + app.d1(i) * (app.ey(i + 1) - app.ey(i));
                end

                app.n = app.n + 1;

            end
        end

        % Callback function: not associated with a component
        function PulseTypeDropDownValueChanged(app, event)
            value = app.PulseTypeDropDown.Value;
            
            if value == "CW"
                app.ipulse = 0;
            elseif value == "Raised cosine"
                app.ipulse = 1;
            elseif value == "Rectangular Pulse"
                app.ipulse = 2;
            end
        end

        % Value changed function: SourceLocationEditField
        function SourceLocationEditFieldValueChanged(app, event)
            app.inc = app.SourceLocationEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Quarter Wave Transformer')
            xlabel(app.UIAxes, 'Position (m)')
            ylabel(app.UIAxes, 'Voltage')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-1.5 1.5];
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.SourceLocationEditFieldLabel = uilabel(app.UIFigure);
            app.SourceLocationEditFieldLabel.Text = 'Source Location';
            app.SourceLocationEditField = uieditfield(app.UIFigure, 'numeric');
            app.SourceLocationEditField.ValueChangedFcn = createCallbackFcn(app, @SourceLocationEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuarterWave

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end