classdef QuarterWaveTransmissionLine < matlab.apps.AppBase
    %QUARTERWAVETRANSMISSIONLINE Quarter Wave Transformer Simulation
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2025
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia
    %   Furse
    %   (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   TODO : names to add : Cynthia Furse; ChatGPT citation
    %   
    %   Simulates a quarter wave transformer

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        TransformerlengthwavelengthsEditField  matlab.ui.control.NumericEditField
        TransformerlengthwavelengthsEditFieldLabel  matlab.ui.control.Label
        RESETButton  matlab.ui.control.Button
        PLAYButton   matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % This "properties" section declares properties of the app

        % Rule: You cannot compute things in the "properties" section
        % Rule: You cannot initialize a variable here using another
        % variable.

        % Parameters and initialization
        ey
        hz
        nx = 100; % Number of spatial steps
        nstep = 1000; % Total number of time steps
        ey_reflected


        eyold_1
        eyold_2
        eyold_nx
        eyold_nx1 % Mur boundary conditions

        % define source type and parameters
        ipulse = 0; % Continuous wave (CW)
        F = 1e6; % Frequency in Hz

        w % Angular frequency

        inc = 10 % source location (index)
        vpo = 2.996e8; % Velocity of propagation in air
        wavelength_o
        B

        %set up model
        er
        sig
        eo = 8.854e-12;
        uo = 1.26e-6;
        eta = 377;
        % model for a quarter wave transformer

        % A quarter wave transformer should remove reflections caused by hitting a
        % material. Assume material 1 is air; material Tx is transformer; material
        % 2 is on the right side (and touches the boundary)
        % Note: quarter wave transformers are for non-conducting, nonmagnetic materials
        er1=56.8516; % Impedance = 50 Ohms
        er2=14.2129; % Impedance = 100 Ohms

        k2
        vp2
        wavelength2
        erTx
        kTx
        vpTx
        wavelengthTx

        wavelength_min
        dx
        dt
        nTx

        d1
        c1
        c2

        Play = false
        n = 0 % Counter for FDTD loop
        quarter_wave_plot % plot handle for quarter wave plot

        % xline handles to indicate locations of mediums
        medium_1
        medium_2
        medium_3

        % Shaded region:
        shaded_region

    end
    
    methods (Access = private)

        % Function to generate the wave (incident waveform)
        function value = wave(~, t, x, w, B, F, ipulse)
            % t = time (seconds)
            % x = distance (meters)
            % w = angular frequency (radians/sec)
            % B = (beta) propagation constant
            % F = frequency (Hz)
            % ipulse = signal type where 0=CW, 1=raised cosine, 2=rectangular pulse
            if ipulse == 0         % Continuous wave (CW)
                value = sin(w*t - B*x);
            elseif ipulse==1        % Raised cosine pulse
                if t <= 1 / F
                    value = 1 - cos(w * t- B*x);
                else
                    value = 0;
                end
            elseif ipulse==2 %rectangular pulse
                if t <= 10
                    value = 1;
                elseif t<=20
                    value=-1;
                else
                    value=0;
                end
            end
        end


        function reset_wave(app)
            app.Play = false;
            app.PLAYButton.Text = "PLAY";

            startupFcn(app)
            app.n = 0;
        end

        function wavelengthString = formatWavelength(~, value)
            % Define magnitude prefixes and their corresponding powers of 10
            prefixes = {'pm', 'nm', 'µm', 'mm', 'm', 'km', 'Mm', 'Gm'};  % pico, nano, micro, milli, base, kilo, mega, giga
            exponents = -12:3:9;  % Corresponding powers of ten for each prefix

            % Find the closest exponent level
            exponentIndex = find(value >= 10.^exponents, 1, 'last');

            if isempty(exponentIndex)
                exponentIndex = 1;  % Default to smallest unit (pm) if value is extremely small
            end

            % Scale the value and round to two decimal places
            scaledValue = value / (10^exponents(exponentIndex));
            roundedValue = round(scaledValue, 2);

            % Generate the output string with the appropriate prefix
            wavelengthString = sprintf('%.2f %s', roundedValue, prefixes{exponentIndex});
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.UIAxes, 'on'); % Enable hold on UIAxes
            
            app.eyold_1=0;app.eyold_2=0;app.eyold_nx=0;app.eyold_nx1=0;
            
            app.w = 2 * pi * app.F; % Angular frequency
            app.ey = zeros(1, app.nx); % Ey field array
            app.hz = zeros(1, app.nx); % Hz field array

            app.wavelength_o = app.vpo/app.F; % meters (for air)
            app.B = 2*pi/app.wavelength_o; % (beta) propagation constant (radians / meter)
            app.er = ones(1, app.nx); % Relative permittivity (air = 1)
            app.sig = zeros(1, app.nx); % Conductivity (air = 0)

            app.k2 = app.w*sqrt(app.uo*app.eo*app.er2); % propagation constant
            app.vp2 = app.w/app.k2; % velocity of propagation (m/s)
            app.wavelength2 = app.vp2/app.F; % wavelength in meters for second medium
            app.erTx = sqrt(app.er1*app.er2); % quarter wave transformer
            app.kTx = app.w*sqrt(app.uo*app.eo*app.erTx); % propagation constant
            app.vpTx = app.w/app.kTx; % velocity of propagation (m/s)
            app.wavelengthTx = app.vpTx/app.F;
            app.wavelength_min = min(app.wavelength_o, app.wavelength2);
            app.wavelength_min = min(app.wavelength_min,app.wavelengthTx);
            app.dx = app.wavelength_min / 20; % spatial step size (meters)
            app.dt = app.dx / (2*app.vpo); % Time step for stability

            app.nTx = round(app.wavelengthTx/(4*app.dx)); % number of cells in Tx 
            left = app.inc+30; % left boundary for transformer
            right = app.inc+30+app.nTx; % right boundary for transformer
            app.er(left:right)=app.erTx;
            app.er(right+1:app.nx)=app.er2;

            % Draw indicators for mediums and transformer:
            app.medium_1 = xline(app.UIAxes, 0, '--r', 'Medium 1 (air) e_r = 1'); % TODO format these a little better
            app.medium_2 = xline(app.UIAxes, left, '--r', 'Transformer e_r = sqrt(8)'); % Vertical line at x = 5, dashed red, with a label
            app.medium_3 = xline(app.UIAxes, right, '--r', 'Medium 2 e_r = 8');

            % app.quarter_wave_plot = plot(app.UIAxes, app.ey(10:end)); 
            app.quarter_wave_plot = plot(app.UIAxes, app.ey);


            % Define the x and y coordinates for the shaded region
            x_shade = [left, right, right, left];  % x-coordinates of the shaded area
            y_shade = [-1.5, -1.5, 1.5, 1.5]; % Cover the y-range of the plot

            % Shade the region with a light blue color
            app.shaded_region = fill(app.UIAxes, x_shade, y_shade, 'c', 'FaceAlpha', 0.3, 'EdgeColor', 'none');

            % Calculate constants
            for i = 1:app.nx
                app.d1(i) = -app.dt / (app.uo * app.dx);
                app.c1(i) = (1 - app.sig(i) * app.dt / (2 * app.er(i) * app.eo)) / (1 + app.sig(i) * app.dt / (2 * app.er(i) * app.eo));
                app.c2(i) = app.dt / (app.dx * app.er(i)* app.eo) / (1 + app.sig(i) * app.dt / (2 * app.er(i) * app.eo));
            end

        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            % FDTD main loop
            if app.Play == true
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
            elseif app.Play == false
                app.Play = true;
                app.PLAYButton.Text = "PAUSE";
            end

            while app.Play % iterate time

                % Added Ey source
                app.ey(app.inc) = app.ey(app.inc) + wave(app, (app.n* app.dt), 0, app.w, app.B, app.F, app.ipulse);
                % Forced Ey source
                % app.ey(app.inc) = app.wave(app.n * app.dt, 0.0, app.w, app.B, app.F, app.ipulse);

                % Update Ey field at n+1 and i-1/2
                for i = 2:app.nx
                    app.ey(i) = app.c1(i) * app.ey(i) + app.c2(i) * (app.hz(i - 1) - app.hz(i));
                end

                % TODO : create another FDTD variable for er_inc and hr_inc
                % where all e_r = 1. Calculate and to find er_reflected do:
                % e_total - e_inc = e_reflected. (true at all locations)
                % INSERT ABOVE and ensure boundary conditions. force right
                % e_reflected(end) = 0. expect to see one reflection that
                % will dissipate. 

                % Boundary conditions for Ey
                % --LEFT side--
                %ey(1) = 0. % metal wall
                % Mur 1st-order
                RBC_constL = (app.vpo * app.dt - app.dx) / (app.vpo * app.dt + app.dx);
                app.ey(1) = app.eyold_2 + RBC_constL*(app.ey(2) - app.eyold_1);
                app.eyold_1 = app.ey(1);
                app.eyold_2 = app.ey(2);

                %  --RIGHT side--
                %ey(nx) = 0. % metal
                % --Mur 1st order --
                RBC_constR = (app.vp2 * app.dt - app.dx) / (app.vp2 * app.dt + app.dx);
                app.ey(app.nx) = app.eyold_nx1 + RBC_constR*(app.ey(app.nx-1) - app.eyold_nx);
                app.eyold_nx1 = app.ey(app.nx-1);
                app.eyold_nx = app.ey(app.nx);

                % app.quarter_wave_plot = plot(app.UIAxes, app.ey(10:end)); 
                set(app.quarter_wave_plot, 'YData', app.ey)
                

                drawnow limitrate; % TODO adjust
                pause(0.01) % Buffer

                % Update Hz field at n+1/2 and i+1/2
                for i = 1:app.nx - 1
                    app.hz(i) = app.hz(i) + app.d1(i) * (app.ey(i + 1) - app.ey(i));
                end

                app.n = app.n + 1;

            end
        end

        % Callback function
        function FrequencyHzEditFieldValueChanged(app, event)
            app.F = app.FrequencyHzEditField.Value;
            
            reset_wave(app)

        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
                reset_wave(app)
        end

        % Value changed function: TransformerlengthwavelengthsEditField
        function TransformerlengthwavelengthsEditFieldValueChanged(app, event)
            value = app.TransformerlengthwavelengthsEditField.Value;

            app.nTx = round(value * app.wavelengthTx/(app.dx)); % number of cells in Tx 
            left = app.inc+30; % left boundary for transformer
            right = app.inc+30+app.nTx; % right boundary for transformer
            app.er(left:right)=app.erTx;
            app.er(right+1:app.nx)=app.er2;

            % Calculate constants
            for i = 1:app.nx
                app.d1(i) = -app.dt / (app.uo * app.dx);
                app.c1(i) = (1 - app.sig(i) * app.dt / (2 * app.er(i) * app.eo)) / (1 + app.sig(i) * app.dt / (2 * app.er(i) * app.eo));
                app.c2(i) = app.dt / (app.dx * app.er(i)* app.eo) / (1 + app.sig(i) * app.dt / (2 * app.er(i) * app.eo));
            end

            % Redraw the xlines:
            % Draw indicators for mediums and transformer:
            delete(app.medium_3)
            app.medium_3 = xline(app.UIAxes, right, '--r', 'Medium 2');

            % Update the plot:
            set(app.quarter_wave_plot, 'YData', app.ey)

            % Define the x and y coordinates for the shaded region
            x_shade = [left, right, right, left];  % x-coordinates of the shaded area
            y_shade = [-1.5, -1.5, 1.5, 1.5]; % Cover the y-range of the plot

            % Shade the region with a light blue color
            app.shaded_region.XData = x_shade;
            app.shaded_region.YData = y_shade;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Reflected Wave')
            xlabel(app.UIAxes2, 'Position (m)')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 100];
            app.UIAxes2.YLim = [-1.5 1.5];
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Quarter Wave Transformer (Transmission Line)')
            xlabel(app.UIAxes, 'Position (m)')
            ylabel(app.UIAxes, 'Voltage')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [-1.5 1.5];
            app.PLAYButton = uibutton(app.GridLayout, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.RESETButton = uibutton(app.GridLayout, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.TransformerlengthwavelengthsEditFieldLabel = uilabel(app.GridLayout);
            app.TransformerlengthwavelengthsEditFieldLabel.Text = 'Transformer length (wavelengths): ';
            app.TransformerlengthwavelengthsEditField = uieditfield(app.GridLayout, 'numeric');
            app.TransformerlengthwavelengthsEditField.Limits = [0 Inf];
            app.TransformerlengthwavelengthsEditField.ValueChangedFcn = createCallbackFcn(app, @TransformerlengthwavelengthsEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuarterWaveTransmissionLine

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end