classdef SmithChart < matlab.apps.AppBase
    %SMITHCHART Interactive Smith Chart
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2025
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia Furse
    %   (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   Portions of this code were generated with assistance from ChatGPT
    %   (OpenAI, 2025).
    %   
    %   Image: SmithChart1.png
    %   Source: adapted from MTT-S, "Smithchart with logo", 2023. 
    %   Retrieved from: https://mtt.org/smith-chart/
    %   
    %   Image: SmithChart2Edit2.png
    %   Source: adapted from MTT-S, "Smithchart with logo", 2023. 
    %   Retrieved from: https://mtt.org/smith-chart/
    %   
    %   Interactive Smith Chart that allows user selected points and
    %   adjustable values. Includes an "automatic ruler" to read basic
    %   Smith Chart values and auto-updating entry boxes. Imaginary input
    %   and 'inf' is allowed. 
    %   
    %   Uses the Okabe–Ito palette (color blind friendly).
    %   

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        MainPanel             matlab.ui.container.Panel
        TRANSMCOEFFEorILabel  matlab.ui.control.Label
        TRANSMCOEFFPLabel     matlab.ui.control.Label
        SWPEAKCONSTPLabel     matlab.ui.control.Label
        RFLLOSSdBLabel        matlab.ui.control.Label
        SWLOSSCOEFFLabel      matlab.ui.control.Label
        ATTENdbLabel          matlab.ui.control.Label
        RFLCOEFFPLabel        matlab.ui.control.Label
        RTNLOSSdBLabel        matlab.ui.control.Label
        dBSLabel              matlab.ui.control.Label
        SWRLabel              matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
        XLjLabel              matlab.ui.control.Label
        Label_5               matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        RLLabel               matlab.ui.control.Label
        yLEditField           matlab.ui.control.EditField
        yLEditFieldLabel      matlab.ui.control.Label
        gammaAngleEditField   matlab.ui.control.NumericEditField
        degEditFieldLabel     matlab.ui.control.Label
        YLEditField           matlab.ui.control.EditField
        YLLabel               matlab.ui.control.Label
        zLEditField           matlab.ui.control.EditField
        zLEditFieldLabel      matlab.ui.control.Label
        ZLEditField           matlab.ui.control.EditField
        ZLLabel               matlab.ui.control.Label
        gammaMagEditField     matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        RulerCheckBox         matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        % Below values are for SmithChart1.png
        r1 = 1625; % Length (in pixels) of inner radius of Smith Chart
        r2 = 1875; % Length (in pixels) of the outer radius of the Smith Chart
        r3 = 1460; % Length (in pixels) of gamma = 1 for the Ruler

        Z0 = 50; % (ohms) Assumed characteristic impedance of transmission line

        ZL; % (ohms) Load impedance
        zL; % Normalized load impedance
        YL; % (mhos) Load admittance
        yL; % Normalized load admittance

        gamma; % Reflection coefficient 
        gammaMag = 0; % Magntude of reflection coefficient
        gammaAngle = 0; % (deg) Angle of reflection coefficient
        
        point; % Scatter handle for the current point on the Smith Chart
        imgIntersection; % Plot handle for the intersecting imaginary line
        realIntersection; % Plot handle for the intersecting real line

        innerRuler; % Plot from the center to 'point' on the chart 
        outerRuler; % Plot from the 'point' to the outer radius on the chart

        mouseListener; % Listener object for selecting the Smith Chart

        % Array of plot handles for making the Smith Chart ruler. The index
        % of the array corresponds to the # ruler going from left to right
        % and top to bottom (ej - index 1 is the SWR ruler and index 3 is
        % RTN. LOSS [dB])
        rulerMarkings = gobjects(1,7); 

        % Okabe–Ito palette: TODO NOW
        okabeBlue = [0, 114, 178]/255;
        okabeReddishPurple = [204, 121, 167]/255;
        okabeBluishGreen = [0.0000, 0.6196, 0.4510];
        okabeSkyBlue = [0.3373, 0.7059, 0.9137];
        okabeOrange = [230, 159, 0]/255;
    end

    methods (Access = private)

        % Updates the location of the point on the Smith Chart according
        % to current Smith Chart values.
        function updateSmithPoint(app)
            % Update the intersecting lines:
            updateSmithIntersection(app);

            % Update the ruler and ruler values:
            updateRuler(app);
            updateRulerMarkings(app);
            updateRulerValues(app);

            % Update app.point:
            r = abs(app.gamma);
            theta = angle(app.gamma);
            x = app.r1*r*cos(theta);
            y = app.r1*r*sin(theta);

            app.point.XData = x;
            app.point.YData = y;

        end

        function updateSmithIntersection(app)
            %UPDATESMITHINTERSECTION Traces lines for the current point
            %   MYFUNCTION(APP) Traces lines for the current point for this
            %   app.
            %   This function will trace the real and imaginary lines for
            %   the current point plotted on the Smith Chart. It will do so
            %   by spanning over the real and imaginary components of ZL
            %   independently of one another and plotting.

            % Author: Carsen Villegas (with assistance from ChatGPT)
            % Date: 2025-06-04

            % Extract the components of the current ZL
            realComponent = real(app.ZL);
            imgComponent = imag(app.ZL);

            % The real line, or circle, will be traced by maintaining the
            % real component of ZL and plotting over the full range of
            % imaginary values. A higher density of points centered around
            % 0 is needed to make the plot look like a smooth circle.
            N = 1000; % Number of points
            a = logspace(0, 4, N/2); % Higher density of points around 0
            A = [-fliplr(a), 0, a];  % full range from -1e4 to 1e4, clustered near 0
            realCircleSpan = realComponent.*ones(1, 1001) + A.*1i; % All ZL values to trace the line

            % Now using gamma to extract the exact x and y coordinates to
            % plot for all ZL values above
            gm = (realCircleSpan - app.Z0)./(realCircleSpan + app.Z0); % gamma
            r = abs(gm);
            theta = angle(gm);
            x = app.r1.*r.*cos(theta);
            y = app.r1.*r.*sin(theta);

            realX = [x, x(1)]; % Appending the first data points to give the appearance of a full circle
            realY = [y, y(1)];

            % Update the real intersection plot:
            app.realIntersection.XData = realX;
            app.realIntersection.YData = realY;

            % For the imaginary line will be traced by maintaining the
            % imaginary component of ZL and plotting over the full range of
            % real values. A higher density of points centered around 0 is
            % needed to make the plot look smooth. 0 is appended to the
            % real component to start at the very edge of the Smith Chart.
            imgCircleSpan = [0, logspace(0, 5, 1000)] + imgComponent.*ones(1,1001).*1i;

            gm = (imgCircleSpan - app.Z0)./(imgCircleSpan + app.Z0); % gamma
            r = abs(gm);
            theta = angle(gm);
            x = app.r1.*r.*cos(theta);
            y = app.r1.*r.*sin(theta);

            imgX = x;
            imgY = y;

            % Update the imaginary intersection plot:
            app.imgIntersection.XData = imgX;
            app.imgIntersection.YData = imgY;

        end


        % Imports and adjusts images to align with axes objects
        function loadImages(app)
            % Load Smith Chart image
            img = imread('SmithChart1.png'); % Chart itself

            % Image dimensions:
            squareSize = 3820;

            halfSize = floor(squareSize / 2);
            [imgHeight, imgWidth, ~] = size(img);

            % (0,0) Coordinates in pixels:
            centerX = 1910;
            centerY = 1910;

            % Display on app.UIAxes using image()
            h = image(app.UIAxes, img);  % Display the image
            set(h, 'Interpolation', 'bilinear');
            axis(app.UIAxes, 'off');  % Hide axis ticks

            % Hold for overlay plots
            hold(app.UIAxes, 'on');

            % Align with plot:

            % Set axis limits to match the image dimensions, with (0, 0) centered
            % Ensure the center of the image corresponds to (0, 0) on the axes
            set(app.UIAxes, 'XLim', [-halfSize halfSize], 'YLim', [-halfSize halfSize]);

            % Adjust the image position to place its center at (0, 0) in the axes
            set(h, 'XData', [-halfSize halfSize] + centerX - imgWidth/2, ...
                'YData', [halfSize -halfSize] + centerY - imgHeight/2);

            set(app.UIAxes, 'YDir', 'normal');

            % Ensure square aspect ratio:
            % pbaspect(app.UIAxes, [1 1 1])
            axis(app.UIAxes, 'image');

            % Load the ruler portion of the Smith Chart:
            img = imread('SmithChart2Edit2.png');
            % img_small = imresize(img, 0.25, 'bilinear'); % uses nearest neighbor interpolation

            % h = image(app.UIAxes2, img_small);
            h = image(app.UIAxes2, img);

            set(h, 'Interpolation', 'bilinear');
            axis(app.UIAxes2, 'off');
            hold(app.UIAxes2, 'on');
            axis(app.UIAxes2, 'image')

            % pbaspect(app.UIAxes2, [10200 1617 1])
        end
       
        function updateSmithValues(app, userUpdate)
            %updateSmithValues Updates all other relevant values for the
            %Smith Chart
            %   The user specifies which value was manually updated in the
            %   application. All other values are updated accordingly to
            %   avoid dependency errors. Also updates textboxes
            %   accordingly.
            %
            %   INPUTS:
            %     userUpdate - The value the user manually updated

            % gammaMag, gammaAngle, gamma, ZL, zL, YL, yL,

            % Author: Carsen Villegas (with assistance from ChatGPT)
            % Date: 2025-06-16

            switch userUpdate
                case 'gammaMag'
                    app.gamma = app.gammaMag*exp(1i*deg2rad(app.gammaAngle));

                    app.ZL = app.Z0*(1+app.gamma)/(1-app.gamma);
                    app.zL = app.ZL/app.Z0;
                    app.YL = 1/app.ZL;
                    app.yL = 1/app.zL;

                    % Update fields:
                    app.ZLEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.zLEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                    app.YLEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.yLEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));

                    app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 
                case 'gammaAngle'
                    app.gamma = app.gammaMag*exp(1i*deg2rad(app.gammaAngle));

                    app.ZL = app.Z0*(1+app.gamma)/(1-app.gamma);
                    app.zL = app.ZL/app.Z0;
                    app.YL = 1/app.ZL;
                    app.yL = 1/app.zL;

                    app.ZLEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.zLEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                    app.YLEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.yLEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));

                    app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 
                case 'zL'
                    app.ZL = app.Z0*app.zL;
                    app.YL = 1/app.ZL;
                    app.yL = 1/app.zL;

                    % Check inf condition for ZL:
                    if app.ZL == inf
                        app.gamma = 1;
                    else
                        app.gamma = (app.ZL - app.Z0)/(app.ZL + app.Z0);
                    end

                    app.gammaMag = abs(app.gamma);
                    app.gammaAngle = rad2deg(angle(app.gamma));

                    app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    app.ZLEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.YLEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.yLEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));


                case 'yL'
                    app.zL = 1/app.yL;
                    app.ZL = app.Z0*app.zL;
                    app.YL = 1/app.ZL;

                    % Check inf condition for ZL:
                    if app.ZL == inf
                        app.gamma = 1;
                    else
                        app.gamma = (app.ZL - app.Z0)/(app.ZL + app.Z0);
                    end

                    app.gammaMag = abs(app.gamma);
                    app.gammaAngle = rad2deg(angle(app.gamma));

                    app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    app.ZLEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.YLEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.zLEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));

                case 'ZL'
                    app.zL = app.ZL/app.Z0;
                    app.YL = 1/app.ZL;
                    app.yL = 1/app.zL;

                    % Check inf condition for ZL:
                    if app.ZL == inf
                        app.gamma = 1;
                    else
                        app.gamma = (app.ZL - app.Z0)/(app.ZL + app.Z0);
                    end

                    app.gammaMag = abs(app.gamma);
                    app.gammaAngle = rad2deg(angle(app.gamma));

                    app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    app.zLEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                    app.YLEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.yLEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));

                case 'YL'
                    app.ZL = 1/app.YL;
                    app.zL = app.ZL/app.Z0;
                    app.yL = 1/app.zL;

                    % Check inf condition for ZL:
                    if app.ZL == inf
                        app.gamma = 1;
                    else
                        app.gamma = (app.ZL - app.Z0)/(app.ZL + app.Z0);
                    end

                    app.gammaMag = abs(app.gamma);
                    app.gammaAngle = rad2deg(angle(app.gamma));

                    app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    app.ZLEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.zLEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                    app.yLEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));
            end
        end

        function userClickResponse(app) 
            % Get the current point of the mouse in the UIAxes
            cp = app.UIAxes.CurrentPoint;
            x = cp(1,1);
            y = cp(1,2);

            % disp(['Clicked at: (', num2str(x), ', ', num2str(y), ')']);

            % Check if the user selected within the Smith Chart:
            inBounds = isInBounds(app, x, y);

            if inBounds
                % Update values:
                app.gammaMag = sqrt(x^2 + y^2)/app.r1;
                theta = atan2(y,x);
                app.gammaAngle = rad2deg(theta);
                disp(app.gammaMag)
                disp(app.gammaAngle)
                updateSmithValues(app, 'gammaAngle')

                % Visual update:
                updateSmithIntersection(app);
                updateRuler(app);
                updateRulerMarkings(app);
                updateRulerValues(app);
                app.point.XData = x;
                app.point.YData = y;
            end


        end
        
        % Checks to see if coordinates (x,y) are in bounds of the Smith
        % Chart
        function result = isInBounds(app, x, y)
            result = sqrt(x^2 + y^2) <= app.r1;
        end

        % Updates the automatic ruler from the center point through 'app.point'
        function updateRuler(app)
            r = abs(app.gamma);
            theta = angle(app.gamma);

            % 'point' location: (pixel location)
            x = app.r1*r*cos(theta); 
            y = app.r1*r*sin(theta);

            % Update the ruler:
            set(app.innerRuler, 'XData', [0 x], 'YData', [0 y]);
            set(app.outerRuler, 'XData', [x app.r2*cos(theta)], 'YData', [y app.r2*sin(theta)]);
            
        end
        
        % Highlights the ring on the Smith Chart for the reflection coefficient
        function fillGammaAngle(app)
            theta = linspace(0, 2*pi, 500);  % Angle values
            ra = 3597 - 1910;  % Inner radius TODO compensate for new CENTER!!
            rb = 3659 - 1910;  % Outer radius

            % Outer circle (counterclockwise)
            x_outer = rb * cos(theta);
            y_outer = rb * sin(theta);

            % Inner circle (clockwise to avoid overlap issue)
            x_inner = fliplr(ra * cos(theta));
            y_inner = fliplr(ra * sin(theta));

            % Combine coordinates
            x_ring = [x_outer, x_inner];
            y_ring = [y_outer, y_inner];

            % Plot filled annulus
            h = fill(app.UIAxes, x_ring, y_ring, app.okabeSkyBlue); 

            % Set transparency
            h.FaceAlpha = 0.3;

            % Remove the outline:
            h.EdgeColor = 'none';

            % Remove pickable parts:
            h.PickableParts = 'none';


        end
        
        % Updates the positioning of the axes: TODO
        function onResize(app)
            disp('Inner position of UIAxes: ')
            disp(app.UIAxes.InnerPosition)
            disp("inner position of UIAxes2: ")
            disp(app.UIAxes2.InnerPosition)

            % Get panel size
            panelPos = app.MainPanel.Position;  % [left, bottom, width, height]

            % Margin
            spacing = 10;

            % Compute new size for each axes (50% height)
            width = panelPos(3) - 2*spacing;
            height = (panelPos(4) - 3*spacing) / 2;

            % Set position of top axes
            app.UIAxes.Position = [spacing, spacing*2 + height, width, height];

            % Set position of bottom axes
            app.UIAxes2.Position = [spacing, spacing, width, height];


        end
        
        function updateRulerMarkings(app)
            % Updates the markings on the Smith Chart Ruler:

            % Calculate the corresponding pixel measurement:
            r = abs(app.gamma);
            innerRulerLength = r*app.r3;

            app.rulerMarkings(1).YData = [222, 222];
            app.rulerMarkings(2).YData = [222, 222];

            app.rulerMarkings(3).YData = [335, 335];
            app.rulerMarkings(4).YData = [335, 335];

            app.rulerMarkings(5).YData = [447, 447];
            app.rulerMarkings(6).YData = [447, 447];

            app.rulerMarkings(7).YData = [559, 559];

            % For the first 6 measurements:
            for i = 1:6
                if mod(i, 2) == 0 % Right side of Center
                    app.rulerMarkings(i).XData = [1910 - innerRulerLength, 1910];
                else % Left side of center
                    app.rulerMarkings(i).XData = [1910 + innerRulerLength, 1910];
                end
            end

            app.rulerMarkings(7).XData = [450, 450 + innerRulerLength];

        end
        
        % Updates the textboses reporting values read off from the Smith
        % Chart ruler
        function updateRulerValues(app)
            SWR = (1 + app.gammaMag)/(1 - app.gammaMag);
            app.SWRLabel.Text = sprintf("SWR = %.2f", SWR);

            dBS = 20*log10(SWR);
            app.dBSLabel.Text = sprintf("dBS = %.2f", dBS);

            rtnLoss = -20*log10(app.gammaMag);
            app.RTNLOSSdBLabel.Text = sprintf("RTN. LOSS [dB] = %.2f", rtnLoss);

            rflCoeffP = app.gammaMag^2;
            app.RFLCOEFFPLabel.Text = sprintf("RFL. COEFF, P = %.2f", rflCoeffP);

            attenDB = -10*log10(1 - app.gammaMag^2); % TODO wrong?
            app.ATTENdbLabel.Text = sprintf("ATTEN. [db] = %.2f", attenDB);

            swLossCoeff = 1 - app.gammaMag^2; % TODO wrong?
            app.SWLOSSCOEFFLabel.Text = sprintf("S.W. LOSS COEFF = %.2f", swLossCoeff);

            rflLossDB = 1; % TODO
            app.RFLLOSSdBLabel.Text = sprintf("RFL. LOSS [dB] = %.2f", rflLossDB);

            swPeak = 1; % TODO
            app.SWPEAKCONSTPLabel.Text = sprintf("S.W. PEAK (CONST. P) = %.2f", swPeak);

            transCoeffP = 1 - app.gammaMag^2;
            app.TRANSMCOEFFPLabel.Text = sprintf("TRANSM. COEFF, P = %.2f", transCoeffP);

            transCoeffE = 1 + app.gammaMag; % TODO ??? how to measure?
            app.TRANSMCOEFFEorILabel.Text = sprintf("TRANSM. COEFF, E or I = %.2f", transCoeffE);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load image:
            loadImages(app);

            % Disable data tips (not useful):
            datacursormode(app.UIFigure, 'off');

            % Color the Angle of reflection coefficient:
            fillGammaAngle(app);

            % Set listener for selecting points on the Smith Chart
            app.mouseListener = event.listener(app.UIFigure,'WindowMousePress',@(src,evt) userClickResponse(app));

            % Set listener for resizing the app window: TODO
            app.MainPanel.AutoResizeChildren = 'off';
            app.MainPanel.SizeChangedFcn = @(~, ~) onResize(app);


            % Initialize ruler plots:
            app.innerRuler = plot(app.UIAxes, nan, nan, 'LineWidth', 2, 'Visible', 'off', 'Color', app.okabeBluishGreen);
            app.innerRuler.PickableParts = 'none';
            app.outerRuler = plot(app.UIAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeSkyBlue, 'Visible', 'off'); % alpha = .4
            app.outerRuler.PickableParts = 'none';

            for i = 1:7
                app.rulerMarkings(i) = plot(app.UIAxes2, nan, nan, 'LineWidth', 3, 'Visible', 'off', 'Color', app.okabeBluishGreen); % SET ALPHA TODO
                app.rulerMarkings(i).PickableParts = 'none';
            end

            % Initialize the intersecting line plots:
            app.imgIntersection = plot(app.UIAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeBlue); % Blue line
            app.imgIntersection.PickableParts = 'none';
            app.realIntersection = plot(app.UIAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeReddishPurple); % Reddish purple line
            app.realIntersection.PickableParts = 'none';

            % Initialize app.point:
            app.point = scatter(app.UIAxes, nan, nan, 100, 'filled');
            app.point.CData = app.okabeOrange;
            app.point.PickableParts = 'none';


        end

        % Value changed function: ZLEditField
        function ZLEditFieldValueChanged(app, event)
            str = app.ZLEditField.Value; % e.g., '3+4i'
            try
                val = str2num(str);  % Safely evaluates string
                if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
                    error('Invalid input');
                end
            catch
                uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
                return;
            end

            app.ZL = val;  % Store the complex number
            % Update other variables:
            updateSmithValues(app, 'ZL');
            updateSmithPoint(app);

        end

        % Value changed function: gammaMagEditField
        function gammaMagEditFieldValueChanged(app, event)
            app.gammaMag = app.gammaMagEditField.Value;
            updateSmithValues(app, 'gammaMag');
            updateSmithPoint(app);
            
        end

        % Value changed function: gammaAngleEditField
        function gammaAngleEditFieldValueChanged(app, event)
            app.gammaAngle = app.gammaAngleEditField.Value;
            updateSmithValues(app, 'gammaAngle');
            updateSmithPoint(app);
            
        end

        % Value changed function: zLEditField
        function zLEditFieldValueChanged(app, event)
            str = app.zLEditField.Value;
            try
                val = str2num(str);  % Safely evaluates string
                if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
                    error('Invalid input');
                end
            catch
                uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
                return;
            end

            app.zL = val;  % Store the complex number
            % Update other variables:
            updateSmithValues(app, 'zL');
            updateSmithPoint(app)
        end

        % Value changed function: yLEditField
        function yLEditFieldValueChanged(app, event)
            str = app.yLEditField.Value;
            try
                val = str2num(str);  % Safely evaluates string
                if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
                    error('Invalid input');
                end
            catch
                uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
                return;
            end

            app.yL = val;  % Store the complex number
            % Update other variables:
            updateSmithValues(app, 'yL');
            updateSmithPoint(app)
            
        end

        % Value changed function: YLEditField
        function YLEditFieldValueChanged(app, event)
            str = app.YLEditField.Value;
            try
                val = str2num(str);  % Safely evaluates string
                if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
                    error('Invalid input');
                end
            catch
                uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
                return;
            end

            app.YL = val;  % Store the complex number
            % Update other variables:
            updateSmithValues(app, 'YL');
            updateSmithPoint(app)
            
        end

        % Value changed function: RulerCheckBox
        function RulerCheckBoxValueChanged(app, event)
            value = app.RulerCheckBox.Value;
            
            if value
                app.innerRuler.Visible = 'on';
                app.outerRuler.Visible = 'on';

                for i = 1:7
                    app.rulerMarkings(i).Visible = 'on';
                end
            else
                app.innerRuler.Visible = 'off';
                app.outerRuler.Visible = 'off';

                for i = 1:7
                    app.rulerMarkings(i).Visible = 'off';
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RulerCheckBox = uicheckbox(app.GridLayout);
            app.RulerCheckBox.ValueChangedFcn = createCallbackFcn(app, @RulerCheckBoxValueChanged, true);
            app.RulerCheckBox.Text = 'Ruler';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '|Γ|';
            app.gammaMagEditField = uieditfield(app.GridLayout, 'numeric');
            app.gammaMagEditField.Limits = [0 1];
            app.gammaMagEditField.ValueChangedFcn = createCallbackFcn(app, @gammaMagEditFieldValueChanged, true);
            app.ZLLabel = uilabel(app.GridLayout);
            app.ZLLabel.Text = 'ZL (Ω) = ';
            app.ZLEditField = uieditfield(app.GridLayout, 'text');
            app.ZLEditField.ValueChangedFcn = createCallbackFcn(app, @ZLEditFieldValueChanged, true);
            app.zLEditFieldLabel = uilabel(app.GridLayout);
            app.zLEditFieldLabel.Text = 'zL';
            app.zLEditField = uieditfield(app.GridLayout, 'text');
            app.zLEditField.ValueChangedFcn = createCallbackFcn(app, @zLEditFieldValueChanged, true);
            app.YLLabel = uilabel(app.GridLayout);
            app.YLLabel.Text = 'YL (℧)';
            app.YLEditField = uieditfield(app.GridLayout, 'text');
            app.YLEditField.ValueChangedFcn = createCallbackFcn(app, @YLEditFieldValueChanged, true);
            app.degEditFieldLabel = uilabel(app.GridLayout);
            app.degEditFieldLabel.Text = '∠Γ (deg)';
            app.gammaAngleEditField = uieditfield(app.GridLayout, 'numeric');
            app.gammaAngleEditField.ValueChangedFcn = createCallbackFcn(app, @gammaAngleEditFieldValueChanged, true);
            app.yLEditFieldLabel = uilabel(app.GridLayout);
            app.yLEditFieldLabel.Text = 'yL';
            app.yLEditField = uieditfield(app.GridLayout, 'text');
            app.yLEditField.ValueChangedFcn = createCallbackFcn(app, @yLEditFieldValueChanged, true);
            app.RLLabel = uilabel(app.GridLayout);
            app.RLLabel.Text = 'RL';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '+';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '+';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '+';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '+';
            app.XLjLabel = uilabel(app.GridLayout);
            app.XLjLabel.Text = 'XLj';
            app.MainPanel = uipanel(app.GridLayout);
            app.MainPanel.Title = 'Panel';
            app.UIAxes = uiaxes(app.MainPanel);
            title(app.UIAxes, 'Smith Chart')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.MainPanel);
            title(app.UIAxes2, 'Ruler')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SWRLabel = uilabel(app.MainPanel);
            app.SWRLabel.Text = 'SWR = ';
            app.dBSLabel = uilabel(app.MainPanel);
            app.dBSLabel.Text = 'dBS = ';
            app.RTNLOSSdBLabel = uilabel(app.MainPanel);
            app.RTNLOSSdBLabel.Text = 'RTN. LOSS [dB] = ';
            app.RFLCOEFFPLabel = uilabel(app.MainPanel);
            app.RFLCOEFFPLabel.Text = 'RFL. COEFF, P = ';
            app.ATTENdbLabel = uilabel(app.MainPanel);
            app.ATTENdbLabel.Text = 'ATTEN. [db] = ';
            app.SWLOSSCOEFFLabel = uilabel(app.MainPanel);
            app.SWLOSSCOEFFLabel.Text = 'S.W. LOSS COEFF = ';
            app.RFLLOSSdBLabel = uilabel(app.MainPanel);
            app.RFLLOSSdBLabel.Text = 'RFL. LOSS [dB] = ';
            app.SWPEAKCONSTPLabel = uilabel(app.MainPanel);
            app.SWPEAKCONSTPLabel.Text = 'S.W. PEAK (CONST. P) = ';
            app.TRANSMCOEFFPLabel = uilabel(app.MainPanel);
            app.TRANSMCOEFFPLabel.Text = 'TRANSM. COEFF, P = ';
            app.TRANSMCOEFFEorILabel = uilabel(app.MainPanel);
            app.TRANSMCOEFFEorILabel.Text = 'TRANSM. COEFF, E or I = ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmithChart

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end