classdef SmithChartAdvancedSeries < matlab.apps.AppBase
    %SMITHCHARTADVANCEDSERIES Interactive Smith Chart
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2025
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia Furse
    %   (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   Portions of this code were generated with assistance from ChatGPT
    %   (OpenAI, 2025).
    %   
    %   Image: SmithChart.png
    %   Source: adapted from MTT-S, "Smithchart with logo", 2023. 
    %   Retrieved from: https://mtt.org/smith-chart/
    %   
    %   Image: SmithChartRuler.png
    %   Source: adapted from MTT-S, "Smithchart with logo", 2023. 
    %   Retrieved from: https://mtt.org/smith-chart/
    %   
    %   Interactive Smith Chart that allows user selected points and
    %   adjustable values. Includes an "automatic ruler" to read basic
    %   Smith Chart values and auto-updating entry boxes. Imaginary input
    %   and 'inf' is allowed. 
    %   
    %   Uses the Okabe–Ito palette (color blind friendly).

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        ShowYstubLocationButton     matlab.ui.control.Button
        ResettoStep0Button          matlab.ui.control.Button
        Step1ClicktoselectaZLPointLabel  matlab.ui.control.Label
        GridLayout2_2               matlab.ui.container.GridLayout
        Label_7                     matlab.ui.control.Label
        StubOpenorShortLabel        matlab.ui.control.Label
        ShortButton                 matlab.ui.control.Button
        OpenButton                  matlab.ui.control.Button
        GridLayout2                 matlab.ui.container.GridLayout
        Label_6                     matlab.ui.control.Label
        YaorYbLabel                 matlab.ui.control.Label
        YbButton                    matlab.ui.control.Button
        YaButton                    matlab.ui.control.Button
        ShowstartendpointsCheckBox  matlab.ui.control.CheckBox
        ZCirclesCheckBox            matlab.ui.control.CheckBox
        SWRCircleCheckBox           matlab.ui.control.CheckBox
        Y_Lcdot50Label              matlab.ui.control.Label
        Z_L50Label                  matlab.ui.control.Label
        Z_LLabel                    matlab.ui.control.Label
        MainPanel                   matlab.ui.container.Panel
        RFLCOEFFEorILabel           matlab.ui.control.Label
        TRANSMCOEFFEorILabel        matlab.ui.control.Label
        TRANSMCOEFFPLabel           matlab.ui.control.Label
        SWPEAKCONSTPLabel           matlab.ui.control.Label
        RFLLOSSdBLabel              matlab.ui.control.Label
        SWLOSSCOEFFLabel            matlab.ui.control.Label
        ATTENdbLabel                matlab.ui.control.Label
        RFLCOEFFPLabel              matlab.ui.control.Label
        RTNLOSSdBLabel              matlab.ui.control.Label
        dBSLabel                    matlab.ui.control.Label
        SWRLabel                    matlab.ui.control.Label
        SmithAxes                   matlab.ui.control.UIAxes
        X_LjLabel                   matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        R_LLabel                    matlab.ui.control.Label
        y_LEditField                matlab.ui.control.EditField
        y_LEditFieldLabel           matlab.ui.control.Label
        Y_LEditField                matlab.ui.control.EditField
        YLLabel                     matlab.ui.control.Label
        z_LEditField                matlab.ui.control.EditField
        z_LEditFieldLabel           matlab.ui.control.Label
        Z_LOmegaEditField           matlab.ui.control.EditField
        ZLLabel                     matlab.ui.control.Label
        RulerCheckBox               matlab.ui.control.CheckBox
        CircuitAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Below values are for SmithChart.png
        innerR = 1625; % Length (in pixels) of inner radius of Smith Chart
        outerR = 1875; % Length (in pixels) of the outer radius of the Smith Chart
        rulerPxLen = 1460; % Length (in pixels) of gamma = 1 for the Ruler
        indicatorR = 2000; % Length (in pixels) of the radius of the wavelength indicator

        Z0 = 50; % (ohms) Assumed characteristic impedance of transmission line

        ZL = 20+20*1i; % (ohms) Load impedance
        zL; % Normalized load impedance
        YL; % (mhos) Load admittance
        yL; % Normalized load admittance

        zin; % (Normalized) Input impedance
        zinGammaTheta; % Angle of gamma of zin (radians)
        zinGammaMag; % Magnitude of zin
        zLength = 0.15; % (wavelengths) Distance between Zin and ZL

        selectMode = "ZL"; % The particular select mode. Can be ZL or Zin.

        gamma; % Reflection coefficient 
        gammaMag = 0; % Magntude of reflection coefficient
        gammaAngle = 0; % (deg) Angle of reflection coefficient


        zLPoint; % Scatter handle for the current point on the Smith Chart
        zinPoint; % Scatter handle for the current Zin point on the Smith Chart
        imgIntersection; % Plot handle for the intersecting imaginary line
        realIntersection; % Plot handle for the intersecting real line
        swrCircle; % Plot handle for the SWR circle

        zLPointLabel;

        yLPoint;
        yLPointLabel;


        Ya;
        Yb;

        YaPoint;
        YbPoint;
        Ystub;

        YaLabel;
        YbLabel;
        YstubLabel;

        lambdaCircle2;
        wavelengthStartIndicator2;
        wavelengthEndIndicator2;

        lamdaZLLine;
        lamdaYabLine;

        clickEnabled = true; 
       
        YinLine1;
        YinLine2;

        YinOverlay;

        YinCircuitPt1; 
        YinCircuitPt2; 


        x1;
        x1Endlength;


        y1;
        y1Endlength;


        x2;
        x2Endlength;

        y2;
        y2Endlength;

        wavelengthTextCircuit2;
        wavelengthIndicatorCircuit2;

        stubStatus;

        shortLine;

        openTopDot;
        openBottomDot;

        priorTheta; % Theta value measured prior to a rotation step

        innerRuler; % Plot from the center to 'point' on the chart 
        outerRuler; % Plot from the 'point' to the outer radius on the chart

        lambdaCircle; % Plot from initial to ending point for rotating and measuring wavelength distance
        zinLine; % Plot to mark the initial line for rotation
        startingTheta; % Theta value for the start of measurement
        finalLine; % Plot to mark the final line for rotation
        rotatingNow = false; % Checks if the user has begun rotating or not
        rotateDirectionTxt; % Textbox that indicates the direction of rotation
        rotateDirection = "TWG"; % "TWG" (Toward Generator) or "TWL" (Toward Load)
        wavelengthIndicator; % Indicator to note the total wavelength travelled while measuring.
        wavelengthStartIndicator; % Indicator for the starting wavelength value on the chart
        wavelengthEndIndicator; % Indicator for the ending wavelength value on the chart
        rotateRing; % Patch handle for the particular ring in question for rotating
        circleQuality = 500; % Number of points in 1 full rotation of lambdaCircle

        % Circuit Diagram:
        ZLTextCircuit; % ZL indicator on the circuit diagram
        ZinTextCircuit; % Zin indicator on the circuit diagram
        wavelengthTextCircuit; % wavelength indicator on the circuit diagram
        wavelengthIndicatorCircuit; % Plot object to show distance from Zin or ZL
        zinCircuitPt1; % Scatter object for the top zin marker
        zinCircuitPt2; % Scatter object for the bottom zin marker

        mouseListener; % Listener object for selecting the Smith Chart

        % Array of plot handles for making the Smith Chart ruler markings. The index
        % of the array corresponds to the # ruler going from left to right
        % and top to bottom (ej - index 1 is the SWR ruler and index 3 is
        % RTN. LOSS [dB]). rulerMarkings will refer to the green line,
        % rulerPoints will refer to the point at the end of each marking.
        rulerMarkings = gobjects(1,7); 
        rulerPoints = gobjects(1,7);

        % Rotating dynamics
        rotateToggleOn = false; % Boolean to determine if the user is in the rotating state.
        mouseHeld; % Boolean to track if the mouse button is held

        % Okabe–Ito color blind friendly palette:
        okabeBlue = [0.0000 0.4471 0.6980];
        okabeReddishPurple = [204, 121, 167]/255;
        okabeBluishGreen = [0.0000, 0.6196, 0.4510];
        okabeSkyBlue = [0.3373, 0.7059, 0.9137];
        okabeOrange = [0.9020 0.6235 0];
        okabeYellow = [240, 228, 66]/255;
        okabeBlack = [0, 0, 0];                
        okabeVermilion = [213, 94, 0]/255;
        okabeLightVermilion = [0.835, 0.368, 0, 0.3];
        okabeLightTeal = [102, 194, 165]/255; 
        
        % Additional listener:
        mouseReleaseListener
    end

    methods (Access = private)

        % Updates the location of the point on the Smith Chart according
        % to current Smith Chart values. Updates the ruler markings and
        % Z circles as well
        function updateSmithPoint(app)
            % Update the z circles:
            
            updateZCircles(app);

            % Update SWR Circle:
            updateSWRCircle(app);

         
            % Update app.zLPoint location:
            r = abs(app.gamma);
            theta = angle(app.gamma);
            x = app.innerR*r*cos(theta);
            y = app.innerR*r*sin(theta);

            app.zLPoint.XData = x;
            app.zLPoint.YData = y;
            app.zLPointLabel.Position = [app.zLPoint.XData-150, app.zLPoint.YData+1];


            % Andrew: Yl Point plot
         

            % Adjust zin to be on the same SWR circle as ZL
            app.zinGammaMag = app.gammaMag;

            % Adjust the location of zin to prioritize the
            % entered length. TWG:
            deltaTheta = (2*pi/0.5)*app.zLength;
            app.zinGammaTheta = deg2rad(app.gammaAngle) - deltaTheta;
            zinGamma = app.zinGammaMag*exp(1i*app.zinGammaTheta);
            Zin = app.Z0*(1+zinGamma)/(1-zinGamma);
            app.zin = Zin/app.Z0;

            % app.ZinEditField.Value = sprintf('%.2f + %.2fj', real(app.zin), imag(app.zin));

            % Update its visual location:
            x = app.innerR*app.zinGammaMag*cos(app.zinGammaTheta);
            y = app.innerR*app.zinGammaMag*sin(app.zinGammaTheta);

            app.zinPoint.XData = x;
            app.zinPoint.YData = y;

            % Adjust start/end markings. Let the ruler be the
            % ending point:
            set(app.zinLine, 'XData', [0 app.outerR*cos(app.zinGammaTheta)], 'YData', [0 app.outerR*sin(app.zinGammaTheta)]);

            % Adjust distance marking on the Smith chart:
            % Update wavelength indicator:
            updateWavelengthIndicator(app, mod(app.zinGammaTheta, 2*pi), app.zLength)


            % Update starting/end point indicators:
            wavelengthStart = mod(deg2rad(app.gammaAngle) - pi, 2*pi)/(4*pi);
            wavelengthEnd = mod(app.zinGammaTheta - pi, 2*pi)/(4*pi);

            app.wavelengthStartIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle)];
            app.wavelengthStartIndicator.String = sprintf("%.3fλ", wavelengthStart);
            app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthEnd);
            app.wavelengthEndIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta) + 100]; % Always just above the wavelengthIndicator

            % Update the circuit diagram:
            circuitDiagramUpdate(app, app.zLength, true);


        end

        function updateZCircles(app)
            %UPDATEZCIRCLES Traces lines for the current point
            %   MYFUNCTION(APP) Traces lines for the current point for this
            %   app.
            %   This function will trace the real and imaginary lines for
            %   the current point plotted on the Smith Chart. It will do so
            %   by spanning over the real and imaginary components of ZL
            %   independently of one another and plotting.

            % Author: Carsen Villegas (with assistance from ChatGPT)
            % Date: 2025-06-04


            if app.gammaMag <= 0
                return;
            end

            % Extract the components of the current ZL
            realComponent = real(app.ZL);
            imgComponent = imag(app.ZL);

            % The real line, or circle, will be traced by maintaining the
            % real component of ZL and plotting over the full range of
            % imaginary values. A higher density of points centered around
            % 0 is needed to make the plot look like a smooth circle.
            N = 1000; % Number of points
            a = logspace(0, 4, N/2); % Higher density of points around 0
            A = [-fliplr(a), 0, a];  % full range from -1e4 to 1e4, clustered near 0
            realCircleSpan = realComponent.*ones(1, 1001) + A.*1i; % All ZL values to trace the line

            % Now using gamma to extract the exact x and y coordinates to
            % plot for all ZL values above
            gm = (realCircleSpan - app.Z0)./(realCircleSpan + app.Z0); % gamma
            r = abs(gm);
            theta = angle(gm);
            x = app.innerR.*r.*cos(theta);
            y = app.innerR.*r.*sin(theta);


            % realX = [x, x(1)]; % Appending the first data points to give the appearance of a full circle
            % realY = [y, y(1)];

            % Update the real intersection plot:

            % app.realIntersection.XData = realX;
            % app.realIntersection.YData = realY;


            % For the imaginary line will be traced by maintaining the
            % imaginary component of ZL and plotting over the full range of
            % real values. A higher density of points centered around 0 is
            % needed to make the plot look smooth. 0 is appended to the
            % real component to start at the very edge of the Smith Chart.
            imgCircleSpan = [0, logspace(0, 5, 1000)] + imgComponent.*ones(1,1001).*1i;

            gm = (imgCircleSpan - app.Z0)./(imgCircleSpan + app.Z0); % gamma
            r = abs(gm);
            theta = angle(gm);
            x = app.innerR.*r.*cos(theta);
            y = app.innerR.*r.*sin(theta);

            imgX = x;
            imgY = y;

            % Update the imaginary intersection plot:
            % app.imgIntersection.XData = imgX;
            % app.imgIntersection.YData = imgY;


            app.calculateYab();


        end

        function CreateMatchingCircle(app)
            %UPDATEZCIRCLES Traces lines for the current point
            %   MYFUNCTION(APP) Traces lines for the current point for this
            %   app.
            %   This function will trace the real and imaginary lines for
            %   the current point plotted on the Smith Chart. It will do so
            %   by spanning over the real and imaginary components of ZL
            %   independently of one another and plotting.

            % Author: Carsen Villegas (with assistance from ChatGPT)
            % Date: 2025-06-04

            % Extract the components of the current ZL
            realComponentMatching = real(50);

            % The real line, or circle, will be traced by maintaining the
            % real component of ZL and plotting over the full range of
            % imaginary values. A higher density of points centered around
            % 0 is needed to make the plot look like a smooth circle.
            N = 1000; % Number of points
            aMatching = logspace(0, 4, N/2); % Higher density of points around 0
            AMatching = [-fliplr(aMatching), 0, aMatching];  % full range from -1e4 to 1e4, clustered near 0
            realCircleSpanMatching = realComponentMatching.*ones(1, 1001) + AMatching.*1i; % All ZL values to trace the line

            % Now using gamma to extract the exact x and y coordinates to
            % plot for all ZL values above
            gm = (realCircleSpanMatching - app.Z0)./(realCircleSpanMatching + app.Z0); % gamma
            r = abs(gm);
            theta = angle(gm);
            x = app.innerR.*r.*cos(theta);
            y = app.innerR.*r.*sin(theta);

            realX = [x, x(1)]; % Appending the first data points to give the appearance of a full circle
            realY = [y, y(1)];


            % Update the real intersection plot:
            app.realIntersection.XData = realX;
            app.realIntersection.YData = realY;

           
        end

        function calculateYab(app)
             % TODO delete later: testing matching: it works
%            [xout, yout] = circcirc(0, 0, app.gammaMag*app.innerR, app.innerR/2, 0, app.innerR/2)
          %  scatter(app.SmithAxes, xout, yout)

            [app.Ya,app.Yb] = circcirc(0, 0, app.gammaMag*app.innerR, app.innerR/2, 0, app.innerR/2);


            app.YaPoint.XData = app.Ya(1);
            app.YaPoint.YData = app.Yb(1);

            app.YbPoint.XData = app.Ya(2);
            app.YbPoint.YData = app.Yb(2);

            % app.YaPoint.XData = app.Y;
            % app.YaPoint.YData = y;
            % 
            % app.YbPoint.XData = 
            % app.YbPoint.YData =

            app.YaLabel.Position = [app.YaPoint.XData-150, app.YaPoint.YData + 1];
            app.YbLabel.Position = [app.YbPoint.XData-150, app.YbPoint.YData + 1];

            stepTracker(app, 1);


        end


        % Imports and adjusts images to align with axes objects
        function loadImages(app)
            % Load Smith Chart image
            img = imread('SmithChart.png'); % Chart itself

            % Image dimensions:
            squareSize = 3820;

            halfSize = floor(squareSize / 2);
            [imgHeight, imgWidth, ~] = size(img);

            % (0,0) Coordinates in pixels:
            centerX = 1910;
            centerY = 1910;

            % Display on app.SmithAxes using image() 
            h = image(app.SmithAxes, img);  % Display the image
            set(h, 'Interpolation', 'bilinear');
            axis(app.SmithAxes, 'off');  % Hide axis ticks

            % Hold for overlay plots
            hold(app.SmithAxes, 'on');

            % Turn off title:
            app.SmithAxes.Title.Visible = 'off';

            % Align with plot:
            % Ensure square aspect ratio:
            pbaspect(app.SmithAxes, [1 1 1])
            axis(app.SmithAxes, 'manual');

            % Set axis limits to match the image dimensions, with (0, 0) centered
            % Ensure the center of the image corresponds to (0, 0) on the
            % axes 
            z = 200; % Additional padding to allow for rotation circle. 
            set(app.SmithAxes, 'XLim', [-halfSize-z halfSize+z], 'YLim', [-halfSize-z halfSize+z]);

            % Adjust the image position to place its center at (0, 0) in the axes
            set(h, 'XData', [-halfSize halfSize] + centerX - imgWidth/2, ...
                'YData', [halfSize -halfSize] + centerY - imgHeight/2);

            set(app.SmithAxes, 'YDir', 'normal');

            % % Load the ruler portion of the Smith Chart:
            % img = imread('SmithChartRuler.png');
            % 
            % % h = image(app.RulerAxes, img_small);
            % h = image(app.RulerAxes, img);
            % 
            % set(h, 'Interpolation', 'bilinear');
            % axis(app.RulerAxes, 'off');
            % hold(app.RulerAxes, 'on');
            % axis(app.RulerAxes, 'image')
            % 
            % app.RulerAxes.OuterPosition = app.RulerAxes.Position;
            % app.RulerAxes.Title.Visible = 'off';

            % Load Zin/ZL Circuit diagram:
            img = imread('CircuitDiagram.png');

            h = image(app.CircuitAxes, img);

            set(h, 'Interpolation', 'bilinear');
            axis(app.CircuitAxes, 'off');
            hold(app.CircuitAxes, 'on');
            axis(app.CircuitAxes, 'image')
            app.CircuitAxes.Title.Visible = 'off';
        end
       
        function updateSmithValues(app, userUpdate)
            %updateSmithValues Updates all other relevant values for the
            %Smith Chart includes changes to circuit diagram
            %   The user specifies which value was manually updated in the
            %   application. All other values are updated accordingly to
            %   avoid dependency errors. Also updates textboxes
            %   accordingly.
            %
            %   INPUTS:
            %     userUpdate - The value the user manually updated

            % gammaMag, gammaAngle, gamma, ZL, zL, YL, yL,

            % Author: Carsen Villegas (with assistance from ChatGPT)
            % Date: 2025-06-16

            switch userUpdate
                case 'gammaMag'
                    app.gamma = app.gammaMag*exp(1i*deg2rad(app.gammaAngle));

                    app.ZL = app.Z0*(1+app.gamma)/(1-app.gamma);
                    app.zL = app.ZL/app.Z0;

                    % Adjustment: Check Γ = arg(-1) case (irregular output)
                    if (mod(app.gammaAngle, 180) == 0) && (app.gammaMag == 1)
                        app.YL = inf;
                        app.yL = inf;
                    else
                        app.YL = 1/app.ZL;
                        app.yL = 1/app.zL;
                    end

                    % Update fields:
                    app.Z_LOmegaEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.z_LEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                    app.Y_LEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.y_LEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));
                    % 
                    % app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    % app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 
                case 'gammaAngle'

                    if isnan(app.gammaMag)
                        app.Z_LOmegaEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                        app.z_LEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                        app.Y_LEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                        app.y_LEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));

                        return; % skip calculations until user clicks
                    end


                    app.gamma = app.gammaMag*exp(1i*deg2rad(app.gammaAngle));

                    app.ZL = app.Z0*(1+app.gamma)/(1-app.gamma);
                    app.zL = app.ZL/app.Z0;

                    % Adjustment: Check Γ = arg(-1) case (irregular output)
                    if (mod(app.gammaAngle, 180) == 0) && (app.gammaMag == 1)
                        app.YL = inf;
                        app.yL = inf;
                    else
                        app.YL = 1/app.ZL;
                        app.yL = 1/app.zL;
                    end

                    app.Z_LOmegaEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.z_LEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                    app.Y_LEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.y_LEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));

                    % app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    % app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 
                case 'zL'
                    app.ZL = app.Z0*app.zL;
                    app.YL = 1/app.ZL;
                    app.yL = 1/app.zL;

                    % Check inf condition for ZL:
                    if app.ZL == inf
                        app.gamma = 1;
                    else
                        app.gamma = (app.ZL - app.Z0)/(app.ZL + app.Z0);
                    end

                    app.gammaMag = abs(app.gamma);
                    app.gammaAngle = rad2deg(angle(app.gamma));
                    % 
                    % app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    % app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    app.Z_LOmegaEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.Y_LEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.y_LEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));
                    app.z_LEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));

                case 'yL'
                    app.zL = 1/app.yL;
                    app.ZL = app.Z0*app.zL;
                    app.YL = 1/app.ZL;

                    % Check inf condition for ZL:
                    if app.ZL == inf
                        app.gamma = 1;
                    else
                        app.gamma = (app.ZL - app.Z0)/(app.ZL + app.Z0);
                    end

                    app.gammaMag = abs(app.gamma);
                    app.gammaAngle = rad2deg(angle(app.gamma));
                    % 
                    % app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    % app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    app.Z_LOmegaEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.Y_LEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.z_LEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));

                case 'ZL'
                    app.zL = app.ZL/app.Z0;
                    app.YL = 1/app.ZL;
                    app.yL = 1/app.zL;

                    % Check inf condition for ZL:
                    if app.ZL == inf
                        app.gamma = 1;
                    else
                        app.gamma = (app.ZL - app.Z0)/(app.ZL + app.Z0);
                    end

                    app.gammaMag = abs(app.gamma);
                    app.gammaAngle = rad2deg(angle(app.gamma));

                    % app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    % app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    app.z_LEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                    app.Y_LEditField.Value = sprintf('%.2f + %.2fj', real(app.YL), imag(app.YL));
                    app.y_LEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));

                case 'YL'
                    app.ZL = 1/app.YL;
                    app.zL = app.ZL/app.Z0;
                    app.yL = 1/app.zL;

                    % Check inf condition for ZL:
                    if app.ZL == inf
                        app.gamma = 1;
                    else
                        app.gamma = (app.ZL - app.Z0)/(app.ZL + app.Z0);
                    end

                    app.gammaMag = abs(app.gamma);
                    app.gammaAngle = rad2deg(angle(app.gamma));

                    % app.gammaMagEditField.Value = round(app.gammaMag, 2); 
                    % app.gammaAngleEditField.Value = round(app.gammaAngle, 2); 

                    app.Z_LOmegaEditField.Value = sprintf('%.2f + %.2fj', real(app.ZL), imag(app.ZL));
                    app.z_LEditField.Value = sprintf('%.2f + %.2fj', real(app.zL), imag(app.zL));
                    app.y_LEditField.Value = sprintf('%.2f + %.2fj', real(app.yL), imag(app.yL));
                otherwise
                    error("SmithChartIntermediate:InvalidInput", "Unsupported value:")
            end
        end

        
        % Checks to see if coordinates (x,y) are in bounds of the Smith
        % Chart
        function result = isInBounds(app, x, y)
            result = sqrt(x^2 + y^2) <= app.innerR;
        end

        % Checks to see if coordinates (x,y) are sufficiently close to the
        % Smith Chart for rotating. This allows the user to safely select
        % text boxes on the right without moving the point while in rotate
        % mode. 
        function result = isCloseEnough(app, x, y)
            buffer = 1500;
            result = sqrt(x^2 + y^2) <= app.outerR + buffer; % Outer radius + buffer is deemed "close enough" to the chart.
        end

        % Updates the automatic ruler from the center point through 'app.zLPoint'
        function updateRuler(app)
            r = abs(app.gamma);
            theta = angle(app.gamma);

            % 'point' location: (pixel location)
            x = app.innerR*r*cos(theta);
            y = app.innerR*r*sin(theta);

            % Update the ruler:
            set(app.innerRuler, 'XData', [0 x], 'YData', [0 y]);
            set(app.outerRuler, 'XData', [x app.outerR*cos(theta)], 'YData', [y app.outerR*sin(theta)]);
        end
        
        % Highlights the ring on the Smith Chart for the reflection coefficient
        function fillGammaAngle(app)
            theta = linspace(0, 2*pi, 500);  % Angle values
            ra = 3597 - 1910;  % Inner radius
            rb = 3659 - 1910;  % Outer radius

            % Outer circle (counterclockwise)
            x_outer = rb * cos(theta);
            y_outer = rb * sin(theta);

            % Inner circle (clockwise to avoid overlap issue)
            x_inner = fliplr(ra * cos(theta));
            y_inner = fliplr(ra * sin(theta));

            % Combine coordinates
            x_ring = [x_outer, x_inner];
            y_ring = [y_outer, y_inner];

            % Plot filled annulus
            h = fill(app.SmithAxes, x_ring, y_ring, app.okabeSkyBlue); 

            % Set transparency
            h.FaceAlpha = 0.3;

            % Remove the outline:
            h.EdgeColor = 'none';

            % Remove pickable parts:
            h.PickableParts = 'none';
        end

        % Highlights the ring on the Smith Chart for the rotation direction
        % according to app.rotateDirection
        function fillRotateDirection(app)
            theta = linspace(0, 2*pi, 500);  % Angle values
            
            % Establish radii according to rotate direction:
            if app.rotateDirection == "TWL"
                ra = 3659 - 1910;  % Inner radius
                rb = 3722 - 1910;  % Outer radius
            elseif app.rotateDirection == "TWG"
                ra = 3722 - 1910;  % Inner radius
                rb = 3783 - 1910;  % Outer radius
            end

            % Outer circle (counterclockwise)
            x_outer = rb * cos(theta);
            y_outer = rb * sin(theta);

            % Inner circle (clockwise to avoid overlap issue)
            x_inner = fliplr(ra * cos(theta));
            y_inner = fliplr(ra * sin(theta));

            % Combine coordinates
            x_ring = [x_outer, x_inner];
            y_ring = [y_outer, y_inner];

            % Plot filled annulus
            set(app.rotateRing, 'XData', x_ring, 'YData', y_ring)
        end
        
        % Updates the positioning of components within MainPanel. A custom
        % psuedo-grid layout is used to allow the Smith Chart and ruler to
        % be partially aligned, prioritizing the size of the Smith Chart.
        % The arrangement order is as follows: The Smith Chart is given a
        % weight of 9 out of 10 parts vertically. The Smith Chart, embedded
        % in "SmithAxes", has a fixed, square aspect ratio, so this
        % vertical weight will also expand the chart horizontally. Next,
        % the ruler's height (also with fixed original aspect ratio) is
        % adjusted so that it is properly aligned with the Smith Chart. For
        % simplicity and to prevent misalignement between the two images,
        % their widths are artifically expanded beyond the width of the
        % MainPanel. This prevents their images from shrinking based on
        % width. Alignment is, then, based on height. Finally, the ruler
        % markings text boxes are aligned with the MainPanel.
        function onResize(app)
            % Get the available space from the parent container
            panelPos = app.MainPanel.Position;  % [x, y, width, height]
            panelW = panelPos(3);
            panelH = panelPos(4);

            % Total vertical weight
            totalWeight = 10;

            % Row heights (based on weights)
            heightTop = (9 / totalWeight) * panelH;
            heightBottom = (1 / totalWeight) * panelH;

            % Optional margin (optional)
            spacing = 5;  % pixels between rows

            % Reduce for spacing
            heightTop = heightTop - spacing / 2;
            heightBottom = heightBottom - spacing / 2;

            % Horizontal position (full width)
            x = 0;
            w = panelW;

            % Vertical positions (bottom-up)
            yBottom = 0;
            yTop = heightBottom + spacing;

            % Set positions of the axes
            % app.RulerAxes.Units = 'pixels';
            app.SmithAxes.Units = 'pixels';

            % Values were obtained through linear regression and
            % correlating the ruler's height and the SmithAxes' height for
            % varying window sizes for aligned scenarios. A debugging tool
            % was used to manually alter the ruler's height to find aligned
            % scenarios. 
            % rH = (heightTop + 101.2)/5.641; This is the original equation
            % from Smith Basic to perfectly align the Chart and Ruler

            % This is the new equation with a manually adjusted denominator
            % to prioritize the weighting of the Smith Chart
            rH = (heightTop + 101.2)/7.5; 

            % Note: yTop has a 10 pixel subtraction to prevent the top of
            % the panel from covering up the Smith Chart. See the function
            % note for the reason of the width expansion.
            % app.RulerAxes.Position = [x - 1000, yBottom - 15, w + 2000, rH];
            app.SmithAxes.Position = [x - 1000, yTop - 20, w + 2000, heightTop];

            % Ensure the ruler labels are aligned properly. All labels will
            % have 22 height and width 180 (to avoid clipping values). 

            % Align the left values with the left side of the panel and
            % above the ruler:
            rulerTop = yBottom + rH; % y value of the top of the ruler
            labelMargin = 1; % Margin between labels
            labelWidth = 180;
            labelHeight = 22;
            app.SWRLabel.Position = [5, rulerTop + 4*labelHeight + labelMargin, labelWidth, labelHeight];
            app.dBSLabel.Position = [5, rulerTop + 3*labelHeight + labelMargin, labelWidth, labelHeight];
            app.RTNLOSSdBLabel.Position = [5, rulerTop + 2*labelHeight + labelMargin, labelWidth, labelHeight];
            app.RFLCOEFFPLabel.Position = [5, rulerTop + labelHeight + labelMargin, labelWidth, labelHeight];
            app.RFLCOEFFEorILabel.Position = [5, rulerTop, labelWidth, labelHeight];

            % Align the right values with the right side of the panel and
            % above the ruler:
            xLoc = panelW - labelWidth;
            app.ATTENdbLabel.Position = [xLoc - 5, rulerTop + 5*labelHeight + labelMargin, labelWidth, labelHeight];
            app.SWLOSSCOEFFLabel.Position = [xLoc - 5, rulerTop + 4*labelHeight + labelMargin, labelWidth, labelHeight];
            app.RFLLOSSdBLabel.Position = [xLoc - 5, rulerTop + 3*labelHeight + labelMargin, labelWidth, labelHeight];
            app.SWPEAKCONSTPLabel.Position = [xLoc - 5, rulerTop + 2*labelHeight + labelMargin, labelWidth, labelHeight];
            app.TRANSMCOEFFPLabel.Position = [xLoc - 5, rulerTop + labelHeight + labelMargin, labelWidth, labelHeight];
            app.TRANSMCOEFFEorILabel.Position = [xLoc - 5, rulerTop, labelWidth, labelHeight];

        end
        
        function updateRulerMarkings(app)
            % Updates the markings on the Smith Chart Ruler:

            % Calculate the corresponding pixel measurement:
            r = abs(app.gamma);
            innerRulerLength = r*app.rulerPxLen;

            app.rulerMarkings(1).YData = [222, 222];
            app.rulerMarkings(2).YData = [222, 222];

            app.rulerMarkings(3).YData = [335, 335];
            app.rulerMarkings(4).YData = [335, 335];

            app.rulerMarkings(5).YData = [447, 447];
            app.rulerMarkings(6).YData = [447, 447];

            app.rulerMarkings(7).YData = [559, 559];

            % For the first 6 measurements:
            for i = 1:6
                if mod(i, 2) == 0 % Right side of Center
                    app.rulerMarkings(i).XData = [1910 - innerRulerLength, 1910];
                else % Left side of center
                    app.rulerMarkings(i).XData = [1910 + innerRulerLength, 1910];
                end
            end

            app.rulerMarkings(7).XData = [450, 450 + app.rulerPxLen + innerRulerLength];

            % Set the rulerPoints:
            set(app.rulerPoints(1), 'XData', 1910 - innerRulerLength, 'YData', 222);
            set(app.rulerPoints(2), 'XData', 1910 + innerRulerLength, 'YData', 222);
            set(app.rulerPoints(3), 'XData', 1910 - innerRulerLength, 'YData', 335);
            set(app.rulerPoints(4), 'XData', 1910 + innerRulerLength, 'YData', 335);
            set(app.rulerPoints(5), 'XData', 1910 - innerRulerLength, 'YData', 447);
            set(app.rulerPoints(6), 'XData', 1910 + innerRulerLength, 'YData', 447);
            set(app.rulerPoints(7), 'XData', 450 + app.rulerPxLen + innerRulerLength, 'YData', 559);

        end
        
        % Updates the textboxes reporting values read off from the Smith
        % Chart ruler
        function updateRulerValues(app)
            SWR = (1 + app.gammaMag)/(1 - app.gammaMag);
            app.SWRLabel.Text = sprintf("SWR = %.2f", SWR);

            dBS = 20*log10(SWR);
            app.dBSLabel.Text = sprintf("dBS = %.2f", dBS);

            rtnLoss = -20*log10(app.gammaMag);
            app.RTNLOSSdBLabel.Text = sprintf("RTN. LOSS [dB] = %.2f", rtnLoss);

            rflCoeffP = app.gammaMag^2;
            app.RFLCOEFFPLabel.Text = sprintf("RFL. COEFF, P = %.2f", rflCoeffP);

            attenDB = -10*log10(app.gammaMag);
            app.ATTENdbLabel.Text = sprintf("ATTEN. [db] = %.2f", attenDB);

            swLossCoeff = (1+app.gammaMag^2)/(1 - app.gammaMag^2);
            app.SWLOSSCOEFFLabel.Text = sprintf("S.W. LOSS COEFF = %.2f", swLossCoeff);

            rflLossDB = -10*log10(1 - app.gammaMag^2);
            app.RFLLOSSdBLabel.Text = sprintf("RFL. LOSS [dB] = %.2f", rflLossDB);

            swPeak = sqrt(SWR);
            app.SWPEAKCONSTPLabel.Text = sprintf("S.W. PEAK (CONST. P) = %.2f", swPeak);

            transCoeffP = 1 - app.gammaMag^2;
            app.TRANSMCOEFFPLabel.Text = sprintf("TRANSM. COEFF, P = %.2f", transCoeffP);

            transCoeffE = 1 + app.gammaMag;
            app.TRANSMCOEFFEorILabel.Text = sprintf("TRANSM. COEFF, E or I = %.2f", transCoeffE);

            rflCoeffE = app.gammaMag;
            app.RFLCOEFFEorILabel.Text = sprintf("RFL. COEFF, E or I = %.2f", rflCoeffE);
        end
        
        function updateSWRCircle(app)

            if isnan(app.gammaMag)
                app.swrCircle.XData = NaN;
                app.swrCircle.YData = NaN;
                return;
            end

            theta = linspace(0, 2*pi, 500);  % Angle values
            r = abs(app.gamma) * app.innerR;

            % Outer circle (counterclockwise)
            x = r * cos(theta);
            y = r * sin(theta);

            % Update the plot: 
            set(app.swrCircle, 'XData', x, 'YData', y);
        end

        function mousePress(app) 
            tic % Start tracking time of the mouse press

            if app.rotateToggleOn
                % Temporarily add below mouse motion listener (allows real-time graphics updates):
                app.UIFigure.WindowButtonMotionFcn = @(src, event) mouseMoveWhileHeld(app);

                % Update mouse held condition:
                app.mouseHeld = true;
            end

        end

        function userClickResponse(app) 

            if ~app.clickEnabled
                return
            end 


            % Check if the rotate condition is active:
            if ~app.rotateToggleOn

                % Get the current point of the mouse in the UIAxes
                cp = app.SmithAxes.CurrentPoint;
                x = cp(1,1);
                y = cp(1,2);
    
                % Check if the user selected within the Smith Chart:
                inBounds = isInBounds(app, x, y);
    
                if inBounds
                    if app.selectMode == "ZL"
                        % Update values:
                        app.gammaMag = sqrt(x^2 + y^2)/app.innerR;
                        theta = atan2(y,x);
                        app.gammaAngle = rad2deg(theta);
                        updateSmithValues(app, 'gammaAngle')
        
                        % Visual update:
                        updateZCircles(app);
                        updateSWRCircle(app);
                        % updateRuler(app);
                        % updateRulerMarkings(app);
                        % updateRulerValues(app);
                        app.zLPoint.XData = x;
                        app.zLPoint.YData = y;
                        app.zLPointLabel.Position = [app.zLPoint.XData-150, app.zLPoint.YData+1];

                        % Andrew: Yl Point plot
                        % gammaY = -app.gamma;
                        % gammaMagY = abs(gammaY);
                        % gammaThetaY = angle(gammaY);
                        % 
                        % app.yLPoint.XData = app.innerR *gammaMagY*cos(gammaThetaY);
                        % app.yLPoint.YData = app.innerR *gammaMagY*sin(gammaThetaY);
                        % app.yLPointLabel.Position = [app.yLPoint.XData-150, app.yLPoint.YData+1];
                        % 


                        % Adjust zin to be on the same SWR circle as ZL
                        app.zinGammaMag = app.gammaMag;

                        % Adjust the location of zin to prioritize the
                        % entered length. TWG:
                        deltaTheta = (2*pi/0.5)*app.zLength;
                        app.zinGammaTheta = deg2rad(app.gammaAngle) - deltaTheta;
                        zinGamma = app.zinGammaMag*exp(1i*app.zinGammaTheta);
                        Zin = app.Z0*(1+zinGamma)/(1-zinGamma);
                        app.zin = Zin/app.Z0;

                        % app.ZinEditField.Value = sprintf('%.2f + %.2fj', real(app.zin), imag(app.zin));

                        % Update its visual location:
                        x = app.innerR*app.zinGammaMag*cos(app.zinGammaTheta);
                        y = app.innerR*app.zinGammaMag*sin(app.zinGammaTheta);

                        app.zinPoint.XData = x;
                        app.zinPoint.YData = y;

                        % Adjust start/end markings. Let the ruler be the
                        % ending point:
                        set(app.zinLine, 'XData', [0 app.outerR*cos(app.zinGammaTheta)], 'YData', [0 app.outerR*sin(app.zinGammaTheta)]);

                        % Adjust distance marking on the Smith chart:
                        % Update wavelength indicator:
                        % updateWavelengthIndicator(app, mod(app.zinGammaTheta, 2*pi), app.zLength)

                        % % Determine number of points in lambdaCircle:
                        % n = abs(round((app.circleQuality/(2*pi))*deltaTheta));
                        % thetaValues = linspace(deg2rad(app.gammaAngle), app.zinGammaTheta, n);
                        % 
                        % % Update the lambdaCircle
                        % xData = app.indicatorR * cos(thetaValues);
                        % yData = app.indicatorR * sin(thetaValues);
                        % set(app.lambdaCircle, 'XData', xData, 'YData', yData)

                        % % Update starting/end point indicators:
                        % wavelengthStart = mod(deg2rad(app.gammaAngle) - pi, 2*pi)/(4*pi);
                        % wavelengthEnd = mod(app.zinGammaTheta - pi, 2*pi)/(4*pi);
                        % 
                        % app.wavelengthStartIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle)]; 
                        % app.wavelengthStartIndicator.String = sprintf("%.3fλ", wavelengthStart);
                        % app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthEnd);
                        % app.wavelengthEndIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta) + 100]; % Always just above the wavelengthIndicator
                        % 


                    elseif app.selectMode == "Zin"
                        % Visual update zin point:
                        app.zinPoint.XData = x;
                        app.zinPoint.YData = y;

                        % Update its corresponding values:
                        app.zinGammaMag = sqrt(x^2 + y^2)/app.innerR;
                        app.zinGammaTheta = atan2(y,x);
                        zinGamma = app.zinGammaMag*exp(1i*app.zinGammaTheta);

                        Zin = app.Z0*(1+zinGamma)/(1-zinGamma);
                        app.zin = Zin/app.Z0;
                        % app.ZinEditField.Value = sprintf('%.2f + %.2fj', real(app.zin), imag(app.zin));

                        % Adjust ZL to be on the same SWR circle as Zin
                        app.gammaMag = app.zinGammaMag;
                        updateSmithValues(app, 'gammaMag')

                        % Adjust the location of zL to prioritize the
                        % entered length:
                        deltaTheta = (2*pi/0.5)*app.zLength;

                        % Adjust the location of ZL (if zin is starting
                        % point, we're going TWL). 
                        app.gammaAngle = rad2deg(app.zinGammaTheta + deltaTheta);
                        updateSmithValues(app, 'gammaAngle')
                        updateSmithPoint(app);

                        % Adjust start/end markings. 
                        set(app.zinLine, 'XData', [0 app.outerR*cos(app.zinGammaTheta)], 'YData', [0 app.outerR*sin(app.zinGammaTheta)], 'Visible', 'on');

                        % Adjust distance marking on the Smith chart:
                        % Update wavelength indicator:
                        updateWavelengthIndicator(app, mod(deg2rad(app.gammaAngle), 2*pi), app.zLength)

                        % Determine number of points in lambdaCircle:
                        n = abs(round((app.circleQuality/(2*pi))*deltaTheta));
                        thetaValues = linspace(app.zinGammaTheta, deg2rad(app.gammaAngle), n);

                        % Update the lambdaCircle
                        xData = app.indicatorR * cos(thetaValues);
                        yData = app.indicatorR * sin(thetaValues);
                        set(app.lambdaCircle, 'XData', xData, 'YData', yData)

                        % Update starting/end point indicators:
                        wavelengthStart = mod(app.zinGammaTheta - pi, 2*pi)/(4*pi);
                        wavelengthEnd = mod(deg2rad(app.gammaAngle) - pi, 2*pi)/(4*pi);

                        app.wavelengthStartIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta)];
                        app.wavelengthStartIndicator.String = sprintf("%.3fλ", wavelengthStart);
                        app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthEnd);
                        app.wavelengthEndIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle) + 100]; % Always just above the wavelengthIndicator

                        % Update the circuit diagram:
                        circuitDiagramUpdate(app, app.zLength, true);
                    end
                end
            end
        end


        function endMouseHold(app)
            app.mouseHeld = false;

            % Remove the mouse motion listener:
            app.UIFigure.WindowButtonMotionFcn = [];

            % Get length of mouse press:
            toc

            threshold = 0.2; % (s)

            if toc < threshold
                % Allow smith points to update. Assume no panning intention
                userClickResponse(app) 
            end
        end

        function mouseMoveWhileHeld(app)
            % Check mouse location:
            cp = app.SmithAxes.CurrentPoint;
            x = cp(1,1);
            y = cp(1,2);
            closeEnough = isCloseEnough(app, x, y);

            % Update if: rotate mode is on, the mouse is held down, and is
            % close enough the Smith Chart. 
            if app.rotateToggleOn && app.mouseHeld && closeEnough
                % Update theta:
                theta = atan2(y,x);

                rotationUpdate(app, theta); % Update the app during rotation mode
            end
        end

        % Manages user input while in rotation mode:
        function rotationUpdate(app, currentTheta)
            % Check if rotation has begun and initialize process:
            if app.rotatingNow == false   
                % The user has begun to actively rotate:
                app.rotatingNow = true;

                % Get the last theta value prior to rotation:
                app.priorTheta = deg2rad(app.gammaAngle);

                % Compute delta to establish direction (TWG or TWL)
                delta = mod(currentTheta - app.priorTheta, 2*pi);

                if delta <= pi
                    app.rotateDirection = "TWL"; % Counterclockwise direction
                    app.rotateDirectionTxt.String = "Toward the LOAD";

                    % Adjust buttons for continuity:
                    % app.z_LButton.Value = false;
                    % app.z_inButton.Value = true;
                    % app.selectMode = "Zin";
                else
                    app.rotateDirection = "TWG"; % Clockwise direction
                    app.rotateDirectionTxt.String = "Toward the GENERATOR";

                    % Adjust buttons for continuity:
                    % app.z_LButton.Value = true;
                    % app.z_inButton.Value = false;
                    % app.selectMode = "ZL";
                end

                % Set the starting point of rotation:
                set(app.zinLine, 'XData', [0 app.outerR*cos(app.priorTheta)], 'YData', [0 app.outerR*sin(app.priorTheta)], 'Visible', 'on');
                set(app.lambdaCircle, 'Visible', 'on');
                set(app.wavelengthIndicator, 'Visible', 'on');
                
                % Set the starting wavelength tag and point on chart and
                % circuit diagram:
                if app.rotateDirection == "TWL" % zL changes
                    set(app.zinPoint, 'XData', abs(app.gamma)*app.innerR*cos(app.priorTheta), 'YData', abs(app.gamma)*app.innerR*sin(app.priorTheta)) % Fix zin point
                    set(app.zinCircuitPt1, 'XData', 387, 'YData', 430, 'Visible', 'on')
                    set(app.zinCircuitPt2, 'XData', 387, 'YData', 1845, 'Visible', 'on')
                    wavelengthStart = mod(app.priorTheta - pi, 2*pi)/(4*pi);
                elseif app.rotateDirection == "TWG" % Zin changes
                    set(app.zLPoint, 'XData', abs(app.gamma)*app.innerR*cos(app.priorTheta), 'YData', abs(app.gamma)*app.innerR*sin(app.priorTheta)) % Fix zL point
                    set(app.zinCircuitPt1, 'XData', 3285, 'YData', 430, 'Visible', 'on')
                    set(app.zinCircuitPt2, 'XData', 3285, 'YData', 1845, 'Visible', 'on')
                    wavelengthStart = mod(-app.priorTheta - pi, 2*pi)/(4*pi);
                end

                % Highlight the appropriate ring:
                fillRotateDirection(app);

                % Update start indicator:
                app.wavelengthStartIndicator.Position = [app.indicatorR*cos(app.priorTheta) app.indicatorR*sin(app.priorTheta)];
                app.wavelengthStartIndicator.String = sprintf("%.3fλ", wavelengthStart);

                % Ensure wavelengthStart and End are visible if needed:
                ShowstartendpointsCheckBoxValueChanged(app);
                
                % Set the starting theta value for measurement:
                app.startingTheta = app.priorTheta;

                % Set visiblity on the circuit diagram:
                app.wavelengthIndicatorCircuit.Visible = 'on';

            else % Runs while rotating:
                % Unwrap currentTheta in relation to app.priorTheta (prevents
                % discontinuites at theta = +-pi and allows multiple
                % traces.
                unwrappedThetaValues = unwrap([app.priorTheta currentTheta]);
                currentTheta = unwrappedThetaValues(2);

                % Store currentTheta into app.priorTheta for next use
                % app.priorTheta = currentTheta;
                % Store currentTheta into app.priorTheta for next use
                app.priorTheta = currentTheta;

                % Update ZL point only
                if app.rotateDirection == "TWL"
                    delta = currentTheta - app.startingTheta;

                    app.gammaAngle = rad2deg(currentTheta);
                    updateSmithValues(app, 'gammaAngle')
                    updateSmithPoint(app)
                % Update Zin point only
                elseif app.rotateDirection == "TWG"
                    delta = app.startingTheta - currentTheta;
                    app.zinPoint.XData = app.gammaMag*app.innerR*cos(currentTheta);
                    app.zinPoint.YData = app.gammaMag*app.innerR*sin(currentTheta);

                    app.zinGammaTheta = currentTheta;
                    zinGamma = app.zinGammaMag*exp(1i*app.zinGammaTheta);

                    Zin = app.Z0*(1+zinGamma)/(1-zinGamma);
                    app.zin = Zin/app.Z0;
                    % app.ZinEditField.Value = sprintf('%.2f + %.2fj', real(app.zin), imag(app.zin));

                    set(app.zinLine, 'XData', [0 app.outerR*cos(app.zinGammaTheta)], 'YData', [0 app.outerR*sin(app.zinGammaTheta)]);
                end

                % Update wavelength indicator:
                conversionRatio = 0.5/(2*pi);
                wavelengths = conversionRatio*delta;
                updateWavelengthIndicator(app, mod(currentTheta, 2*pi), wavelengths)

                % Update length edit field
                app.LengthlambdaEditField.Value = wavelengths;
                app.zLength = wavelengths;

                % Determine number of points in lambdaCircle:
                n = abs(round((app.circleQuality/(2*pi))*delta));
                thetaValues = linspace(app.startingTheta, currentTheta, n);

                % Update the circle:
                xData = app.indicatorR * cos(thetaValues);
                yData = app.indicatorR * sin(thetaValues);
                set(app.lambdaCircle, 'XData', xData, 'YData', yData)

                % Update the circuit diagram:
                circuitDiagramUpdate(app, wavelengths, true)
            end
        end

        % Updates the location of the wavelength indicator on the Smith
        % Chart. Specifically the end indicator/measurement.
        function updateWavelengthIndicator(app, theta, wavelengths)
            % Update the text:
            app.wavelengthIndicator.String = sprintf("ℓ = %.3fλ", wavelengths);
            app.wavelengthIndicator.Position = [app.indicatorR*cos(theta) app.indicatorR*sin(theta)];

            % Set the starting wavelength tag:
            if app.rotateDirection == "TWL"
                wavelengthEnd = mod(theta - pi, 2*pi)/(4*pi);
            elseif app.rotateDirection == "TWG"
                wavelengthEnd = mod(-theta - pi, 2*pi)/(4*pi);
            end

            app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthEnd);
            app.wavelengthEndIndicator.Position = [app.indicatorR*cos(theta) app.indicatorR*sin(theta) + 100]; % Always just above the wavelengthIndicator
        end

        function circuitDiagramUpdate(app, wavelengths, zin)
            if zin % Zin changes:
                % Update wavelength:
                conversionRatio = 2837/(0.5); % Ratio of the length of the transmission line on the circuit diagram and max (expected) wavelengths to show.
                endingPoint = 3200 - conversionRatio*wavelengths;

                set(app.wavelengthIndicatorCircuit, 'XData', [endingPoint 3200], 'YData', [300 300])


                % Update zin indicator:
                set(app.zinCircuitPt1, 'XData', endingPoint, 'YData', 430, 'Visible', 'on')
                set(app.zinCircuitPt2, 'XData', endingPoint, 'YData', 1845, 'Visible', 'on')


                lineLength = 750;

                app.x1 = app.zinCircuitPt1.XData;
                app.x1Endlength = app.x1;


                app.y1 = app.zinCircuitPt1.YData;
                app.y1Endlength = app.y1 - lineLength;


                app.x2 = app.zinCircuitPt1.XData - 250;
                app.x2Endlength = app.x2;

                app.y2 = app.zinCircuitPt1.YData;
                app.y2Endlength = app.y2 - lineLength;


                set(app.YinLine1, 'XData', [app.x1 app.x1Endlength], 'YData', [app.y1 app.y1Endlength]);
                set(app.YinLine2, 'XData', [app.x2 app.x2Endlength], 'YData', [app.y2 app.y2Endlength]);

                set(app.YinOverlay, 'XData', [app.x1 app.x2], 'YData', [app.zinCircuitPt1.YData app.zinCircuitPt1.YData]);


                app.ZinTextCircuit.String = sprintf("z_{in}=%.2f+%.2fj", real(app.zin), imag(app.zin));
                app.ZLTextCircuit.String = sprintf("z_{L} =%.2f+%.2fj", real(app.zL), imag(app.zL));

                app.wavelengthTextCircuit.String = sprintf("ℓ=%.3fλ", wavelengths);
                app.wavelengthTextCircuit.Position = [endingPoint+100 100];


            elseif ~zin % ZL changes:
                % Update wavelength:
                conversionRatio = 1000/(0.5); % Ratio of the length of the transmission line on the circuit diagram and max (expected) wavelengths to show.


                % endingPointX1 = app.x1Endlength - (1/sqrt(2)) * wavelengths*conversionRatio;
                % endingPointY1 = app.y1Endlength + (1/sqrt(2)) *  wavelengths*conversionRatio;

                endingPointX1 = app.x1;
                endingPointY1 = app.y1 - wavelengths*conversionRatio;


                set(app.YinCircuitPt1, 'XData', endingPointX1, 'YData', endingPointY1, 'Visible', 'on');

                % endingPointX2 = app.x2Endlength - (1/sqrt(2)) * wavelengths*conversionRatio;
                % endingPointY2 = app.y2Endlength + (1/sqrt(2)) * wavelengths*conversionRatio;

                endingPointX2 = app.x2;
                endingPointY2 = app.y2 - wavelengths*conversionRatio;


                set(app.YinCircuitPt2, 'XData', endingPointX2, 'YData', endingPointY2, 'Visible', 'on');

                app.wavelengthTextCircuit2.Visible = 'on';
                app.wavelengthTextCircuit2.String = sprintf("ℓ=%.3fλ", wavelengths);
                app.wavelengthTextCircuit2.Position = [endingPointX2-900 endingPointY2];

                set(app.wavelengthIndicatorCircuit2, 'XData', [endingPointX2-150 endingPointX2-150], 'YData', [app.y1 endingPointY2+20]);

                if (app.stubStatus == "Short")
                    set(app.shortLine, 'XData', [endingPointX1 endingPointX2], 'YData', [endingPointY1 endingPointY2]);
                
                elseif (app.stubStatus == "Open")

                    app.openTopDot.XData = app.x1Endlength;
                    app.openTopDot.YData = app.y1Endlength;

                    app.openBottomDot.XData = app.x2Endlength;
                    app.openBottomDot.YData = app.y2Endlength;

                end

            end

       
        end

        % Updates the value of zin from manual updates for ZL:
        function updateFromZLChange(app)
            % Update other variables:
            updateSmithValues(app, 'ZL');
            updateSmithPoint(app);

            % Adjust zin to be on the same SWR circle as ZL
            app.zinGammaMag = app.gammaMag;

            % Adjust the location of zin to prioritize the
            % entered length. TWG:
            deltaTheta = (2*pi/0.5)*app.zLength;
            app.zinGammaTheta = deg2rad(app.gammaAngle) - deltaTheta;
            zinGamma = app.zinGammaMag*exp(1i*app.zinGammaTheta);
            Zin = app.Z0*(1+zinGamma)/(1-zinGamma);
            app.zin = Zin/app.Z0;


            % app.ZinEditField.Value = sprintf('%.2f + %.2fj', real(app.zin), imag(app.zin));

            % Update its visual location:
            x = app.innerR*app.zinGammaMag*cos(app.zinGammaTheta);
            y = app.innerR*app.zinGammaMag*sin(app.zinGammaTheta);

            app.zinPoint.XData = x;
            app.zinPoint.YData = y;

            % Adjust start/end markings. Let the ruler be the
            % ending point:
            set(app.zinLine, 'XData', [0 app.outerR*cos(app.zinGammaTheta)], 'YData', [0 app.outerR*sin(app.zinGammaTheta)]);

            % Adjust distance marking on the Smith chart:
            % Update wavelength indicator:
            updateWavelengthIndicator(app, mod(app.zinGammaTheta, 2*pi), app.zLength)

            % Determine number of points in lambdaCircle:
            n = abs(round((app.circleQuality/(2*pi))*deltaTheta));
            thetaValues = linspace(deg2rad(app.gammaAngle), app.zinGammaTheta, n);

            % Update the lambdaCircle
            xData = app.indicatorR * cos(thetaValues);
            yData = app.indicatorR * sin(thetaValues);
            set(app.lambdaCircle, 'XData', xData, 'YData', yData)

            % Update starting/end point indicators:
            wavelengthStart = mod(deg2rad(app.gammaAngle) - pi, 2*pi)/(4*pi);
            wavelengthEnd = mod(app.zinGammaTheta - pi, 2*pi)/(4*pi);

            app.wavelengthStartIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle)];
            app.wavelengthStartIndicator.String = sprintf("%.3fλ", wavelengthStart);
            app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthEnd);
            % Always just above the wavelengthIndicator:
            app.wavelengthEndIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta) + 100];

            % Change the starting point mode to zL:
            % 
            % app.z_LButton.Value = true;
            % z_LButtonValueChanged(app);

            % Update the circuit diagram: 
            circuitDiagramUpdate(app, app.zLength, true);
        end

        function stepTracker(app, step)

            switch step
                
                % Before user click
                case 0
                    
                    app.Step1ClicktoselectaZLPointLabel.Text = 'Step 1: Click to select a ZL Point';
                    buttons = [app.OpenButton, app.ShortButton, app.YaButton, app.YbButton];
                    set(buttons,'Enable', 'off');
                    app.ShowYstubLocationButton.Visible = 'off';

                % User clicks
                case 1
                    app.clickEnabled = false; 
                    app.Step1ClicktoselectaZLPointLabel.Text = 'Step 2: Choose between Ya/Yb';
                    buttons = [app.YaButton, app.YbButton];
                    set(buttons,'Enable', 'on');


                    % Ya or Yb
                case 2
                    buttons = [app.YaButton, app.YbButton];
                    set(buttons,'Enable', 'off');

                    app.ShowYstubLocationButton.Visible = 'on';
                    app.ShowYstubLocationButton.Text = 'Show Ystub Location';
                    app.Step1ClicktoselectaZLPointLabel.Text = 'Step 3: Click to Continue';


                    % Open/Closed
                case 3
                    buttons = [app.OpenButton, app.ShortButton, app.YaButton, app.YbButton];
                    set(buttons,'Enable', 'off');

                    app.Step1ClicktoselectaZLPointLabel.Text = 'Step 5: Complete';

                otherwise
                    error("Invalid step");
            end
                
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Order you plot each of the objects is order they will appear
            % Load image:
            loadImages(app);

            % Disable data tips:
            datacursormode(app.UIFigure, 'off');

            % Color the angle of reflection coefficient:
            fillGammaAngle(app);

            % Set listener for selecting points on the Smith Chart
            app.mouseListener = event.listener(app.UIFigure,'WindowMousePress', @(src,evt) mousePress(app));

            % Set listener for rotating:
            % Initialize control flags
            app.mouseHeld = false;  % Tracks whether the mouse is being held

            % Add additional listener to help with rotating:
            app.mouseReleaseListener = event.listener(app.UIFigure, 'WindowMouseRelease', @(src,evt) endMouseHold(app));

            % Set listener for resizing the app window:
            app.MainPanel.AutoResizeChildren = 'off';
            app.MainPanel.SizeChangedFcn = @(~, ~) onResize(app);

            % Initialize ruler plots:
            app.innerRuler = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Visible', 'off', 'Color', app.okabeBluishGreen);
            app.innerRuler.PickableParts = 'none';
            app.outerRuler = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeSkyBlue, 'Visible', 'off'); % alpha = .4
            app.outerRuler.PickableParts = 'none';

            % Initialize ruler marking handles:
            % for i = 1:7
            %     app.rulerMarkings(i) = plot(app.RulerAxes, nan, nan, 'LineWidth', 3, 'Visible', 'on', 'Color', app.okabeBluishGreen);
            % %     app.rulerM2arkings(i).PickableParts = 'none';
            % end
            % 
            % % Doing a separate loop ensures these points are on top in the
            % % UI stack:
            % for i = 1:7
            %     app.rulerPoints(i) = scatter(app.RulerAxes, nan, nan, 50, 'filled', 'Visible', 'on');
            %     app.rulerPoints(i).CData = app.okabeOrange;
            %     app.rulerPoints(i).PickableParts = 'none';
            % end

            % Initialize the intersecting line plots:
            app.imgIntersection = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeBlue, 'Visible', 'off'); % Blue line
            app.imgIntersection.PickableParts = 'none';
            app.realIntersection = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeReddishPurple, 'Visible', 'on'); % Reddish purple line
            app.realIntersection.PickableParts = 'none';

            % Initialize SWR circle plot:
            app.swrCircle = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeVermilion, 'Visible', 'on');
            app.swrCircle.PickableParts = 'none';

            % Change ZL and zL text fields' color: 
            app.Z_LOmegaEditField.FontColor = app.okabeOrange;
            app.z_LEditField.FontColor = app.okabeOrange;

            % Set up lambda measurements for TWG and TWL:
            app.rotateDirectionTxt = text(app.SmithAxes, 1500, 1800, "Toward the GENERATOR", 'FontSize', 16, 'FontWeight', 'bold');
            app.rotateDirectionTxt.Visible = 'on';

            % Initialize starting line for rotating:
            app.zinLine = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', 'k', 'Visible', 'off', 'PickableParts', 'none');
            
            % Initialize the wavelength indicator elements:
            app.wavelengthIndicator = text(app.SmithAxes, 0, 0, 'ℓ = ', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 16, 'FontWeight','bold', 'Visible', 'off');
            app.lambdaCircle = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeLightVermilion, 'Visible', 'off', 'PickableParts', 'none');
            app.wavelengthStartIndicator = text(app.SmithAxes, 0, 0, 'ℓ start = ', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 12, 'FontWeight','bold', 'Color', app.okabeLightVermilion, 'Visible', 'off');
            app.wavelengthEndIndicator = text(app.SmithAxes, 0, 0, 'ℓ end = ', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 12, 'FontWeight','bold', 'Color', app.okabeLightVermilion, 'Visible', 'off');

            % Initialize the rotateRing patch object:
            app.rotateRing = fill(app.SmithAxes, nan, nan, app.okabeYellow);
            app.rotateRing.FaceAlpha = 0.3;
            app.rotateRing.EdgeColor = 'none';
            app.rotateRing.PickableParts = 'none';


            app.YinLine1 = line(app.CircuitAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeBlack, 'Visible', 'on', 'PickableParts', 'none');
            app.YinLine2 = line(app.CircuitAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeBlack, 'Visible', 'on', 'PickableParts', 'none');

            app.YinOverlay = line(app.CircuitAxes, nan, nan, 'LineWidth', 4, 'Color', 'w', 'Visible', 'on', 'PickableParts', 'none');


            % Initialize zin markers for the circuit diagram:
            app.zinCircuitPt1 = scatter(app.CircuitAxes, nan, nan, 75, 'filled', 'Marker', '>', 'Visible', 'on', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [0.835, 0.368, 0], 'PickableParts', 'none');
            app.zinCircuitPt2 = scatter(app.CircuitAxes, nan, nan, 75, 'filled', 'Marker', '>', 'Visible', 'on', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [0.835, 0.368, 0], 'PickableParts', 'none');


            app.lamdaZLLine =  line(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeBlack, 'Visible', 'on', 'PickableParts', 'none');
            app.lamdaYabLine = line(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeBlack, 'Visible', 'on', 'PickableParts', 'none');


            % Initialize app.zinPoint:
            app.zinPoint = scatter(app.SmithAxes, nan, nan, 100, 'filled', 'Marker', '>', 'Visible', 'off', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', app.okabeBlue);
            app.zinPoint.PickableParts = 'none';

            % Initialize app.zLPoint:
            app.zLPoint = scatter(app.SmithAxes, nan, nan, 100, 'filled');
            app.zLPoint.CData = app.okabeOrange;
            app.zLPoint.PickableParts = 'none';


            %Andrew Code 


            app.lambdaCircle2 = plot(app.SmithAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeSkyBlue, 'Visible', 'off', 'PickableParts', 'none');
            app.wavelengthStartIndicator2 = text(app.SmithAxes, 0, 0, 'ℓ start = ', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 12, 'FontWeight','bold', 'Color', app.okabeSkyBlue, 'Visible', 'off');
            app.wavelengthEndIndicator2 = text(app.SmithAxes, 0, 0, 'ℓ end = ', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 12, 'FontWeight','bold', 'Color', app.okabeSkyBlue, 'Visible', 'off');


            app.zLPointLabel = text(app.SmithAxes, nan, nan, 'z_L', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight','bold', 'Visible', 'on');

            app.yLPoint = scatter(app.SmithAxes, nan, nan, 100, 'filled');
            app.yLPoint.CData = app.okabeLightTeal;
            app.yLPoint.PickableParts = 'none';


            app.YaPoint = scatter(app.SmithAxes, nan, nan, 100, 'filled');
            app.YaPoint.CData = app.okabeLightTeal;
            app.YaPoint.PickableParts = 'none';


            app.YbPoint = scatter(app.SmithAxes, nan, nan, 100, 'filled');
            app.YbPoint.CData = app.okabeLightTeal;
            app.YbPoint.PickableParts = 'none';


            app.Ystub = scatter(app.SmithAxes, nan, nan, 100, 'filled', 'Marker','s');
            app.Ystub.CData = app.okabeLightTeal;
            app.Ystub.PickableParts = 'none';


            app.YstubLabel = text(app.SmithAxes, nan, nan, 'Ystub', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight','bold', 'Visible', 'on');
            app.YaLabel = text(app.SmithAxes, nan, nan, 'Ya', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight','bold', 'Visible', 'on');
            app.YbLabel = text(app.SmithAxes, nan, nan, 'Yb', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight','bold', 'Visible', 'on');
            app.yLPointLabel =  text(app.SmithAxes, nan, nan, 'y_L', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight','bold', 'Visible', 'on');
         
            app.shortLine = plot(app.CircuitAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeBlack, 'Visible', 'on', 'PickableParts', 'none');


            app.YinCircuitPt1 = scatter(app.CircuitAxes, nan, nan, 75, 'filled', 'Marker', '>', 'Visible', 'on', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', app.okabeSkyBlue, 'PickableParts', 'none');
            app.YinCircuitPt2 = scatter(app.CircuitAxes, nan, nan, 75, 'filled', 'Marker', '>', 'Visible', 'on', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', app.okabeSkyBlue, 'PickableParts', 'none');


            app.wavelengthTextCircuit2 = text(app.CircuitAxes, 3200, 300, 'ℓ=???', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight','bold', 'Color', app.okabeSkyBlue, 'Visible', 'off');
            app.wavelengthIndicatorCircuit2 = plot(app.CircuitAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeSkyBlue, 'Visible', 'on', 'PickableParts', 'none');

            app.openTopDot = scatter(app.CircuitAxes, nan, nan, 100, 'filled', 'Marker','s');
            app.openTopDot.CData = app.okabeBlack;
            app.openTopDot.PickableParts = 'none';

            app.openBottomDot = scatter(app.CircuitAxes, nan, nan, 100, 'filled', 'Marker','s');
            app.openBottomDot.CData = app.okabeBlack;
            app.openBottomDot.PickableParts = 'none';


            %Initialize Matching Circle 
            CreateMatchingCircle(app)

            % Set up the circuit diagram:
            app.ZLTextCircuit = text(app.CircuitAxes, 3175, 1094, 'z_{L}= ???', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight','bold', 'Visible', 'on');
            app.ZinTextCircuit = text(app.CircuitAxes, 393, 1094, 'z_{in}=???', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight','bold', 'Visible', 'on');
            app.wavelengthTextCircuit = text(app.CircuitAxes, 3200, 300, 'ℓ=???', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight','bold', 'Color', app.okabeLightVermilion,'Visible', 'on');
            app.wavelengthIndicatorCircuit = plot(app.CircuitAxes, nan, nan, 'LineWidth', 2, 'Color', app.okabeLightVermilion, 'Visible', 'on', 'PickableParts', 'none');


            stepTracker(app, 0);


            % Initialize setup using the current value of ZL:
           % Z_LOmegaEditFieldValueChanged(app);


            % TODO delete later: testing matching: it works
%            [xout, yout] = circcirc(0, 0, app.gammaMag*app.innerR, app.innerR/2, 0, app.innerR/2)
          %  scatter(app.SmithAxes, xout, yout)
        end

        % Value changed function: Z_LOmegaEditField
        function Z_LOmegaEditFieldValueChanged(app, event)
           
            if ~app.clickEnabled
                return
            end 


            str = app.Z_LOmegaEditField.Value; % e.g., '3+4i'
            try
                val = str2num(str);  % Safely evaluates string
                if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
                    error('Invalid input');
                end
            catch
                uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
                return;
            end

            app.ZL = val;  % Store the complex number

            % Update the diagrams and zin values from this manual ZL change
            updateFromZLChange(app);

            % Handle rotate state:
            % if app.RotateToggleButton.Value
            %     app.RotateToggleButton.Value = false;
            %     app.RotateToggleButtonValueChanged(app);
            % end

        end

        % Callback function
        function gammaMagEditFieldValueChanged(app, event)
            % app.gammaMag = app.gammaMagEditField.Value;
            % updateSmithValues(app, 'gammaMag');
            % updateSmithPoint(app);
            % 
            % % Update the diagrams and zin values from this manual ZL change
            % updateFromZLChange(app);
        end

        % Callback function
        function gammaAngleEditFieldValueChanged(app, event)
            % app.gammaAngle = app.gammaAngleEditField.Value;
            % updateSmithValues(app, 'gammaAngle');
            % updateSmithPoint(app);
            % 
            % % Update the diagrams and zin values from this manual ZL change
            % updateFromZLChange(app);
            % 
            % % Handle rotate state:
            % if app.RotateToggleButton.Value
            %     app.RotateToggleButton.Value = false;
            %     app.RotateToggleButtonValueChanged(app);
            % end
            
        end

        % Value changed function: z_LEditField
        function z_LEditFieldValueChanged(app, event)
            
            if ~app.clickEnabled
                return
            end 

            
            str = app.z_LEditField.Value;
            try
                val = str2num(str);  % Safely evaluates string
                if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
                    error('Invalid input');
                end
            catch
                uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
                return;
            end

            app.zL = val;  % Store the complex number
            % Update other variables:
            updateSmithValues(app, 'zL');
            updateSmithPoint(app)

            % Update the diagrams and zin values from this manual ZL change
            updateFromZLChange(app);

            % Handle rotate state:
            % if app.RotateToggleButton.Value
            %     app.RotateToggleButton.Value = false;
            %     app.RotateToggleButtonValueChanged(app);
            % end
        end

        % Value changed function: y_LEditField
        function y_LEditFieldValueChanged(app, event)
            if ~app.clickEnabled
                return
            end 


            str = app.y_LEditField.Value;
            try
                val = str2num(str);  % Safely evaluates string
                if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
                    error('Invalid input');
                end
            catch
                uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
                return;
            end

            app.yL = val;  % Store the complex number

            % Update other variables:
            updateSmithValues(app, 'yL');
            updateSmithPoint(app)

            % Update the diagrams and zin values from this manual ZL change
            updateFromZLChange(app);

            % Handle rotate state:
            % if app.RotateToggleButton.Value
            %     app.RotateToggleButton.Value = false;
            %     app.RotateToggleButtonValueChanged(app);
            % end
            
        end

        % Value changed function: Y_LEditField
        function Y_LEditFieldValueChanged(app, event)
            
            
            if ~app.clickEnabled
                return
            end 

            
            str = app.Y_LEditField.Value;
            try
                val = str2num(str);  % Safely evaluates string
                if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
                    error('Invalid input');
                end
            catch
                uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
                return;
            end

            app.YL = val;  % Store the complex number
            % Update other variables:
            updateSmithValues(app, 'YL');
            updateSmithPoint(app)

            % Update the diagrams and zin values from this manual ZL change
            updateFromZLChange(app);

            % Handle rotate state:
            % if app.RotateToggleButton.Value
            %     app.RotateToggleButton.Value = false;
            %     app.RotateToggleButtonValueChanged(app);
            % end
            
        end

        % Callback function
        function RulerCheckBoxValueChanged(app, event)
            value = app.RulerCheckBox.Value;
            
            if value
                app.innerRuler.Visible = 'on';
                app.outerRuler.Visible = 'on';

                for i = 1:7
                    app.rulerMarkings(i).Visible = 'on';
                    app.rulerPoints(i).Visible = 'on';
                end
            else
                app.innerRuler.Visible = 'off';
                app.outerRuler.Visible = 'off';

                for i = 1:7
                    app.rulerMarkings(i).Visible = 'off';
                    app.rulerPoints(i).Visible = 'off';
                end
            end

        end

        % Callback function
        function SWRCircleCheckBoxValueChanged(app, event)
            value = app.SWRCircleCheckBox.Value;

            if value
                app.swrCircle.Visible = 'on';
            else
                app.swrCircle.Visible = 'off';
            end
            
        end

        % Callback function
        function RotateToggleButtonValueChanged(app, event)
            % value = app.RotateToggleButton.Value;
            % 
            % if value
            %     app.rotateToggleOn = true;
            %     app.RotateToggleButton.Text = "Rotate: ON";
            % 
            %     app.rotateDirectionTxt.String = "Toward ...";
            %     app.ResetRotationButton.Enable = 'on';
            % 
            %     % Temporarily disable panning:
            %     app.SmithAxes.InteractionOptions.PanSupported = 'off';
            % 
            %     % Enable ruler and SWR circle:
            %     app.SWRCircleCheckBox.Value = true;
            %     app.RulerCheckBox.Value = true;
            %     SWRCircleCheckBoxValueChanged(app);
            %     RulerCheckBoxValueChanged(app);
            % 
            %     % Turn off Z-circles:
            %     app.ZCirclesCheckBox.Value = false;
            %     ZCirclesCheckBoxValueChanged(app);
            % 
            %     % Set the lambdaCircle and starting/endpoints to not
            %     % visible
            % 
            %     set(app.lambdaCircle, 'XData', nan, 'YData', nan);
            %     app.wavelengthStartIndicator.Position = [nan nan];
            %     app.wavelengthEndIndicator.Position = [nan nan];
            %     app.wavelengthIndicator.Position = [nan nan];
            % 
            %     % Grey out select-mode buttons:
            %     % app.z_LButton.Enable = false;
            %     % app.z_inButton.Enable = false;
            % 
            %     % Set both points to the starting point:
            %     % ZLStart = app.z_LButton.Value;
            % 
            %     if ZLStart
            %         % Set zin to the location of ZL:
            %         set(app.zinPoint, 'XData', app.zLPoint.XData, 'YData', app.zLPoint.YData);
            %         app.zin = app.zL;
            %         % app.ZinEditField.Value = sprintf('%.2f + %.2fj', real(app.zin), imag(app.zin)); % AND update the circuit diagram
            %         set(app.zinLine, 'XData', [0 app.outerR*cosd(app.gammaAngle)], 'YData', [0 app.outerR*sind(app.gammaAngle)]);
            %         app.zLength = 0;
            %         app.LengthlambdaEditField.Value = 0;
            %         circuitDiagramUpdate(app, app.zLength);
            %     else % Zin is the starting point
            %         set(app.zLPoint, 'XData', app.zinPoint.XData, 'YData', app.zinPoint.YData);
            % 
            %         app.zL = app.zin;
            %         updateSmithValues(app, 'zL')
            %         updateSmithPoint(app);
            %         app.zLength = 0;
            %         app.LengthlambdaEditField.Value = 0;
            %         circuitDiagramUpdate(app, app.zLength);
            %     end
            % 
            % else
            %     app.rotateToggleOn = false;
            %     app.RotateToggleButton.Text = "Rotate: OFF";
            %     app.ResetRotationButton.Enable = 'off';
            %     app.rotatingNow = false;
            % 
            %     % Enable panning:
            %     app.SmithAxes.InteractionOptions.PanSupported = 'on';
            % 
            %     % Remove grey-out for select-mode buttons:
            %     % app.z_LButton.Enable = true;
            %     % app.z_inButton.Enable = true;
            % end

        end

        % Callback function
        function ResetRotationButtonPushed(app, event)
            app.rotatingNow = false;

            set(app.lambdaCircle, 'XData', nan, 'YData', nan);
            app.wavelengthStartIndicator.Position = [nan nan];
            app.wavelengthEndIndicator.Position = [nan nan];
            app.wavelengthIndicator.Position = [nan nan];

            % ZLStart = app.z_LButton.Value;

            if ZLStart
                % Set zin to the location of ZL:
                set(app.zinPoint, 'XData', app.zLPoint.XData, 'YData', app.zLPoint.YData);
                app.zin = app.zL;
                % app.ZinEditField.Value = sprintf('%.2f + %.2fj', real(app.zin), imag(app.zin)); % AND update the circuit diagram
                set(app.zinLine, 'XData', [0 app.outerR*cosd(app.gammaAngle)], 'YData', [0 app.outerR*sind(app.gammaAngle)]);
                app.zLength = 0;
                app.LengthlambdaEditField.Value = 0;
                circuitDiagramUpdate(app, app.zLength, true);
            else % Zin is the starting point
                set(app.zLPoint, 'XData', app.zinPoint.XData, 'YData', app.zinPoint.YData);
                app.zL = app.zin;
                updateSmithValues(app, 'zL')
                updateSmithPoint(app);
                app.zLength = 0;
                app.LengthlambdaEditField.Value = 0;
                circuitDiagramUpdate(app, app.zLength, true);
            end
        end

        % Callback function
        function ZCirclesCheckBoxValueChanged(app, event)
            value = app.ZCirclesCheckBox.Value;
            
            if value
                app.realIntersection.Visible = 'on';
                app.imgIntersection.Visible = 'on';
            else
                app.realIntersection.Visible = 'off';
                app.imgIntersection.Visible = 'off';
            end
        end

        % Callback function
        function ShowstartendpointsCheckBoxValueChanged(app, event)
            value = app.ShowstartendpointsCheckBox.Value;
            
            if value
                app.wavelengthStartIndicator.Visible = 'on';
                app.wavelengthEndIndicator.Visible = 'on';
            else
                app.wavelengthStartIndicator.Visible = 'off';
                app.wavelengthEndIndicator.Visible = 'off';
            end

        end

        % Callback function
        function z_LButtonValueChanged(app, event)
            % value = app.z_LButton.Value;
            % 
            % % Handle the Z_in button:f
            % if value
            %     app.z_inButton.Value = false;
            %     app.selectMode = "ZL";
            %     app.rotateDirection = "TWG";
            % 
            %     app.rotateDirectionTxt.String = "Toward the GENERATOR";
            % 
            %     % Update indicators:
            %     app.wavelengthIndicator.String = sprintf("ℓ = %.3fλ", app.zLength);
            %     app.wavelengthIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta)];
            % 
            %     wavelengthStart = mod(deg2rad(app.gammaAngle) - pi, 2*pi)/(4*pi);
            %     wavelengthEnd = mod(app.zinGammaTheta - pi, 2*pi)/(4*pi);
            % 
            %     app.wavelengthEndIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta) + 100];
            %     app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthStart);
            % 
            %     app.wavelengthStartIndicator.String = sprintf("%.3fλ", wavelengthEnd);
            %     app.wavelengthStartIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle)]; % Always just above the wavelengthIndicator
            % else
            %     % Update the circuit diagram:
            %     set(app.zinCircuitPt1, 'XData', 387, 'YData', 430)
            %     set(app.zinCircuitPt2, 'XData', 387, 'YData', 1845)
            % 
            %     app.z_inButton.Value = true;
            %     app.selectMode = "Zin";
            %     app.rotateDirection = "TWL";
            % 
            %     app.rotateDirectionTxt.String = "Toward the LOAD";
            % 
            %     app.wavelengthIndicator.String = sprintf("ℓ = %.3fλ", app.zLength);
            %     app.wavelengthIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle)];
            % 
            %     wavelengthStart = mod(app.zinGammaTheta - pi, 2*pi)/(4*pi);
            %     wavelengthEnd = mod(deg2rad(app.gammaAngle) - pi, 2*pi)/(4*pi);
            % 
            %     app.wavelengthStartIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta)];
            %     app.wavelengthStartIndicator.String = sprintf("%.3fλ", wavelengthStart);
            % 
            %     app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthEnd);
            %     app.wavelengthEndIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle) + 100]; % Always just above the wavelengthIndicator
            % end
            % 
            % % Rotate direction and diagram update:
            % fillRotateDirection(app);
            % circuitDiagramUpdate(app, app.zLength);
        end

        % Callback function
        function z_inButtonValueChanged(app, event)
            % value = app.z_inButton.Value;
            % 
            % % Handle the ZL button:
            % if value
            %     app.z_LButton.Value = false;
            %     app.selectMode = "Zin";
            %     app.rotateDirection = "TWL";
            % 
            %     % Update the circuit diagram:
            %     set(app.zinCircuitPt1, 'XData', 387, 'YData', 430)
            %     set(app.zinCircuitPt2, 'XData', 387, 'YData', 1845)
            % 
            %     app.rotateDirectionTxt.String = "Toward the LOAD";
            % 
            %     app.wavelengthIndicator.String = sprintf("ℓ = %.3fλ", app.zLength);
            %     app.wavelengthIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle)];
            % 
            %     wavelengthStart = mod(app.zinGammaTheta - pi, 2*pi)/(4*pi);
            %     wavelengthEnd = mod(deg2rad(app.gammaAngle) - pi, 2*pi)/(4*pi);
            % 
            %     app.wavelengthStartIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta)];
            %     app.wavelengthStartIndicator.String = sprintf("%.3fλ", wavelengthStart);
            % 
            %     app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthEnd);
            %     app.wavelengthEndIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle) + 100]; % Always just above the wavelengthIndicator
            % else
            %     app.z_LButton.Value = true;
            %     app.selectMode = "ZL";
            %     app.rotateDirection = "TWG";
            % 
            %     app.rotateDirectionTxt.String = "Toward the GENERATOR";
            % 
            %     % Update indicators:
            %     app.wavelengthIndicator.String = sprintf("ℓ = %.3fλ", app.zLength);
            %     app.wavelengthIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta)];
            % 
            %     wavelengthStart = mod(deg2rad(app.gammaAngle) - pi, 2*pi)/(4*pi);
            %     wavelengthEnd = mod(app.zinGammaTheta - pi, 2*pi)/(4*pi);
            % 
            %     app.wavelengthEndIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta) + 100];
            %     app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthStart);
            % 
            %     app.wavelengthStartIndicator.String = sprintf("%.3fλ", wavelengthEnd);
            %     app.wavelengthStartIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle)]; % Always just above the wavelengthIndicator
            % end
            % 
            % % Direction and diagram update:
            % fillRotateDirection(app);
            % circuitDiagramUpdate(app, app.zLength);
        end

        % Callback function
        function ZinEditFieldValueChanged(app, event)
            % str = app.ZinEditField.Value; % e.g., '3+4i'
            % try
            %     val = str2num(str);  % Safely evaluates string
            %     if ~isnumeric(val) || ~isscalar(val) || real(val) < 0
            %         error('Invalid input');
            %     end
            % catch
            %     uialert(app.UIFigure, 'Please enter a valid complex impedance.', 'Input Error');
            %     return;
            % end
            % 
            % app.zin = val;  % Store the complex number
            % 
            % % Change the starting point mode to zin:
            % app.z_inButton.Value = true;
            % z_inButtonValueChanged(app);
            % 
            % % Check inf condition for zin:
            % Zin = app.zin * 50;
            % 
            % if app.zin == inf
            %     zinGamma = 1;
            % else
            %     zinGamma = (Zin - app.Z0)/(Zin + app.Z0);
            % end
            % 
            % app.zinGammaMag = abs(zinGamma);
            % app.zinGammaTheta = angle(zinGamma);
            % 
            % x = app.innerR*app.zinGammaMag*cos(app.zinGammaTheta);
            % y = app.innerR*app.zinGammaMag*sin(app.zinGammaTheta);
            % 
            % app.zinPoint.XData = x;
            % app.zinPoint.YData = y;
            % 
            % % Update its corresponding values:
            % app.zinGammaMag = sqrt(x^2 + y^2)/app.innerR;
            % app.zinGammaTheta = atan2(y,x);
            % zinGamma = app.zinGammaMag*exp(1i*app.zinGammaTheta);
            % 
            % Zin = app.Z0*(1+zinGamma)/(1-zinGamma);
            % app.zin = Zin/app.Z0;
            % app.ZinEditField.Value = sprintf('%.2f + %.2fj', real(app.zin), imag(app.zin));
            % 
            % % Adjust ZL to be on the same SWR circle as Zin
            % app.gammaMag = app.zinGammaMag;
            % updateSmithValues(app, 'gammaMag')
            % 
            % % Adjust the location of ZL to prioritize the
            % % entered length:
            % deltaTheta = (2*pi/0.5)*app.zLength;
            % 
            % % Adjust the location of ZL (if zin is starting
            % % point, we're going TWL).
            % app.gammaAngle = rad2deg(app.zinGammaTheta + deltaTheta);
            % updateSmithValues(app, 'gammaAngle')
            % updateSmithPoint(app);
            % 
            % % Adjust start/end markings.
            % set(app.zinLine, 'XData', [0 app.outerR*cos(app.zinGammaTheta)], 'YData', [0 app.outerR*sin(app.zinGammaTheta)], 'Visible', 'on');
            % 
            % % Adjust distance marking on the Smith chart:
            % % Update wavelength indicator:
            % updateWavelengthIndicator(app, mod(deg2rad(app.gammaAngle), 2*pi), app.zLength)
            % 
            % % Determine number of points in lambdaCircle:
            % n = abs(round((app.circleQuality/(2*pi))*deltaTheta));
            % thetaValues = linspace(app.zinGammaTheta, deg2rad(app.gammaAngle), n);
            % 
            % % Update the lambdaCircle
            % xData = app.indicatorR * cos(thetaValues);
            % yData = app.indicatorR * sin(thetaValues);
            % set(app.lambdaCircle, 'XData', xData, 'YData', yData)
            % 
            % % Update starting/end point indicators:
            % wavelengthStart = mod(app.zinGammaTheta - pi, 2*pi)/(4*pi);
            % wavelengthEnd = mod(deg2rad(app.gammaAngle) - pi, 2*pi)/(4*pi);
            % 
            % app.wavelengthStartIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta)];
            % app.wavelengthStartIndicator.String = sprintf("%.3fλ", wavelengthStart);
            % app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthEnd);
            % app.wavelengthEndIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle) + 100]; % Always just above the wavelengthIndicator
            % 
            % % Update the circuit diagram:
            % circuitDiagramUpdate(app, app.zLength);
            
        
        end

        % Callback function
        function LengthlambdaEditFieldValueChanged(app, event)
            app.zLength = app.LengthlambdaEditField.Value;

            % Handle rotate state:
            % if app.RotateToggleButton.Value
            %     app.RotateToggleButton.Value = false;
            %     app.RotateToggleButtonValueChanged(app);
            % end

            % Adjust the location of the end point:
            if app.selectMode == "Zin"
                % Adjust the location of ZL:
                deltaTheta = (2*pi/0.5)*app.zLength;

                % Adjust the location of ZL (if zin is starting
                % point, we're going TWL).
                app.gammaAngle = rad2deg(app.zinGammaTheta + deltaTheta);
                updateSmithValues(app, 'gammaAngle')
                updateSmithPoint(app);

                % Adjust distance marking on the Smith chart:
                % Update wavelength indicator:
                updateWavelengthIndicator(app, mod(deg2rad(app.gammaAngle), 2*pi), app.zLength)

                % Determine number of points in lambdaCircle:
                n = abs(round((app.circleQuality/(2*pi))*deltaTheta));
                thetaValues = linspace(app.zinGammaTheta, deg2rad(app.gammaAngle), n);

                % Update the lambdaCircle
                xData = app.indicatorR * cos(thetaValues);
                yData = app.indicatorR * sin(thetaValues);
                set(app.lambdaCircle, 'XData', xData, 'YData', yData)

                % Update starting/end point indicators:
                wavelengthEnd = mod(deg2rad(app.gammaAngle) - pi, 2*pi)/(4*pi);
                app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthEnd);
                app.wavelengthEndIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle) + 100]; % Always just above the wavelengthIndicator

                circuitDiagramUpdate(app, app.zLength, true);

            elseif app.selectMode == "ZL"
                % Adjust the location of zin:
                deltaTheta = (2*pi/0.5)*app.zLength;

                % Adjust the location of Zzin (if zin is starting
                % point, we're going TWL).
                app.zinGammaTheta = deg2rad(app.gammaAngle) - deltaTheta;
                zinGamma = app.zinGammaMag*exp(1i*app.zinGammaTheta);
                Zin = app.Z0*(1+zinGamma)/(1-zinGamma);
                app.zin = Zin/app.Z0;

                % Update the zin point and its line:
                x = app.innerR*app.zinGammaMag*cos(app.zinGammaTheta);
                y = app.innerR*app.zinGammaMag*sin(app.zinGammaTheta);

                app.zinPoint.XData = x;
                app.zinPoint.YData = y;

                % app.ZinEditField.Value = sprintf('%.2f + %.2fj', real(app.zin), imag(app.zin));

                set(app.zinLine, 'XData', [0 app.outerR*cos(app.zinGammaTheta)], 'YData', [0 app.outerR*sin(app.zinGammaTheta)]);

                % Adjust distance marking on the Smith chart:
                % Update wavelength indicator:
                updateWavelengthIndicator(app, mod(app.zinGammaTheta, 2*pi), app.zLength)

                % Determine number of points in lambdaCircle:
                n = abs(round((app.circleQuality/(2*pi))*deltaTheta));
                thetaValues = linspace(deg2rad(app.gammaAngle), app.zinGammaTheta, n);

                % Update the lambdaCircle
                xData = app.indicatorR * cos(thetaValues);
                yData = app.indicatorR * sin(thetaValues);
                set(app.lambdaCircle, 'XData', xData, 'YData', yData)

                % Update starting/end point indicators:
                wavelengthEnd = mod(app.zinGammaTheta - pi, 2*pi)/(4*pi);
                app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthEnd);
                app.wavelengthEndIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta) + 100]; % Always just above the wavelengthIndicator

                circuitDiagramUpdate(app, app.zLength, true);
            end
        end

        % Button pushed function: YaButton
        function YaButtonPushed(app, event)
             app.YaPoint.XData 
             app.YaPoint.YData


             gMag = sqrt(app.YaPoint.XData ^2 + app.YaPoint.YData^2)/app.innerR;
             theta = atan2(app.YaPoint.YData,app.YaPoint.XData );
             app.gammaAngle = rad2deg(theta);

             g = app.gammaMag*exp(1i*deg2rad(app.gammaAngle));

        
            YaEq= app.Z0*(1+g)/(1-g);


            % Extract the components of the current ZL
            realComponent = real(YaEq);
            imgComponent = imag(YaEq);


            imgCircleSpan = [0, logspace(0, 5, 1000)] + imgComponent.*ones(1,1001).*1i;

            gm = (imgCircleSpan - app.Z0)./(imgCircleSpan + app.Z0); % gamma
            r = abs(gm);
            theta = angle(gm);
            x = app.innerR.*r.*cos(theta);
            y = app.innerR.*r.*sin(theta);

            imgX = x;
            imgY = y;

            % Update the imaginary intersection plot:
            app.imgIntersection.XData = imgX;
            app.imgIntersection.YData = imgY;


            app.Ystub.XData = imgX(1);
            app.Ystub.YData = imgY(1) * -1;
            app.YstubLabel.Position = [app.Ystub.XData-150, app.Ystub.YData + 75];
            app.Ystub.Visible = 'off';
            app.YstubLabel.Visible = 'off';
             
            app.imgIntersection.Visible = "on";


            gamma_start = app.gamma;
            phi_start = angle(gamma_start);

            gamma_end = (app.YaPoint.XData + 1i*app.YaPoint.YData) / app.innerR;
            gamma_end = gamma_end(1);
            phi_end = angle(gamma_end);

            dphi = mod(phi_start - phi_end, 2*pi);   % always positive
             
            lambdaAtAngle = @(phi) mod(pi - phi, 2*pi) / (4*pi);

            wavelengthStart = lambdaAtAngle(phi_start);
            wavelengthEnd   = lambdaAtAngle(phi_end);

            wavelengths = abs(dphi)/(4*pi);


            thetaArc = linspace(phi_start, phi_start - dphi, 720);
        
            xData = app.indicatorR * cos(thetaArc);
            yData = app.indicatorR * sin(thetaArc);
            set(app.lambdaCircle, 'XData', xData, 'YData', yData);
            app.lambdaCircle.Visible = 'on';

            app.wavelengthStartIndicator.Position = [ app.indicatorR*cos(phi_start), app.indicatorR*sin(phi_start) ];
            app.wavelengthStartIndicator.String   = sprintf('%.3fλ', wavelengthStart);
            app.wavelengthStartIndicator.Visible = 'on';

            app.wavelengthEndIndicator.Position = [ app.indicatorR*cos(phi_end), app.indicatorR*sin(phi_end) ] + [0, 0.02*app.indicatorR];
            app.wavelengthEndIndicator.String   = sprintf('%.3fλ', wavelengthEnd);
            app.wavelengthEndIndicator.Visible = 'on';


            app.lamdaZLLine.XData = [app.innerR*real(gamma_start) app.innerR*cos(phi_start)];
            app.lamdaZLLine.YData = [app.innerR*imag(gamma_start) app.innerR*sin(phi_start)];

            app.lamdaYabLine.XData = [app.innerR*real(gamma_end) app.innerR*cos(phi_end)];
            app.lamdaYabLine.YData = [app.innerR*imag(gamma_end) app.innerR*sin(phi_end)];

           % uistack(app.yLPoint, 'top');
           % uistack(app.YaPoint, 'top');


           circuitDiagramUpdate(app, wavelengths, true);
           app.YinLine1.Visible = 'on';
           app.YinLine2.Visible = 'on';
           app.YinOverlay.Visible = 'on';


            app.YbPoint.XData = NaN;
            app.YbPoint.YData = NaN;

            app.YbLabel.Position = [NaN NaN];

            stepTracker(app, 2);


                       % 
                       % 
                       % % Update starting/end point indicators:
                       %  wavelengthStart = mod(deg2rad(app.gammaAngle) - pi, 2*pi)/(4*pi);
                       %  wavelengthEnd = mod(app.zinGammaTheta - pi, 2*pi)/(4*pi);
                       % 
                       %  app.wavelengthStartIndicator.Position = [app.indicatorR*cosd(app.gammaAngle) app.indicatorR*sind(app.gammaAngle)]; 
                       %  app.wavelengthStartIndicator.String = sprintf("%.3fλ", wavelengthStart);
                       %  app.wavelengthEndIndicator.String = sprintf("%.3fλ", wavelengthEnd);
                       %  app.wavelengthEndIndicator.Position = [app.indicatorR*cos(app.zinGammaTheta) app.indicatorR*sin(app.zinGammaTheta) + 100]; % Always just above the wavelengthIndicator
                       % 
                       % 


        end

        % Button pushed function: YbButton
        function YbButtonPushed(app, event)
             app.YbPoint.XData 
             app.YbPoint.YData
           

             gMag = sqrt(app.YbPoint.XData ^2 + app.YbPoint.YData^2)/app.innerR;
             theta = atan2(app.YbPoint.YData,app.YbPoint.XData );
             app.gammaAngle = rad2deg(theta);

             g = app.gammaMag*exp(1i*deg2rad(app.gammaAngle));

        
            YbEq= app.Z0*(1+g)/(1-g);


            % Extract the components of the current ZL
            realComponent = real(YbEq);
            imgComponent = imag(YbEq);


            imgCircleSpan = [0, logspace(0, 5, 1000)] + imgComponent.*ones(1,1001).*1i;

            gm = (imgCircleSpan - app.Z0)./(imgCircleSpan + app.Z0); % gamma
            r = abs(gm);
            theta = angle(gm);
            x = app.innerR.*r.*cos(theta);
            y = app.innerR.*r.*sin(theta);

            imgX = x;
            imgY = y;

            % Update the imaginary intersection plot:
            app.imgIntersection.XData = imgX;
            app.imgIntersection.YData = imgY;

            app.Ystub.XData = imgX(1);
            app.Ystub.YData = imgY(1) * -1;
            app.YstubLabel.Position = [app.Ystub.XData-150, app.Ystub.YData + 75];
            app.Ystub.Visible = 'off';
            app.YstubLabel.Visible = 'off';

             
            app.imgIntersection.Visible = "on";


            gamma_start = app.gamma;
            phi_start = angle(gamma_start);

            gamma_end = (app.YbPoint.XData + 1i*app.YbPoint.YData) / app.innerR;
            gamma_end = gamma_end(1);
            phi_end = angle(gamma_end);


            dphi = mod(phi_start - phi_end, 2*pi);   % always positive
             
            lambdaAtAngle = @(phi) mod(pi - phi, 2*pi) / (4*pi);

            wavelengthStart = lambdaAtAngle(phi_start);
            wavelengthEnd   = lambdaAtAngle(phi_end);

            wavelengths = abs(dphi)/(4*pi);


            thetaArc = linspace(phi_start, phi_start - dphi, 720);
        
            xData = app.indicatorR * cos(thetaArc);
            yData = app.indicatorR * sin(thetaArc);
            set(app.lambdaCircle, 'XData', xData, 'YData', yData);
            app.lambdaCircle.Visible = 'on';

            app.wavelengthStartIndicator.Position = [ app.indicatorR*cos(phi_start), app.indicatorR*sin(phi_start) ];
            app.wavelengthStartIndicator.String   = sprintf('%.3fλ', wavelengthStart);
            app.wavelengthStartIndicator.Visible = 'on';

            app.wavelengthEndIndicator.Position = [ app.indicatorR*cos(phi_end), app.indicatorR*sin(phi_end) ] + [0, 0.02*app.indicatorR];
            app.wavelengthEndIndicator.String   = sprintf('%.3fλ', wavelengthEnd);
            app.wavelengthEndIndicator.Visible = 'on';


            app.lamdaZLLine.XData = [app.innerR*real(gamma_start) app.innerR*cos(phi_start)];
            app.lamdaZLLine.YData = [app.innerR*imag(gamma_start) app.innerR*sin(phi_start)];

            app.lamdaYabLine.XData = [app.innerR*real(gamma_end) app.innerR*cos(phi_end)];
            app.lamdaYabLine.YData = [app.innerR*imag(gamma_end) app.innerR*sin(phi_end)];


            circuitDiagramUpdate(app, wavelengths, true);
            app.YinLine1.Visible = 'on';
            app.YinLine2.Visible = 'on';
            app.YinOverlay.Visible = 'on';


            app.YaPoint.XData = NaN;
            app.YaPoint.YData = NaN;

            app.YaLabel.Position = [NaN NaN];


            stepTracker(app, 2);

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)


            gamma_start = -1 +0i;
            phase_start = angle(gamma_start);

            gamma_stub = (app.Ystub.XData + 1i  * (app.Ystub.YData))/app.innerR;
            gamma_stub = gamma_stub(1);
            phase_end = angle(gamma_stub);

            start_pos = mod(phase_start, 2*pi); 
            end_pos = mod(phase_end, 2*pi);

            dphi_raw = end_pos - start_pos;
            dphi = -1 * mod(-1 * dphi_raw, 2*pi);


            % Relative electrical length traveled
            lambda_rel = abs(dphi) / (4*pi);

            % Reference for Smith chart
            % OPEN = 0.00 λ, SHORT = 0.25 λ
            lambda_ref = 0;    % because gamma_start is OPEN

            % Accumulated Smith-chart wavelength
            wavelengthStart = lambda_ref;
            wavelengthEnd   = lambda_rel;
            thetaArc = linspace(start_pos, start_pos + dphi, 720);


            xData = (app.indicatorR + .05*app.indicatorR)  * cos(thetaArc);
            yData = (app.indicatorR + .05*app.indicatorR)  * sin(thetaArc);
            set(app.lambdaCircle2, 'XData', xData, 'YData', yData);
            app.lambdaCircle2.Visible = 'on';

            app.wavelengthStartIndicator2.Position = [ app.indicatorR*cos(start_pos), app.indicatorR*sin(start_pos) ];
            app.wavelengthStartIndicator2.String   = sprintf('%.3fλ', wavelengthStart);
            app.wavelengthStartIndicator2.Visible = 'on';

            app.wavelengthEndIndicator2.Position = [ app.indicatorR*cos(end_pos), app.indicatorR*sin(end_pos) ] + [0, 0.02*app.indicatorR];
            app.wavelengthEndIndicator2.String   = sprintf('%.3fλ', wavelengthEnd);
            app.wavelengthEndIndicator2.Visible = 'on';


            wavelengths = abs(dphi)/(4*pi);

            app.stubStatus = "Open";


            circuitDiagramUpdate(app, wavelengths, false);


            % 
            % buttons = [app.OpenButton, app.ShortButton];
            % set(buttons,'Enable', 'off');

            stepTracker(app, 3);


        end

        % Button pushed function: ShortButton
        function ShortButtonPushed(app, event)
            gamma_start = 1 +0i;
            phase_start = 0;

            gamma_stub = (app.Ystub.XData + 1i  * (app.Ystub.YData))/app.innerR;
            gamma_stub = gamma_stub(1);
            phase_end = angle(gamma_stub);

            start_pos = mod(phase_start, 2*pi);
            end_pos = mod(phase_end, 2*pi);

            dphi_raw = end_pos - start_pos;
            dphi = -1 * mod(-1 * dphi_raw, 2*pi);


            % Relative electrical length traveled
            lambda_rel = abs(dphi) / (4*pi);

            % Reference for Smith chart
            % OPEN = 0.00 λ, SHORT = 0.25 λ
            lambda_ref = 0.25;    % because gamma_start is SHORT

            % Accumulated Smith-chart wavelength
            wavelengthStart = lambda_ref;
            wavelengthEnd   = lambda_ref + lambda_rel;


            thetaArc = linspace(start_pos, start_pos + dphi, 720);
        
            xData = (app.indicatorR + .05*app.indicatorR) * cos(thetaArc);
            yData = (app.indicatorR + .05*app.indicatorR) * sin(thetaArc);
            set(app.lambdaCircle2, 'XData', xData, 'YData', yData);
            app.lambdaCircle2.Visible = 'on';

            app.wavelengthStartIndicator2.Position = [ app.indicatorR*cos(start_pos), app.indicatorR*sin(start_pos) ];
            app.wavelengthStartIndicator2.String   = sprintf('%.3fλ', wavelengthStart);
            app.wavelengthStartIndicator2.Visible = 'on';

            app.wavelengthEndIndicator2.Position = [ app.indicatorR*cos(end_pos), app.indicatorR*sin(end_pos) ] + [0, 0.02*app.indicatorR];
            app.wavelengthEndIndicator2.String   = sprintf('%.3fλ', wavelengthEnd);
            app.wavelengthEndIndicator2.Visible = 'on';

            wavelengths = abs(dphi)/(4*pi);
            
            app.stubStatus = "Short";

            circuitDiagramUpdate(app, wavelengths, false);


            stepTracker(app, 3);


            % 
            % buttons = [app.OpenButton, app.ShortButton];
            % set(buttons,'Enable', 'off');


        end

        % Button pushed function: ResettoStep0Button
        function ResettoStep0ButtonPushed(app, event)
    stepTracker(app, 0);

    % ==============================
    % RESET CORE STATE VARIABLES
    % ==============================
    app.gammaMag = NaN;
    app.gammaAngle = NaN;

    app.zin = NaN + 1i*NaN;
    app.zL  = NaN + 1i*NaN;

    app.zinGammaMag   = 0;
    app.zinGammaTheta = 0;

    app.zLength = 0;

    app.clickEnabled = true;

    app.stubStatus = NaN;


    % ==============================
    % RESET SMITH CHART POINTS & LINES
    % ==============================
    app.zLPoint.XData  = NaN;
    app.zLPoint.YData  = NaN;

    app.zinPoint.XData = NaN;
    app.zinPoint.YData = NaN;

    app.zinLine.XData  = [NaN NaN];
    app.zinLine.YData  = [NaN NaN];

    app.zLPointLabel.Position = [NaN NaN];

    app.yLPoint.XData = NaN;
    app.yLPoint.YData = NaN;
    app.yLPointLabel.Position = [NaN NaN];


    app.lamdaZLLine.XData = NaN;
    app.lamdaZLLine.YData = NaN;

    app.lamdaYabLine.XData = NaN;
    app.lamdaYabLine.YData = NaN;

    app.YinLine1.XData = NaN;
    app.YinLine1.YData = NaN;


    app.YinLine2.XData = NaN;
    app.YinLine2.YData = NaN;

    app.YinCircuitPt1.XData = NaN;
    app.YinCircuitPt1.YData = NaN;

    app.YinCircuitPt2.XData = NaN;
    app.YinCircuitPt2.YData = NaN;

    app.YinOverlay.XData = NaN;
    app.YinOverlay.YData = NaN;

    app.YinLine1.Visible = 'off';
    app.YinLine2.Visible = 'off';
    app.YinOverlay.Visible = 'off';


    app.wavelengthTextCircuit2.Visible = 'off';

    app.wavelengthIndicatorCircuit2.XData = NaN;
    app.wavelengthIndicatorCircuit2.YData = NaN;

    app.shortLine.XData = NaN;
    app.shortLine.YData = NaN;

    app.openTopDot.XData = NaN;
    app.openTopDot.YData = NaN;

    app.openBottomDot.XData = NaN;
    app.openBottomDot.YData = NaN;


    % ==============================
    % RESET Ya / Yb INTERSECTIONS
    % ==============================
    app.Ya = [];
    app.Yb = [];

    app.YaPoint.XData = NaN;
    app.YaPoint.YData = NaN;

    app.YbPoint.XData = NaN;
    app.YbPoint.YData = NaN;

    app.YaLabel.Position = [NaN NaN];
    app.YbLabel.Position = [NaN NaN];

    % ==============================
    % RESET IMAGINARY INTERSECTION & STUB
    % ==============================
    app.imgIntersection.XData = NaN;
    app.imgIntersection.YData = NaN;

    app.Ystub.XData = NaN;
    app.Ystub.YData = NaN;

    app.YstubLabel.Position = [NaN NaN];

    % ==============================
    % RESET LAMBDA ARC & INDICATORS
    % ==============================
    app.lambdaCircle.XData = NaN;
    app.lambdaCircle.YData = NaN;

    app.wavelengthStartIndicator.Position = [NaN NaN];
    app.wavelengthEndIndicator.Position   = [NaN NaN];

    app.lambdaCircle2.XData = NaN;
    app.lambdaCircle2.YData = NaN;

    app.wavelengthStartIndicator2.Position = [NaN NaN];
    app.wavelengthEndIndicator2.Position   = [NaN NaN];


    % ==============================
    % RESET CIRCUIT DIAGRAM
    % ==============================
    circuitDiagramUpdate(app, 0, true);

    % ==============================
    % REFRESH DEPENDENT VISUALS
    % ==============================
   
    
    updateSmithValues(app, 'gammaAngle');
    % updateZCircles(app);
    updateSWRCircle(app);
    % updateRuler(app);
    % updateRulerMarkings(app);
    % updateRulerValues(app);

    % ==============================
    % RESET STEP TRACKER / UI STATE
    % ==============================


        end

        % Button pushed function: ShowYstubLocationButton
        function ShowYstubLocationButtonPushed(app, event)
            buttons = [app.OpenButton, app.ShortButton];
            set(buttons,'Enable', 'on');
            app.Step1ClicktoselectaZLPointLabel.Text = 'Step 4: Choose to either open/closed';
            app.Ystub.Visible = 'on';
            app.YstubLabel.Visible = 'on';
            app.ShowYstubLocationButton.Visible = 'off';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CircuitAxes = uiaxes(app.GridLayout);
            title(app.CircuitAxes, 'Circuit Diagram')
            xlabel(app.CircuitAxes, 'X')
            ylabel(app.CircuitAxes, 'Y')
            zlabel(app.CircuitAxes, 'Z')
            app.RulerCheckBox = uicheckbox(app.GridLayout);
            app.RulerCheckBox.Text = 'Ruler';
            app.RulerCheckBox.Value = true;
            app.ZLLabel = uilabel(app.GridLayout);
            app.ZLLabel.Interpreter = 'latex';
            app.ZLLabel.Text = '$Z_{L}\ (\Omega)=$';
            app.Z_LOmegaEditField = uieditfield(app.GridLayout, 'text');
            app.Z_LOmegaEditField.ValueChangedFcn = createCallbackFcn(app, @Z_LOmegaEditFieldValueChanged, true);
            app.Z_LOmegaEditField.Value = '20+20j';
            app.z_LEditFieldLabel = uilabel(app.GridLayout);
            app.z_LEditFieldLabel.Interpreter = 'latex';
            app.z_LEditFieldLabel.Text = '$z_{L}$';
            app.z_LEditField = uieditfield(app.GridLayout, 'text');
            app.z_LEditField.ValueChangedFcn = createCallbackFcn(app, @z_LEditFieldValueChanged, true);
            app.YLLabel = uilabel(app.GridLayout);
            app.YLLabel.Interpreter = 'latex';
            app.YLLabel.Text = '$Y_{L}\ (℧)$ ';
            app.Y_LEditField = uieditfield(app.GridLayout, 'text');
            app.Y_LEditField.ValueChangedFcn = createCallbackFcn(app, @Y_LEditFieldValueChanged, true);
            app.y_LEditFieldLabel = uilabel(app.GridLayout);
            app.y_LEditFieldLabel.Interpreter = 'latex';
            app.y_LEditFieldLabel.Text = '$y_{L}$';
            app.y_LEditField = uieditfield(app.GridLayout, 'text');
            app.y_LEditField.ValueChangedFcn = createCallbackFcn(app, @y_LEditFieldValueChanged, true);
            app.R_LLabel = uilabel(app.GridLayout);
            app.R_LLabel.Interpreter = 'latex';
            app.R_LLabel.Text = '$R_{L}$';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '+';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '=';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '=';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '=';
            app.X_LjLabel = uilabel(app.GridLayout);
            app.X_LjLabel.Interpreter = 'latex';
            app.X_LjLabel.Text = '$X_{L}j=$';
            app.MainPanel = uipanel(app.GridLayout);
            app.MainPanel.Title = 'Panel';
            app.SmithAxes = uiaxes(app.MainPanel);
            title(app.SmithAxes, 'Smith Chart')
            xlabel(app.SmithAxes, 'X')
            ylabel(app.SmithAxes, 'Y')
            zlabel(app.SmithAxes, 'Z')
            app.SWRLabel = uilabel(app.MainPanel);
            app.SWRLabel.Text = 'SWR = ';
            app.dBSLabel = uilabel(app.MainPanel);
            app.dBSLabel.Text = 'dBS = ';
            app.RTNLOSSdBLabel = uilabel(app.MainPanel);
            app.RTNLOSSdBLabel.Text = 'RTN. LOSS [dB] = ';
            app.RFLCOEFFPLabel = uilabel(app.MainPanel);
            app.RFLCOEFFPLabel.Text = 'RFL. COEFF, P = ';
            app.ATTENdbLabel = uilabel(app.MainPanel);
            app.ATTENdbLabel.Text = 'ATTEN. [db] = ';
            app.SWLOSSCOEFFLabel = uilabel(app.MainPanel);
            app.SWLOSSCOEFFLabel.Text = 'S.W. LOSS COEFF = ';
            app.RFLLOSSdBLabel = uilabel(app.MainPanel);
            app.RFLLOSSdBLabel.Text = 'RFL. LOSS [dB] = ';
            app.SWPEAKCONSTPLabel = uilabel(app.MainPanel);
            app.SWPEAKCONSTPLabel.Text = 'S.W. PEAK (CONST. P) = ';
            app.TRANSMCOEFFPLabel = uilabel(app.MainPanel);
            app.TRANSMCOEFFPLabel.Text = 'TRANSM. COEFF, P = ';
            app.TRANSMCOEFFEorILabel = uilabel(app.MainPanel);
            app.TRANSMCOEFFEorILabel.Text = 'TRANSM. COEFF, E or I = ';
            app.RFLCOEFFEorILabel = uilabel(app.MainPanel);
            app.RFLCOEFFEorILabel.Text = 'RFL. COEFF, E or I = ';
            app.Z_LLabel = uilabel(app.GridLayout);
            app.Z_LLabel.Interpreter = 'latex';
            app.Z_LLabel.Text = '$1/Z_{L} = $';
            app.Z_L50Label = uilabel(app.GridLayout);
            app.Z_L50Label.Interpreter = 'latex';
            app.Z_L50Label.Text = '$Z_{L}/50(Ω) = ';
            app.Y_Lcdot50Label = uilabel(app.GridLayout);
            app.Y_Lcdot50Label.Interpreter = 'latex';
            app.Y_Lcdot50Label.Text = '$Y_{L} \cdot 50\ (Ω) = $';
            app.SWRCircleCheckBox = uicheckbox(app.GridLayout);
            app.SWRCircleCheckBox.Text = 'SWR Circle';
            app.SWRCircleCheckBox.Value = true;
            app.ZCirclesCheckBox = uicheckbox(app.GridLayout);
            app.ZCirclesCheckBox.Text = 'Z Circles';
            app.ShowstartendpointsCheckBox = uicheckbox(app.GridLayout);
            app.ShowstartendpointsCheckBox.Text = 'Show start/end points';
            app.ShowstartendpointsCheckBox.Value = true;
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.YaButton = uibutton(app.GridLayout2, 'push');
            app.YaButton.ButtonPushedFcn = createCallbackFcn(app, @YaButtonPushed, true);
            app.YaButton.Text = 'Ya';
            app.YbButton = uibutton(app.GridLayout2, 'push');
            app.YbButton.ButtonPushedFcn = createCallbackFcn(app, @YbButtonPushed, true);
            app.YbButton.Text = 'Yb';
            app.YaorYbLabel = uilabel(app.GridLayout2);
            app.YaorYbLabel.Text = 'Ya or Yb?';
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = '=';
            app.GridLayout2_2 = uigridlayout(app.GridLayout);
            app.OpenButton = uibutton(app.GridLayout2_2, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ShortButton = uibutton(app.GridLayout2_2, 'push');
            app.ShortButton.ButtonPushedFcn = createCallbackFcn(app, @ShortButtonPushed, true);
            app.ShortButton.Text = 'Short';
            app.StubOpenorShortLabel = uilabel(app.GridLayout2_2);
            app.StubOpenorShortLabel.Text = 'Stub: Open or Short?';
            app.Label_7 = uilabel(app.GridLayout2_2);
            app.Label_7.Text = '=';
            app.Step1ClicktoselectaZLPointLabel = uilabel(app.GridLayout);
            app.Step1ClicktoselectaZLPointLabel.Text = 'Step 1: Click to select a ZL Point';
            app.ResettoStep0Button = uibutton(app.GridLayout, 'push');
            app.ResettoStep0Button.ButtonPushedFcn = createCallbackFcn(app, @ResettoStep0ButtonPushed, true);
            app.ResettoStep0Button.Text = 'Reset to Step 0';
            app.ShowYstubLocationButton = uibutton(app.GridLayout, 'push');
            app.ShowYstubLocationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowYstubLocationButtonPushed, true);
            app.ShowYstubLocationButton.Text = 'Show Ystub Location';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmithChartAdvancedSeries

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end