classdef SnellAndBoundarySIm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SnellsLawTab                   matlab.ui.container.Tab
        Panel_7                        matlab.ui.container.Panel
        TransmittedRayLabel            matlab.ui.control.Label
        ReflectedRayLabel              matlab.ui.control.Label
        IncidentRayLabel               matlab.ui.control.Label
        Panel_10                       matlab.ui.container.Panel
        Panel_9                        matlab.ui.container.Panel
        Panel_8                        matlab.ui.container.Panel
        LEGENDLabel                    matlab.ui.control.Label
        Medium2Label                   matlab.ui.control.Label
        Medium1Label                   matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        Panel_6                        matlab.ui.container.Panel
        TotalInternalReflectionLabel   matlab.ui.control.Label
        TransmittedangleLabel          matlab.ui.control.Label
        ReflectedangleLabel            matlab.ui.control.Label
        OUTPUTSLabel_5                 matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        IncidentAngleiLabel_2          matlab.ui.control.Label
        thetaiSlider                   matlab.ui.control.Slider
        Epsilonr2EditField             matlab.ui.control.NumericEditField
        Relativepermittivityr1Label_2  matlab.ui.control.Label
        Epsilonr1EditField             matlab.ui.control.NumericEditField
        Relativepermittivityr1Label    matlab.ui.control.Label
        INPUTSLabel                    matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ElectricFieldTab               matlab.ui.container.Tab
        Panel_23                       matlab.ui.container.Panel
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        RelationsLabel                 matlab.ui.control.Label
        ComponentDefinitionsLabel      matlab.ui.control.Label
        Panel_13                       matlab.ui.container.Panel
        TransmittedRayLabel_2          matlab.ui.control.Label
        ReflectedRayLabel_2            matlab.ui.control.Label
        IncidentRayLabel_2             matlab.ui.control.Label
        Panel_16                       matlab.ui.container.Panel
        Panel_15                       matlab.ui.container.Panel
        Panel_14                       matlab.ui.container.Panel
        LEGENDLabel_2                  matlab.ui.control.Label
        Panel_12                       matlab.ui.container.Panel
        TransmittedFieldMagnitude      matlab.ui.control.Label
        TransmittedangleLabel_E        matlab.ui.control.Label
        ReflectedangleLabel_E          matlab.ui.control.Label
        OUTPUTSLabel_6                 matlab.ui.control.Label
        Panel_11                       matlab.ui.container.Panel
        SurfaceChargeDensityEditField  matlab.ui.control.NumericEditField
        sEditFieldLabel                matlab.ui.control.Label
        EiEditField                    matlab.ui.control.NumericEditField
        IncidentElectricFieldELabel    matlab.ui.control.Label
        IncidentAngleiLabel_3          matlab.ui.control.Label
        thetaiSlider_E                 matlab.ui.control.Slider
        Epsilonr2EditField_E           matlab.ui.control.NumericEditField
        Relativepermittivityr1Label_4  matlab.ui.control.Label
        Epsilonr1EditField_E           matlab.ui.control.NumericEditField
        Relativepermittivityr1Label_3  matlab.ui.control.Label
        INPUTSLabel_5                  matlab.ui.control.Label
        Medium2Label_2                 matlab.ui.control.Label
        Medium1Label_2                 matlab.ui.control.Label
        UIAxes_E                       matlab.ui.control.UIAxes
        MagneticFieldTab               matlab.ui.container.Tab
        Panel_24                       matlab.ui.container.Panel
        Label_10                       matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        RelationsLabel_2               matlab.ui.control.Label
        ComponentDefinitionsLabel_2    matlab.ui.control.Label
        Panel_19                       matlab.ui.container.Panel
        TransmittedRayLabel_3          matlab.ui.control.Label
        ReflectedRayLabel_3            matlab.ui.control.Label
        IncidentRayLabel_3             matlab.ui.control.Label
        Panel_22                       matlab.ui.container.Panel
        Panel_21                       matlab.ui.container.Panel
        Panel_20                       matlab.ui.container.Panel
        LEGENDLabel_3                  matlab.ui.control.Label
        Panel_18                       matlab.ui.container.Panel
        TransmittedFieldMagnitude_H    matlab.ui.control.Label
        TransmittedangleLabel_H        matlab.ui.control.Label
        ReflectedangleLabel_H          matlab.ui.control.Label
        OUTPUTSLabel_7                 matlab.ui.control.Label
        Medium2Label_3                 matlab.ui.control.Label
        Medium1Label_3                 matlab.ui.control.Label
        Panel_17                       matlab.ui.container.Panel
        JsEditField                    matlab.ui.control.NumericEditField
        JsEditFieldLabel               matlab.ui.control.Label
        HiEditField                    matlab.ui.control.NumericEditField
        IncidentElectricFieldELabel_2  matlab.ui.control.Label
        IncidentAngleiLabel_4          matlab.ui.control.Label
        thetaiSlider_H                 matlab.ui.control.Slider
        Epsilonr2EditField_H           matlab.ui.control.NumericEditField
        Relativepermittivityr1Label_6  matlab.ui.control.Label
        Epsilonr1EditField_H           matlab.ui.control.NumericEditField
        Relativepermittivityr1Label_5  matlab.ui.control.Label
        INPUTSLabel_6                  matlab.ui.control.Label
        UIAxes_H                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        er1 = 0;
        er2 = 0;
        theta_i = 0;
        theta_r;
        theta_t;
        n1;
        n2;
        L = 15;

        iRay;
        rRay;
        tRay;


        ps = 0;
        Ei = 0;
        E2;
        iRayE;
        rRayE;
        tRayE;

        ur1 = 0;
        ur2 = 0;
        Hi = 0;
        Js = 0;
        H2;

        iRayH;
        rRayH;
        tRayH;

        selectedTab;

        arcI;
        arcR;
        arcT;
        arcTextI;
        arcTextR;
        arcTextT;


        arcI_E;
        arcR_E;
        arcT_E;
        arcTextI_E;
        arcTextR_E;
        arcTextT_E;


        arcI_H;
        arcR_H;
        arcT_H;
        arcTextI_H;
        arcTextR_H;
        arcTextT_H;

        iParE;
        iPerpE;

        tPerpE;
        tParE;

        rPerpE;
        rParE;

        iParH;
        iPerpH;

        rPerpH;
        rParH;

        tPerpH;
        tParH;

        iLabel;
        rLabel;
        tLabel;


        iParLabel;
        iPerpLabel;

        rParLabel;
        rPerpLabel;

        tParLabel;
        tPerpLabel;

        iLabelH;
        rLabelH;
        tLabelH;

        iParLabelH;
        iPerpLabelH;

        rParLabelH;
        rPerpLabelH;

        tParLabelH;
        tPerpLabelH;

        iLabelS;
        rLabelS;
        tLabelS;


        okabeBlue = [0.0000 0.4471 0.6980];
        okabeReddishPurple = [204, 121, 167]/255;
        okabeBluishGreen = [0.0000, 0.6196, 0.4510];


    end

    methods (Access = private)

        function updateValues(app)

            if app.selectedTab == app.SnellsLawTab

                if app.er1 <= 0 || app.er2 <= 0
                    return
                end

                app.n1 = sqrt(app.er1);
                app.n2 = sqrt(app.er2);
                ratio = (app.n1/app.n2) * sind(app.theta_i);

                if abs(ratio) > 1
                    app.theta_t = NaN;   % total internal reflection TIR = YES
                    app.TransmittedangleLabel.Text = 'Transmitted Angle (θₜ) = N/A';
                    app.TotalInternalReflectionLabel.Text = "Total Internal Reflection = YES";
                else
                    app.theta_t = asind(ratio);
                    app.TransmittedangleLabel.Text = sprintf('Transmitted Angle (θₜ) = %.2f°', app.theta_t);

                    app.TotalInternalReflectionLabel.Text = "Total Internal Reflection = NO";


                end

                
                app.theta_r = app.theta_i;
                app.ReflectedangleLabel.Text = sprintf('Reflected Angle (θᵣ) = %.2f°', app.theta_r);
                createRays(app);

            end

            if app.selectedTab == app.ElectricFieldTab

                if app.er1 <= 0 || app.er2 <= 0 || app.Ei == 0
                    return
                end

                Ei_parallel = app.Ei * sind(app.theta_i);
                Ei_perpendicular = app.Ei * cosd(app.theta_i);

                E2_parallel = Ei_parallel;
                E2_perpendicular = (app.er1 * Ei_perpendicular + app.ps)/ app.er2;
                app.E2 = sqrt(E2_parallel^2 + E2_perpendicular^2);
                app.theta_t = atan2d(E2_parallel, E2_perpendicular);
                app.theta_r = app.theta_i;

              
                set(app.iParE, ...
                    'XData', -Ei_parallel, 'YData', 0.1, ...
                    'UData', Ei_parallel, 'VData', 0, ...
                    'Visible', 'on');

                set(app.iPerpE, ...
                    'XData', -Ei_parallel, 'YData', 0, ...
                    'UData', 0, 'VData', Ei_perpendicular, ...
                    'Visible', 'on');

                set(app.rParE, ...
                    'XData', 0, 'YData', 0.1, ...
                    'UData', Ei_parallel, 'VData', 0, ...
                    'Visible', 'on');

                set(app.rPerpE, ...
                    'XData', Ei_parallel, 'YData', 0, ...
                    'UData', 0, 'VData', Ei_perpendicular, ...
                    'Visible', 'on');

                set(app.tParE, ...
                    'XData', 0, 'YData', -0.1, ...
                    'UData', E2_parallel, 'VData', 0, ...
                    'Visible', 'on');

                set(app.tPerpE, ...
                    'XData', E2_parallel, 'YData', 0, ...
                    'UData', 0, 'VData', -E2_perpendicular, ...
                    'Visible', 'on');

                % ---- main field labels ----

              
                set(app.iLabel, ...
                    'Position', [-Ei_parallel - 0.45, Ei_perpendicular + 0.25, 0], ...
                    'String', sprintf('E_i = %.2f', app.Ei), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'bottom');

                set(app.rLabel, ...
                    'Position', [Ei_parallel + 0.20, Ei_perpendicular + 0.35, 0], ...
                    'String', sprintf('E_r = %.2f', app.Ei), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'bottom');

                set(app.tLabel, ...
                    'Position', [E2_parallel + 0.20, -E2_perpendicular - 0.35, 0], ...
                    'String', sprintf('E_t = %.2f', app.E2), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'top');

                % ---- incident component labels ----
                set(app.iParLabel, ...
                    'Position', [-Ei_parallel/2, 0.25, 0], ...
                    'String', sprintf('E_{i||} = %.2f', Ei_parallel), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'bottom');

                set(app.iPerpLabel, ...
                    'Position', [-Ei_parallel - 1.25, Ei_perpendicular/2, 0], ...
                    'String', sprintf('E_{i\\perp} = %.2f', Ei_perpendicular), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle');

                % ---- reflected component labels ----
                set(app.rParLabel, ...
                    'Position', [Ei_parallel/2, 0.25, 0], ...
                    'String', sprintf('E_{r||} = %.2f', Ei_parallel), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'bottom');

                set(app.rPerpLabel, ...
                    'Position', [Ei_parallel + 1.25, Ei_perpendicular/2, 0], ...
                    'String', sprintf('E_{r\\perp} = %.2f', Ei_perpendicular), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle');

                % ---- transmitted component labels ----
                set(app.tParLabel, ...
                    'Position', [E2_parallel/2, -0.25, 0], ...
                    'String', sprintf('E_{t||} = %.2f', E2_parallel), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'top');

                set(app.tPerpLabel, ...
                    'Position', [E2_parallel + 1.25, -E2_perpendicular/2, 0], ...
                    'String', sprintf('E_{t\\perp} = %.2f', E2_perpendicular), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle');


                app.TransmittedangleLabel_E.Text = sprintf('Transmitted Angle (θₜ) = %.2f°', app.theta_t);
                app.ReflectedangleLabel_E.Text = sprintf('Reflected Angle (θᵣ) = %.2f°', app.theta_r);
                app.TransmittedFieldMagnitude.Text = sprintf('Transmitted field magnitude (E₂) = %.2f' , app.E2);
                createRays(app);


            end

            if app.selectedTab == app.MagneticFieldTab

                if app.ur1 <= 0 || app.ur2 <= 0 || app.Hi == 0
                    return
                end

                Hi_parallel = app.Hi * sind(app.theta_i);
                Hi_perpendicular = app.Hi * cosd(app.theta_i);

                H2_parallel = Hi_parallel + app.Js;
                H2_perpendicular = (app.ur1 * Hi_perpendicular)/ app.ur2;
                app.H2 = sqrt(H2_parallel^2 + H2_perpendicular^2);

                app.theta_t = atan2d(H2_parallel, H2_perpendicular);
                app.theta_r = app.theta_i;

                set(app.iParH, ...
                    'XData', -Hi_parallel, 'YData', 0.1, ...
                    'UData', Hi_parallel, 'VData', 0, ...
                    'Visible', 'on');

                set(app.iPerpH, ...
                    'XData', -Hi_parallel, 'YData', 0, ...
                    'UData', 0, 'VData', Hi_perpendicular, ...
                    'Visible', 'on');

                set(app.rParH, ...
                    'XData', 0, 'YData', 0.1, ...
                    'UData', Hi_parallel, 'VData', 0, ...
                    'Visible', 'on');

                set(app.rPerpH, ...
                    'XData', Hi_parallel, 'YData', 0, ...
                    'UData', 0, 'VData', Hi_perpendicular, ...
                    'Visible', 'on');

                set(app.tParH, ...
                    'XData', 0, 'YData', -0.1, ...
                    'UData', H2_parallel, 'VData', 0, ...
                    'Visible', 'on');

                set(app.tPerpH, ...
                    'XData', H2_parallel, 'YData', 0, ...
                    'UData', 0, 'VData', -H2_perpendicular, ...
                    'Visible', 'on');

                % ---- main field labels ----


                set(app.iLabelH, ...
                    'Position', [-Hi_parallel - 0.45, Hi_perpendicular + 0.25, 0], ...
                    'String', sprintf('H_i = %.2f', app.Hi), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'bottom');

                set(app.rLabelH, ...
                    'Position', [Hi_parallel + 0.20, Hi_perpendicular + 0.35, 0], ...
                    'String', sprintf('H_r = %.2f', app.Hi), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'bottom');

                set(app.tLabelH, ...
                    'Position', [H2_parallel + 0.20, -H2_perpendicular - 0.35, 0], ...
                    'String', sprintf('H_t = %.2f', app.H2), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'top');

                % ---- incident component labels ----
                set(app.iParLabelH, ...
                    'Position', [-Hi_parallel/2, 0.25, 0], ...
                    'String', sprintf('H_{i||} = %.2f', Hi_parallel), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'bottom');

                set(app.iPerpLabelH, ...
                    'Position', [-Hi_parallel - 1.25, Hi_perpendicular/2, 0], ...
                    'String', sprintf('H_{i\\perp} = %.2f', Hi_perpendicular), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle');

                % ---- reflected component labels ----
                set(app.rParLabelH, ...
                    'Position', [Hi_parallel/2, 0.25, 0], ...
                    'String', sprintf('H_{r||} = %.2f', Hi_parallel), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'bottom');

                set(app.rPerpLabelH, ...
                    'Position', [Hi_parallel + 1.25, Hi_perpendicular/2, 0], ...
                    'String', sprintf('H_{r\\perp} = %.2f', Hi_perpendicular), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle');

                % ---- transmitted component labels ----
                set(app.tParLabelH, ...
                    'Position', [H2_parallel/2, -0.25, 0], ...
                    'String', sprintf('H_{t||} = %.2f', H2_parallel), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'top');

                set(app.tPerpLabelH, ...
                    'Position', [H2_parallel + 1.25, -H2_perpendicular/2, 0], ...
                    'String', sprintf('H_{t\\perp} = %.2f', H2_perpendicular), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle');


                app.TransmittedangleLabel_H.Text = sprintf('Transmitted Angle (θₜ) = %.2f°', app.theta_t);
                app.ReflectedangleLabel_H.Text = sprintf('Reflected Angle (θᵣ) = %.2f°', app.theta_r);
                app.TransmittedFieldMagnitude_H.Text = sprintf('Transmitted field magnitude (H₂) = %.2f' , app.H2);
                createRays(app);


            end
        end

        function createRays(app)

            switch app.selectedTab

                case app.SnellsLawTab


                    x_i = -app.L*sind(app.theta_i);
                    y_i = app.L*cosd(app.theta_i);

                    u_i = -x_i;
                    v_i = -y_i;

                    set(app.iRay,...
                        'XData',x_i,...
                        'YData',y_i,...
                        'UData',u_i,...
                        'VData',v_i);

                    set(app.iLabelS, ...
                        'Position',[x_i-0.5, y_i+0.55, 0],...
                        'String','Eᵢ',...
                        'HorizontalAlignment','center');


                    u_r = app.L *sind(app.theta_r);
                    v_r = app.L*cosd(app.theta_r);

                    set(app.rRay,...
                        'XData',0,...
                        'YData',0,...
                        'UData',u_r,...
                        'VData',v_r);

                    if isnan(app.theta_t)
                        set(app.tRay,'Visible','off');
                        set(app.tLabelS, 'Visible', 'off')

                    else
                        set(app.tRay,'Visible','on');
                        u_t = app.L *sind(app.theta_t);
                        v_t = -app.L*cosd(app.theta_t);

                        set(app.tRay,...
                            'XData',0,...
                            'YData',0,...
                            'UData',u_t,...
                            'VData',v_t);

                        set(app.tLabelS, ...
                            'Position', [u_t - 0.5, v_t - 0.55, 0], ...
                            'String','Eₜ',...
                            'HorizontalAlignment', 'center', ...
                            'Visible', 'on');

                        
                    end

                    maxCoord = app.L;
                    Rarc  = 0.18 * maxCoord;
                    Rtext = 0.30 * maxCoord;


                    set(app.rLabelS, ...
                        'Position', [u_r + 0.5, v_r + 0.55, 0], ...
                        'String','Eᵣ',...
                        'HorizontalAlignment', 'center');


                    updateArcsAndText(app, ...
                        app.arcI, app.arcR, app.arcT, ...
                        app.arcTextI, app.arcTextR, app.arcTextT, ...
                        app.theta_i, app.theta_r, app.theta_t, Rarc, Rtext);


                case app.ElectricFieldTab

                    % Physical component values
                    Ei_parallel = app.Ei * sind(app.theta_i);
                    Ei_perp     = app.Ei * cosd(app.theta_i);

                    E2_parallel = Ei_parallel;
                    E2_perp     = (app.er1 * Ei_perp + app.ps) / app.er2;

                    % Use the same magnitudes for the visible rays
                    x_i = -Ei_parallel;
                    y_i =  Ei_perp;

                    set(app.iRayE, ...
                        'XData', x_i, ...
                        'YData', y_i, ...
                        'UData', -x_i, ...
                        'VData', -y_i, ...
                        'Visible', 'on');

                    set(app.rRayE, ...
                        'XData', 0, ...
                        'YData', 0, ...
                        'UData', Ei_parallel, ...
                        'VData', Ei_perp, ...
                        'Visible', 'on');

                    if isnan(app.theta_t)
                        set(app.tRayE, 'Visible', 'off');
                    else
                        set(app.tRayE, 'Visible', 'on');
                        set(app.tRayE, ...
                            'XData', 0, ...
                            'YData', 0, ...
                            'UData', E2_parallel, ...
                            'VData', -E2_perp);
                    end

                    maxCoord = max([
                        abs(Ei_parallel), abs(Ei_perp), ...
                        abs(E2_parallel), abs(E2_perp)
                        ]);

                    Length = ceil(maxCoord + 1);

                    xlim(app.UIAxes_E,[-Length-1 Length+1]);
                    ylim(app.UIAxes_E,[-Length-1 Length+1]);

                    Rarc  = 0.18 * maxCoord;
                    Rtext = 0.30 * maxCoord;

                    updateArcsAndText(app, ...
                        app.arcI_E, app.arcR_E, app.arcT_E, ...
                        app.arcTextI_E, app.arcTextR_E, app.arcTextT_E, ...
                        app.theta_i, app.theta_r, app.theta_t, Rarc, Rtext);

                case app.MagneticFieldTab

                    Hi_parallel = app.Hi * sind(app.theta_i);
                    Hi_perpendicular = app.Hi * cosd(app.theta_i);

                    H2_parallel = Hi_parallel + app.Js;
                    H2_perpendicular = (app.ur1 * Hi_perpendicular)/ app.ur2;

                    x_i = -Hi_parallel;
                    y_i =  Hi_perpendicular;

                    set(app.iRayH, ...
                        'XData', x_i, ...
                        'YData', y_i, ...
                        'UData', -x_i, ...
                        'VData', -y_i, ...
                        'Visible', 'on');

                    set(app.rRayH, ...
                        'XData', 0, ...
                        'YData', 0, ...
                        'UData', Hi_parallel, ...
                        'VData', Hi_perpendicular, ...
                        'Visible', 'on');

                    if isnan(app.theta_t)
                        set(app.tRayH, 'Visible', 'off');
                    else
                        set(app.tRayH, 'Visible', 'on');
                        set(app.tRayH, ...
                            'XData', 0, ...
                            'YData', 0, ...
                            'UData', H2_parallel, ...
                            'VData', -H2_perpendicular);
                    end

                    maxCoord = max([
                        abs(Hi_parallel), abs(Hi_perpendicular), ...
                        abs(H2_parallel), abs(H2_perpendicular)
                        ]);

                    Length = ceil(maxCoord + 1);

                    xlim(app.UIAxes_H,[-Length-1 Length+1]);
                    ylim(app.UIAxes_H,[-Length-1 Length+1]);

                    Rarc  = 0.18 * maxCoord;
                    Rtext = 0.30 * maxCoord;

                    updateArcsAndText(app, ...
                        app.arcI_H, app.arcR_H, app.arcT_H, ...
                        app.arcTextI_H, app.arcTextR_H, app.arcTextT_H, ...
                        app.theta_i, app.theta_r, app.theta_t, Rarc, Rtext);


            end

        end

        function updateArcsAndText(app, arcI, arcR, arcT, textI, textR, textT, theta_i, theta_r, theta_t, Rarc, Rtext)

      
            RarcI = Rarc + 0.20;   % Incident (largest)
            RarcR = Rarc;          % Reflected (middle)
            RarcT = Rarc - 0.20;   % Transmitted (smallest)

            n = 60;

            % Incident arc
            t = linspace(90, 90 + theta_i, n);
            x = RarcI * cosd(t);
            y = RarcI * sind(t);
            set(arcI, 'XData', x, 'YData', y, 'Visible', 'on');

            mid = 90 + theta_i/2;
            set(textI, ...
                'Position', [Rtext*cosd(mid)-0.4, Rtext*sind(mid)+0.2, 0], ...
                'String', sprintf('\\theta_i = %.1f^\\circ', theta_i), ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'middle', ...
                'BackgroundColor', 'w', ...
                'Margin', 1, ...
                'Visible', 'on');

            % Reflected arc
            t = linspace(90 - theta_r, 90, n);
            x = Rarc * cosd(t);
            y = Rarc * sind(t);
            set(arcR, 'XData', x, 'YData', y, 'Visible', 'on');

            mid = 90 - theta_r/2;
            set(textR, ...
                'Position', [Rtext*cosd(mid)+0.4, Rtext*sind(mid)+0.2, 0], ...
                'String', sprintf('\\theta_r = %.1f^\\circ', theta_r), ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'middle', ...
                'BackgroundColor', 'w', ...
                'Margin', 1, ...
                'Visible', 'on');

            % Transmitted arc
            if isnan(theta_t)
                set(arcT, 'XData', NaN, 'YData', NaN, 'Visible', 'off');
                set(textT, 'String', '', 'Visible', 'off');
            else
                t = linspace(270, 270 + theta_t, n);
                x = RarcT * cosd(t);
                y = RarcT * sind(t);
                set(arcT, 'XData', x, 'YData', y, 'Visible', 'on');

                mid = 270 + theta_t/2;
                set(textT, ...
                    'Position', [Rtext*cosd(mid)+0.2, Rtext*sind(mid)-0.2, 0], ...
                    'String', sprintf('\\theta_t = %.1f^\\circ', theta_t), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle', ...
                    'BackgroundColor', 'w', ...
                    'Margin', 1, ...
                    'Visible', 'on');
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            hold(app.UIAxes, "on");
            patch(app.UIAxes,[-20 20 20 -20],...
                [-20 -20 0 0],...
                [0.95 0.95 1],...   % light blue
                'EdgeColor','none');
            plot(app.UIAxes, [-20 20], [0 0], 'k', 'LineWidth', 2);
            plot(app.UIAxes, [0 0], [-20 20], '--');
            app.iRay = quiver(app.UIAxes,0,0,1,1, 0,'Color', app.okabeBlue, 'LineWidth',2);
            app.rRay = quiver(app.UIAxes,0,0,1,1, 0,'Color', app.okabeReddishPurple, 'LineWidth',2);
            app.tRay = quiver(app.UIAxes,0,0,1,1, 0, 'Color', app.okabeBluishGreen, 'LineWidth',2);

            app.arcI = plot(app.UIAxes, NaN, NaN, 'Color', app.okabeBlue, 'LineWidth', 2);
            app.arcR = plot(app.UIAxes, NaN, NaN, 'Color', app.okabeReddishPurple, 'LineWidth', 2);
            app.arcT = plot(app.UIAxes, NaN, NaN, 'Color', app.okabeBluishGreen, 'LineWidth', 2);

            app.arcTextI = text(app.UIAxes,NaN,NaN,'','Color',app.okabeBlue,'FontWeight','bold');
            app.arcTextR = text(app.UIAxes,NaN,NaN,'','Color',app.okabeReddishPurple,'FontWeight','bold');
            app.arcTextT = text(app.UIAxes,NaN,NaN,'','Color',app.okabeBluishGreen,'FontWeight','bold');

            % Main field labels
            app.iLabelS = text(app.UIAxes,NaN,NaN,'',...
                'Color',app.okabeBlue,...
                'FontWeight','bold',...
                'FontSize',14);

            app.rLabelS = text(app.UIAxes,NaN,NaN,'',...
                'Color',app.okabeReddishPurple,...
                'FontWeight','bold',...
                'FontSize',14);

            app.tLabelS = text(app.UIAxes,NaN,NaN,'',...
                'Color',app.okabeBluishGreen,...
                'FontWeight','bold',...
                'FontSize',14);

            hold(app.UIAxes, "off");

            hold(app.UIAxes_E, "on");
            
            patch(app.UIAxes_E,[-20 20 20 -20],...
                [-20 -20 0 0],...
                [0.95 0.95 1],...   % light blue
                'EdgeColor','none');


            plot(app.UIAxes_E, [-20 20], [0 0], 'k', 'LineWidth', 2);
            plot(app.UIAxes_E, [0 0], [-20 20], '--');
            app.iRayE = quiver(app.UIAxes_E,0,0,1,1, 0,'Color', app.okabeBlue, 'LineWidth',2);
            app.rRayE = quiver(app.UIAxes_E,0,0,1,1, 0,'Color', app.okabeReddishPurple, 'LineWidth',2);
            app.tRayE = quiver(app.UIAxes_E,0,0,1,1, 0, 'Color', app.okabeBluishGreen, 'LineWidth',2);

            app.arcI_E = plot(app.UIAxes_E, NaN, NaN, 'Color', app.okabeBlue, 'LineWidth', 2);
            app.arcR_E = plot(app.UIAxes_E, NaN, NaN, 'Color', app.okabeReddishPurple, 'LineWidth', 2);
            app.arcT_E = plot(app.UIAxes_E, NaN, NaN, 'Color', app.okabeBluishGreen, 'LineWidth', 2);

            app.arcTextI_E = text(app.UIAxes_E,NaN,NaN,'','Color',app.okabeBlue,'FontWeight','bold');
            app.arcTextR_E = text(app.UIAxes_E,NaN,NaN,'','Color',app.okabeReddishPurple,'FontWeight','bold');
            app.arcTextT_E = text(app.UIAxes_E,NaN,NaN,'','Color',app.okabeBluishGreen,'FontWeight','bold');

            % Incident field components
            app.iParE  = quiver(app.UIAxes_E, NaN, NaN, NaN, NaN, 0, ...
                'Color', app.okabeBlue, 'LineStyle', '--', 'LineWidth', 1.5);
            app.iPerpE = quiver(app.UIAxes_E, NaN, NaN, NaN, NaN, 0, ...
                'Color', app.okabeBlue, 'LineStyle', '--', 'LineWidth', 1.5);

            % Reflected field components
            app.rParE  = quiver(app.UIAxes_E, NaN, NaN, NaN, NaN, 0, ...
                'Color', app.okabeReddishPurple, 'LineStyle', '--', 'LineWidth', 1.5);
            app.rPerpE = quiver(app.UIAxes_E, NaN, NaN, NaN, NaN, 0, ...
                'Color', app.okabeReddishPurple, 'LineStyle', '--', 'LineWidth', 1.5);

            % Transmitted field components
            app.tParE  = quiver(app.UIAxes_E, NaN, NaN, NaN, NaN, 0, ...
                'Color', app.okabeBluishGreen, 'LineStyle', '--', 'LineWidth', 1.5);
            app.tPerpE = quiver(app.UIAxes_E, NaN, NaN, NaN, NaN, 0, ...
                'Color', app.okabeBluishGreen, 'LineStyle', '--', 'LineWidth', 1.5);

            % Main field labels
            app.iLabel = text(app.UIAxes_E,NaN,NaN,'',...
                'Color',app.okabeBlue,...
                'FontWeight','bold',...
                'FontSize',14);

            app.rLabel = text(app.UIAxes_E,NaN,NaN,'',...
                'Color',app.okabeReddishPurple,...
                'FontWeight','bold',...
                'FontSize',14);

            app.tLabel = text(app.UIAxes_E,NaN,NaN,'',...
                'Color',app.okabeBluishGreen,...
                'FontWeight','bold',...
                'FontSize',14);

            % Incident components
            app.iParLabel = text(app.UIAxes_E,NaN,NaN,'',...
                'Color',app.okabeBlue,...
                'FontSize',13);

            app.iPerpLabel = text(app.UIAxes_E,NaN,NaN,'',...
                'Color',app.okabeBlue,...
                'FontSize',13);

            % Reflected components
            app.rParLabel = text(app.UIAxes_E,NaN,NaN,'',...
                'Color',app.okabeReddishPurple,...
                'FontSize',13);

            app.rPerpLabel = text(app.UIAxes_E,NaN,NaN,'',...
                'Color',app.okabeReddishPurple,...
                'FontSize',13);

            % Transmitted components
            app.tParLabel = text(app.UIAxes_E,NaN,NaN,'',...
                'Color',app.okabeBluishGreen,...
                'FontSize',13);

            app.tPerpLabel = text(app.UIAxes_E,NaN,NaN,'',...
                'Color',app.okabeBluishGreen,...
                'FontSize',13);


            hold(app.UIAxes_E, "off");


            hold(app.UIAxes_H, "on");
            patch(app.UIAxes_H,[-20 20 20 -20],...
                [-20 -20 0 0],...
                [0.95 0.95 1],...   % light blue
                'EdgeColor','none');


            plot(app.UIAxes_H, [-20 20], [0 0], 'k', 'LineWidth', 2);
            plot(app.UIAxes_H, [0 0], [-20 20], '--');
            app.iRayH = quiver(app.UIAxes_H,0,0,1,1, 0,'Color', app.okabeBlue, 'LineWidth',2);
            app.rRayH = quiver(app.UIAxes_H,0,0,1,1, 0,'Color', app.okabeReddishPurple, 'LineWidth',2);
            app.tRayH = quiver(app.UIAxes_H,0,0,1,1, 0, 'Color', app.okabeBluishGreen, 'LineWidth',2);

            app.arcI_H = plot(app.UIAxes_H, NaN, NaN, 'Color', app.okabeBlue, 'LineWidth', 2);
            app.arcR_H = plot(app.UIAxes_H, NaN, NaN, 'Color', app.okabeReddishPurple, 'LineWidth', 2);
            app.arcT_H = plot(app.UIAxes_H, NaN, NaN, 'Color', app.okabeBluishGreen, 'LineWidth', 2);


            app.arcTextI_H = text(app.UIAxes_H,NaN,NaN,'','Color',app.okabeBlue,'FontWeight','bold');
            app.arcTextR_H = text(app.UIAxes_H,NaN,NaN,'','Color',app.okabeReddishPurple,'FontWeight','bold');
            app.arcTextT_H = text(app.UIAxes_H,NaN,NaN,'','Color',app.okabeBluishGreen,'FontWeight','bold');

            % Incident field components
            app.iParH  = quiver(app.UIAxes_H, NaN, NaN, NaN, NaN, 0, ...
                'Color', app.okabeBlue, 'LineStyle', '--', 'LineWidth', 1.5);
            app.iPerpH = quiver(app.UIAxes_H, NaN, NaN, NaN, NaN, 0, ...
                'Color', app.okabeBlue, 'LineStyle', '--', 'LineWidth', 1.5);

            % Reflected field components
            app.rParH  = quiver(app.UIAxes_H, NaN, NaN, NaN, NaN, 0, ...
                'Color', app.okabeReddishPurple, 'LineStyle', '--', 'LineWidth', 1.5);
            app.rPerpH = quiver(app.UIAxes_H, NaN, NaN, NaN, NaN, 0, ...
                'Color', app.okabeReddishPurple, 'LineStyle', '--', 'LineWidth', 1.5);

            % Transmitted field components
            app.tParH  = quiver(app.UIAxes_H, NaN, NaN, NaN, NaN, 0, ...
                'Color', app.okabeBluishGreen, 'LineStyle', '--', 'LineWidth', 1.5);
            app.tPerpH = quiver(app.UIAxes_H, NaN, NaN, NaN, NaN, 0, ...
                'Color', app.okabeBluishGreen, 'LineStyle', '--', 'LineWidth', 1.5);

            % Main field labels
            app.iLabelH = text(app.UIAxes_H,NaN,NaN,'',...
                'Color',app.okabeBlue,...
                'FontWeight','bold',...
                'FontSize',14);

            app.rLabelH = text(app.UIAxes_H,NaN,NaN,'',...
                'Color',app.okabeReddishPurple,...
                'FontWeight','bold',...
                'FontSize',14);

            app.tLabelH = text(app.UIAxes_H,NaN,NaN,'',...
                'Color',app.okabeBluishGreen,...
                'FontWeight','bold',...
                'FontSize',14);

            % Incident components
            app.iParLabelH = text(app.UIAxes_H,NaN,NaN,'',...
                'Color',app.okabeBlue,...
                'FontSize',13);

            app.iPerpLabelH = text(app.UIAxes_H,NaN,NaN,'',...
                'Color',app.okabeBlue,...
                'FontSize',13);

            % Reflected components
            app.rParLabelH = text(app.UIAxes_H,NaN,NaN,'',...
                'Color',app.okabeReddishPurple,...
                'FontSize',13);

            app.rPerpLabelH = text(app.UIAxes_H,NaN,NaN,'',...
                'Color',app.okabeReddishPurple,...
                'FontSize',13);

            % Transmitted components
            app.tParLabelH = text(app.UIAxes_H,NaN,NaN,'',...
                'Color',app.okabeBluishGreen,...
                'FontSize',13);

            app.tPerpLabelH = text(app.UIAxes_H,NaN,NaN,'',...
                'Color',app.okabeBluishGreen,...
                'FontSize',13);


            hold(app.UIAxes_H, "off");


            app.selectedTab = app.SnellsLawTab;


        end

        % Value changed function: Epsilonr1EditField
        function Epsilonr1EditFieldValueChanged(app, event)
            app.er1 = app.Epsilonr1EditField.Value;
            app.ur1 = app.er1;

            updateValues(app);


        end

        % Value changed function: Epsilonr2EditField
        function Epsilonr2EditFieldValueChanged(app, event)
            app.er2 = app.Epsilonr2EditField.Value;
           
            app.ur2 = app.er2;
            updateValues(app);


        end

        % Value changed function: thetaiSlider
        function thetaiSliderValueChanged(app, event)
            app.theta_i = app.thetaiSlider.Value;
            updateValues(app);

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.selectedTab = app.TabGroup.SelectedTab;
            updateValues(app);
            
            
        end

        % Value changed function: thetaiSlider_E
        function thetaiSlider_EValueChanged(app, event)
            app.theta_i  = app.thetaiSlider_E.Value;
            updateValues(app);
            
        end

        % Value changed function: Epsilonr1EditField_E
        function Epsilonr1EditField_EValueChanged(app, event)
            app.er1 = app.Epsilonr1EditField_E.Value;
            app.ur1 = app.er1;
            updateValues(app);
            
        end

        % Value changed function: Epsilonr2EditField_E
        function Epsilonr2EditField_EValueChanged(app, event)
            app.er2 = app.Epsilonr2EditField_E.Value;
            app.ur2 = app.er2;

            updateValues(app);

        end

        % Value changed function: EiEditField
        function EiEditFieldValueChanged(app, event)
            app.Ei = app.EiEditField.Value;
            app.Hi = app.Ei;
            updateValues(app);
            
        end

        % Value changed function: SurfaceChargeDensityEditField
        function SurfaceChargeDensityEditFieldValueChanged(app, event)
            app.ps = app.SurfaceChargeDensityEditField.Value;
            app.Js = app.ps;
            updateValues(app);
            
        end

        % Value changed function: Epsilonr1EditField_H
        function Epsilonr1EditField_HValueChanged(app, event)
            app.ur1 = app.Epsilonr1EditField_H.Value;
            app.er1 = app.ur1;
            updateValues(app);
            
        end

        % Value changed function: Epsilonr2EditField_H
        function Epsilonr2EditField_HValueChanged(app, event)
            app.ur2 = app.Epsilonr2EditField_H.Value;
            app.er2 = app.ur2;
            updateValues(app);

            
        end

        % Value changed function: HiEditField
        function HiEditFieldValueChanged(app, event)
            app.Hi = app.HiEditField.Value;
            app.Ei = app.Hi;
            updateValues(app);
            
        end

        % Value changed function: thetaiSlider_H
        function thetaiSlider_HValueChanged(app, event)
            app.theta_i = app.thetaiSlider_H.Value;
            updateValues(app);
        end

        % Value changed function: JsEditField
        function JsJsEditFieldValueChanged(app, event)
            app.Js = app.JsEditField.Value;
            app.ps = app.Js;
            updateValues(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SnellsLawTab = uitab(app.TabGroup);
            app.SnellsLawTab.Title = 'Snell''s Law';
            app.UIAxes = uiaxes(app.SnellsLawTab);
            title(app.UIAxes, 'Snell''s Law Visualization')
            xlabel(app.UIAxes, 'Horizontal Distance')
            ylabel(app.UIAxes, 'Vertical Distance')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.SnellsLawTab);
            app.Panel_5 = uipanel(app.Panel);
            app.INPUTSLabel = uilabel(app.Panel_5);
            app.INPUTSLabel.Text = 'INPUTS:';
            app.Relativepermittivityr1Label = uilabel(app.Panel_5);
            app.Relativepermittivityr1Label.Text = 'Relative permittivity (εᵣ₁)';
            app.Epsilonr1EditField = uieditfield(app.Panel_5, 'numeric');
            app.Epsilonr1EditField.ValueChangedFcn = createCallbackFcn(app, @Epsilonr1EditFieldValueChanged, true);
            app.Relativepermittivityr1Label_2 = uilabel(app.Panel_5);
            app.Relativepermittivityr1Label_2.Text = 'Relative permittivity (εᵣ₂)';
            app.Epsilonr2EditField = uieditfield(app.Panel_5, 'numeric');
            app.Epsilonr2EditField.ValueChangedFcn = createCallbackFcn(app, @Epsilonr2EditFieldValueChanged, true);
            app.thetaiSlider = uislider(app.Panel_5);
            app.thetaiSlider.Limits = [0 89];
            app.thetaiSlider.ValueChangedFcn = createCallbackFcn(app, @thetaiSliderValueChanged, true);
            app.IncidentAngleiLabel_2 = uilabel(app.Panel_5);
            app.IncidentAngleiLabel_2.Text = 'Incident Angle (θᵢ):';
            app.Panel_6 = uipanel(app.Panel);
            app.OUTPUTSLabel_5 = uilabel(app.Panel_6);
            app.OUTPUTSLabel_5.Text = 'OUTPUTS:';
            app.ReflectedangleLabel = uilabel(app.Panel_6);
            app.ReflectedangleLabel.Text = 'Reflected angle (θᵣ) = ????';
            app.TransmittedangleLabel = uilabel(app.Panel_6);
            app.TransmittedangleLabel.Text = 'Transmitted angle (θₜ) = ????';
            app.TotalInternalReflectionLabel = uilabel(app.Panel_6);
            app.TotalInternalReflectionLabel.Text = 'Total Internal Reflection = ????';
            app.Medium1Label = uilabel(app.SnellsLawTab);
            app.Medium1Label.Text = 'Medium 1 (εᵣ₁)';
            app.Medium2Label = uilabel(app.SnellsLawTab);
            app.Medium2Label.Text = 'Medium 2 (εᵣ₂)';
            app.Panel_7 = uipanel(app.SnellsLawTab);
            app.LEGENDLabel = uilabel(app.Panel_7);
            app.LEGENDLabel.Text = 'LEGEND';
            app.Panel_8 = uipanel(app.Panel_7);
            app.Panel_9 = uipanel(app.Panel_7);
            app.Panel_10 = uipanel(app.Panel_7);
            app.IncidentRayLabel = uilabel(app.Panel_7);
            app.IncidentRayLabel.Text = 'Incident Ray';
            app.ReflectedRayLabel = uilabel(app.Panel_7);
            app.ReflectedRayLabel.Text = 'Reflected Ray';
            app.TransmittedRayLabel = uilabel(app.Panel_7);
            app.TransmittedRayLabel.Text = 'Transmitted Ray';
            app.ElectricFieldTab = uitab(app.TabGroup);
            app.ElectricFieldTab.Title = 'Electric Field Tab';
            app.UIAxes_E = uiaxes(app.ElectricFieldTab);
            title(app.UIAxes_E, 'Electric Field Visualization')
            xlabel(app.UIAxes_E, 'Horizontal Distance')
            ylabel(app.UIAxes_E, 'Vertical Distance')
            zlabel(app.UIAxes_E, 'Z')
            app.Medium1Label_2 = uilabel(app.ElectricFieldTab);
            app.Medium1Label_2.Text = 'Medium 1 (εᵣ₁)';
            app.Medium2Label_2 = uilabel(app.ElectricFieldTab);
            app.Medium2Label_2.Text = 'Medium 2 (εᵣ₂)';
            app.Panel_11 = uipanel(app.ElectricFieldTab);
            app.INPUTSLabel_5 = uilabel(app.Panel_11);
            app.INPUTSLabel_5.Text = 'INPUTS:';
            app.Relativepermittivityr1Label_3 = uilabel(app.Panel_11);
            app.Relativepermittivityr1Label_3.Text = 'Relative permittivity (εᵣ₁)';
            app.Epsilonr1EditField_E = uieditfield(app.Panel_11, 'numeric');
            app.Epsilonr1EditField_E.ValueChangedFcn = createCallbackFcn(app, @Epsilonr1EditField_EValueChanged, true);
            app.Relativepermittivityr1Label_4 = uilabel(app.Panel_11);
            app.Relativepermittivityr1Label_4.Text = 'Relative permittivity (εᵣ₂)';
            app.Epsilonr2EditField_E = uieditfield(app.Panel_11, 'numeric');
            app.Epsilonr2EditField_E.ValueChangedFcn = createCallbackFcn(app, @Epsilonr2EditField_EValueChanged, true);
            app.thetaiSlider_E = uislider(app.Panel_11);
            app.thetaiSlider_E.Limits = [0 89];
            app.thetaiSlider_E.ValueChangedFcn = createCallbackFcn(app, @thetaiSlider_EValueChanged, true);
            app.IncidentAngleiLabel_3 = uilabel(app.Panel_11);
            app.IncidentAngleiLabel_3.Text = 'Incident Angle (θᵢ):';
            app.IncidentElectricFieldELabel = uilabel(app.Panel_11);
            app.IncidentElectricFieldELabel.Text = 'Incident Electric Field (Eᵢ)';
            app.EiEditField = uieditfield(app.Panel_11, 'numeric');
            app.EiEditField.ValueChangedFcn = createCallbackFcn(app, @EiEditFieldValueChanged, true);
            app.sEditFieldLabel = uilabel(app.Panel_11);
            app.sEditFieldLabel.Text = 'Surface Charge Density (ρₛ)';
            app.SurfaceChargeDensityEditField = uieditfield(app.Panel_11, 'numeric');
            app.SurfaceChargeDensityEditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceChargeDensityEditFieldValueChanged, true);
            app.Panel_12 = uipanel(app.ElectricFieldTab);
            app.OUTPUTSLabel_6 = uilabel(app.Panel_12);
            app.OUTPUTSLabel_6.Text = 'OUTPUTS:';
            app.ReflectedangleLabel_E = uilabel(app.Panel_12);
            app.ReflectedangleLabel_E.Text = 'Reflected angle (θᵣ) = ????';
            app.TransmittedangleLabel_E = uilabel(app.Panel_12);
            app.TransmittedangleLabel_E.Text = 'Transmitted angle (θₜ) = ????';
            app.TransmittedFieldMagnitude = uilabel(app.Panel_12);
            app.TransmittedFieldMagnitude.Text = 'Transmitted field magnitude (E₂)  = ????';
            app.Panel_13 = uipanel(app.ElectricFieldTab);
            app.LEGENDLabel_2 = uilabel(app.Panel_13);
            app.LEGENDLabel_2.Text = 'LEGEND';
            app.Panel_14 = uipanel(app.Panel_13);
            app.Panel_15 = uipanel(app.Panel_13);
            app.Panel_16 = uipanel(app.Panel_13);
            app.IncidentRayLabel_2 = uilabel(app.Panel_13);
            app.IncidentRayLabel_2.Text = 'Incident Ray';
            app.ReflectedRayLabel_2 = uilabel(app.Panel_13);
            app.ReflectedRayLabel_2.Text = 'Reflected Ray';
            app.TransmittedRayLabel_2 = uilabel(app.Panel_13);
            app.TransmittedRayLabel_2.Text = 'Transmitted Ray';
            app.Panel_23 = uipanel(app.ElectricFieldTab);
            app.ComponentDefinitionsLabel = uilabel(app.Panel_23);
            app.ComponentDefinitionsLabel.Text = 'Component Definitions:';
            app.RelationsLabel = uilabel(app.Panel_23);
            app.RelationsLabel.Text = 'Relations:';
            app.Label = uilabel(app.Panel_23);
            app.Label.Text = 'Parallel (||): parallel to the interface (x-direction)';
            app.Label_4 = uilabel(app.Panel_23);
            app.Label_4.Text = 'Perpendicular (⊥): normal to the interface (y-direction)';
            app.Label_5 = uilabel(app.Panel_23);
            app.Label_5.Text = 'E = E|| x̂ + E⊥ ŷ';
            app.Label_6 = uilabel(app.Panel_23);
            app.Label_6.Text = '|E| = √(E||² + E⊥²)';
            app.MagneticFieldTab = uitab(app.TabGroup);
            app.MagneticFieldTab.Title = 'Magnetic Field Tab';
            app.UIAxes_H = uiaxes(app.MagneticFieldTab);
            title(app.UIAxes_H, 'Magnetic Field Visualization')
            xlabel(app.UIAxes_H, 'Horizontal Distance')
            ylabel(app.UIAxes_H, 'Vertical Distance')
            zlabel(app.UIAxes_H, 'Z')
            app.Panel_17 = uipanel(app.MagneticFieldTab);
            app.INPUTSLabel_6 = uilabel(app.Panel_17);
            app.INPUTSLabel_6.Text = 'INPUTS:';
            app.Relativepermittivityr1Label_5 = uilabel(app.Panel_17);
            app.Relativepermittivityr1Label_5.Text = 'Relative permittivity (μᵣ₁)';
            app.Epsilonr1EditField_H = uieditfield(app.Panel_17, 'numeric');
            app.Epsilonr1EditField_H.ValueChangedFcn = createCallbackFcn(app, @Epsilonr1EditField_HValueChanged, true);
            app.Relativepermittivityr1Label_6 = uilabel(app.Panel_17);
            app.Relativepermittivityr1Label_6.Text = 'Relative permittivity (μᵣ₂)';
            app.Epsilonr2EditField_H = uieditfield(app.Panel_17, 'numeric');
            app.Epsilonr2EditField_H.ValueChangedFcn = createCallbackFcn(app, @Epsilonr2EditField_HValueChanged, true);
            app.thetaiSlider_H = uislider(app.Panel_17);
            app.thetaiSlider_H.Limits = [0 89];
            app.thetaiSlider_H.ValueChangedFcn = createCallbackFcn(app, @thetaiSlider_HValueChanged, true);
            app.IncidentAngleiLabel_4 = uilabel(app.Panel_17);
            app.IncidentAngleiLabel_4.Text = 'Incident Angle (θᵢ):';
            app.IncidentElectricFieldELabel_2 = uilabel(app.Panel_17);
            app.IncidentElectricFieldELabel_2.Text = 'Incident Magnetic Field (Hᵢ)';
            app.HiEditField = uieditfield(app.Panel_17, 'numeric');
            app.HiEditField.ValueChangedFcn = createCallbackFcn(app, @HiEditFieldValueChanged, true);
            app.JsEditFieldLabel = uilabel(app.Panel_17);
            app.JsEditFieldLabel.Text = 'Surface Current Density (Jₛ)';
            app.JsEditField = uieditfield(app.Panel_17, 'numeric');
            app.JsEditField.ValueChangedFcn = createCallbackFcn(app, @JsJsEditFieldValueChanged, true);
            app.Medium1Label_3 = uilabel(app.MagneticFieldTab);
            app.Medium1Label_3.Text = 'Medium 1 (μᵣ₁)';
            app.Medium2Label_3 = uilabel(app.MagneticFieldTab);
            app.Medium2Label_3.Text = 'Medium 2 (μᵣ₂)';
            app.Panel_18 = uipanel(app.MagneticFieldTab);
            app.OUTPUTSLabel_7 = uilabel(app.Panel_18);
            app.OUTPUTSLabel_7.Text = 'OUTPUTS:';
            app.ReflectedangleLabel_H = uilabel(app.Panel_18);
            app.ReflectedangleLabel_H.Text = 'Reflected angle (θᵣ) = ????';
            app.TransmittedangleLabel_H = uilabel(app.Panel_18);
            app.TransmittedangleLabel_H.Text = 'Transmitted angle (θₜ) = ????';
            app.TransmittedFieldMagnitude_H = uilabel(app.Panel_18);
            app.TransmittedFieldMagnitude_H.Text = 'Transmitted field magnitude (H₂)  = ????';
            app.Panel_19 = uipanel(app.MagneticFieldTab);
            app.LEGENDLabel_3 = uilabel(app.Panel_19);
            app.LEGENDLabel_3.Text = 'LEGEND';
            app.Panel_20 = uipanel(app.Panel_19);
            app.Panel_21 = uipanel(app.Panel_19);
            app.Panel_22 = uipanel(app.Panel_19);
            app.IncidentRayLabel_3 = uilabel(app.Panel_19);
            app.IncidentRayLabel_3.Text = 'Incident Ray';
            app.ReflectedRayLabel_3 = uilabel(app.Panel_19);
            app.ReflectedRayLabel_3.Text = 'Reflected Ray';
            app.TransmittedRayLabel_3 = uilabel(app.Panel_19);
            app.TransmittedRayLabel_3.Text = 'Transmitted Ray';
            app.Panel_24 = uipanel(app.MagneticFieldTab);
            app.ComponentDefinitionsLabel_2 = uilabel(app.Panel_24);
            app.ComponentDefinitionsLabel_2.Text = 'Component Definitions:';
            app.RelationsLabel_2 = uilabel(app.Panel_24);
            app.RelationsLabel_2.Text = 'Relations:';
            app.Label_7 = uilabel(app.Panel_24);
            app.Label_7.Text = 'Parallel (||): parallel to the interface (x-direction)';
            app.Label_8 = uilabel(app.Panel_24);
            app.Label_8.Text = 'Perpendicular (⊥): normal to the interface (y-direction)';
            app.Label_9 = uilabel(app.Panel_24);
            app.Label_9.Text = 'H = H|| x̂ + H⊥ ŷ';
            app.Label_10 = uilabel(app.Panel_24);
            app.Label_10.Text = '|H| = √(H||² + H⊥²)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SnellAndBoundarySIm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end