classdef BounceDiagramSimulationUNFINISHED < matlab.apps.AppBase
    %BOUNCEDIAGRAMSIMULATIONUNFINISHED Bounce Diagram Simulation
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2024
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia Furse
    %   (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   Simulates a bounce diagram with fully customizable load and source
    %   resistances. Allows full control over the time and designated
    %   position for the Voltage vs. Time and Voltages vs. Position graphs.
    %   Assumes that the velocity of propagation is (2/3)*c m/s.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        PositionZSlider              matlab.ui.control.Slider
        PositionZSliderLabel         matlab.ui.control.Label
        RESETButton                  matlab.ui.control.Button
        up23cLabel                   matlab.ui.control.Label
        Z050Label                    matlab.ui.control.Label
        RSEditField                  matlab.ui.control.NumericEditField
        RSEditFieldLabel             matlab.ui.control.Label
        RLEditField                  matlab.ui.control.NumericEditField
        RLEditFieldLabel             matlab.ui.control.Label
        TimeTnsSlider                matlab.ui.control.Slider
        TimeTnsLabel                 matlab.ui.control.Label
        PLAYButton                   matlab.ui.control.Button
        BOUNCEDIAGRAMSIMULATORLabel  matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Time and simulation control:
        t_c  % Current time in simulation
        z_c = -1 % Current position on transmission line (starts at 0)
        prior_zc  % Prevents double adding TODO delete?
        t_des = 0;  % Designated time for plotting
        z_des = 0; % Designated position for plotting
        bounce = false  % Determines if a bounce is ocurring or not

        % TODO delete unnecessary variables and tidy up here

        % Bounce diagram animated line object
        s1

        % Voltage vs. Time graph animated line object
        VT1

        % Voltage vs. Position graph line object (total)
        Z1

        % Designated Z position dotted line
        Z_des_plot
        Zdp_x  % X values for Designated Z position
        Zdp_y  % Y values for Designated Z position

        t_ary

        % Determines if the simulation is on or off
        Play = false 

        Z0 = 50; % Characteristic impedance of transmission line
        RL = 100;  % resistance at the load
        RS = 25;  % Resistance at the source
        rC_L  % Reflection coefficient for the load
        rC_s  % Reflection coefficient for the source

        Vs = 1;  % (V). Source voltage for step pulse
        V_inc  % Current incident voltage TODO delete!!
        V_ref  % Current reflected voltage
        V_ref_idx = 1; % Current reflected voltage index
        V_ref_ary = zeros(1, 8) % Array of reflected voltages for the bounce diagram (8 reflected voltages, 4 bounces)

        V_tot = 0;  % Current total voltage. Initialized at zero

        % Voltage vs. Position data:
        zData
        vData
        n = 0  % Voltage vs. position data index

        % NEW DESIGN!!!!
        BD_ary  % 2D Array that holds z vs. t information
        ZT_ary  % 3D Array that holds V vs. z and t information V(z,t)
        t_size = 801 % Number of time increments (note 8 segments for bounces)\
        z_size = 100  % Number of spatial increments
        bouncePoints = [5 10 15 20 25 30 35 40]  % Points where bounces take place


    end
    
    methods (Access = private)
        
        function drawBounce(app)
            t_ary = app.ZT_ary(1, 1, :);
            
            t_des_idx = findClosestIndex(app, t_ary, app.t_des);
            
            % Adjust "1" to be t_des (index)
            for t = t_des_idx:app.t_size

                % TODO add pause functionality
                % Simulation control
                if ~app.Play
                    break;
                end

                % Draw bounce diagram:
                addpoints(app.s1, app.BD_ary(t, 2), app.BD_ary(t, 1));

                % Draw V vs. T diagram:
                z_ary = app.ZT_ary(:, 2, 1);
                z_idx = findClosestIndex(app, z_ary, app.z_des);

                % Goes to the "slice" of the 3D array at t_des. Plots the voltage (in column 3)
                % against the time value (in column 1) with row value =
                % z_des.
                addpoints(app.VT1, app.ZT_ary(z_idx, 3, t), app.ZT_ary(z_idx, 1, t))

                % Draw V vs. Z diagram:
                app.t_des = t_ary(t);
                set(app.Z1, 'XData', app.ZT_ary(:, 2, t), 'YData', app.ZT_ary(:, 3, t))

                % Update the time slider:
                app.TimeTnsSlider.Value = 40 - app.t_des;


                pause(0.02)
                drawnow limitrate;


            end

        end

        
        function initializeProperties(app)
            % Construct BD Array:
            app.BD_ary = zeros(app.t_size, 2);  % Initialize the array
            app.BD_ary(:,1) = linspace(0, 40, app.t_size);

            app.BD_ary(1:101, 2) = linspace(0,1,101);
            app.BD_ary(101:201, 2) = linspace(1,0,101);
            app.BD_ary(201:301, 2) = linspace(0,1,101);
            app.BD_ary(301:401, 2) = linspace(1,0,101);
            app.BD_ary(401:501, 2) = linspace(0,1,101);
            app.BD_ary(501:601, 2) = linspace(1,0,101);
            app.BD_ary(601:701, 2) = linspace(0,1,101);
            app.BD_ary(701:801, 2) = linspace(1,0,101);

            % ZT_ary is a 3D array that holds all voltage, position, and time data for the
            % given load and source resistances. I have constructed it by
            % concatenating 2D array "slices" where each slice is a given time with
            % its corresponding voltages at their designated positions along the
            % transmission line. 

            % Construct slices of ZT_ary (V vs. Z) to make 3D array:
            A = zeros(app.z_size, 3);
            timeary = linspace(0,40,app.t_size);

            % Establish V_tot:
            app.V_tot = app.V_ref_ary(1);

            % First bounce
            for t = 1:100
                A(:,1) = timeary(t);
                A(:,2) = linspace(0,1,app.z_size);
                A(t,3) = app.V_tot;
                app.ZT_ary = cat(3, app.ZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(2);

            % Second bounce
            for t = 101:200
                A(:,1) = timeary(t);
                A(:,2) = linspace(0,1,app.z_size);
                A(200 - t + 1,3) = app.V_tot;
                app.ZT_ary = cat(3, app.ZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(3);

            % Third bounce
            for t = 201:300
                A(:,1) = timeary(t);
                A(:,2) = linspace(0,1,app.z_size);
                A(t - 201 + 1,3) = app.V_tot;
                app.ZT_ary = cat(3, app.ZT_ary, A);
            end

            disp(app.ZT_ary(:,:,200))

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(4);

            % Fourth bounce
            for t = 301:400
                A(:,1) = timeary(t);
                A(:,2) = linspace(0,1,app.z_size);
                A(400 - t + 1,3) = app.V_tot;
                app.ZT_ary = cat(3, app.ZT_ary, A);
            end

            disp(app.ZT_ary(:,:,300))

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(5);

            % Fifth bounce
            for t = 401:500
                A(:,1) = timeary(t);
                A(:,2) = linspace(0,1,app.z_size);
                A(t - 401 + 1,3) = app.V_tot;
                app.ZT_ary = cat(3, app.ZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(6);

            % Third bounce
            for t = 501:600
                A(:,1) = timeary(t);
                A(:,2) = linspace(0,1,app.z_size);
                A(600 - t + 1,3) = app.V_tot;
                app.ZT_ary = cat(3, app.ZT_ary, A);
            end

            disp(app.ZT_ary(:,:,500))

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(7);

            % Seventh bounce
            for t = 601:700
                A(:,1) = timeary(t);
                A(:,2) = linspace(0,1,app.z_size);
                A(t - 601 + 1,3) = app.V_tot;
                app.ZT_ary = cat(3, app.ZT_ary, A);
            end

            % Update V_tot
            app.V_tot = app.V_tot + app.V_ref_ary(8);

            % Eight bounce
            for t = 701:800
                A(:,1) = timeary(t);
                A(:,2) = linspace(0,1,app.z_size);
                A(800 - t + 1,3) = app.V_tot;
                app.ZT_ary = cat(3, app.ZT_ary, A);
            end

            disp(app.ZT_ary(:,:,800))

            % Final slice:
            A(:,1) = timeary(801);
            app.ZT_ary = cat(3,app.ZT_ary,A);

            % Construct t_ary for dynamic sliders:
            app.t_ary = app.ZT_ary(1, 1, :);
        end
        
        % Written by ChatGPT
        function idx = findClosestIndex(~, array, value)
            [~, idx] = min(abs(array - value)); % Find the index of the minimum absolute difference
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Ensure X-axis on diagrams are rigid    
            app.UIAxes.XLim = [0 1];
            app.UIAxes2.XLim = [0 1];
            app.UIAxes3.XLim = [0 1];
            app.UIAxes3.YLim = [0,1];

            % Ensure TODO z-slider is aligned with bounce diagram:
            % Create GridLayout2
            disp("Bounce diagram get inner position:")
            disp(app.UIAxes.InnerPosition)

            disp("Bounce diagram get outer position:")
            disp(app.UIAxes.OuterPosition)

            disp("Bounce diagram get position:")
            disp(app.UIAxes.Position)


            % app.GridLayout2 = uigridlayout(app.GridLayout);
            % app.GridLayout2.ColumnWidth = {'fit', 130, '1x', 190};
            % app.GridLayout2.RowHeight = {'fit'};
            % app.GridLayout2.Layout.Row = 2;
            % app.GridLayout2.Layout.Column = [1 5];

            % Ensure interactivity
            datacursormode(app.UIAxes2, 'on');

            % Calculate initial reflection coefficients:
            app.rC_L = (app.RL - app.Z0)/(app.RL + app.Z0);
            app.rC_s = (app.RS - app.Z0)/(app.RS + app.Z0);

            % Initialize Reflected voltages array:
            % 8 Reflected voltages for 4 bounces: FUNCTION CREATE! (for
            % user-directed RL and RS loads) TODO ?

            % Animated line object initialization:
            % line segment (bounce diagram) animated line object
            app.s1 = animatedline(app.UIAxes, 'Color', 'b', 'LineWidth', 2);
            hold(app.UIAxes, 'on')

            % (Voltage vs. time) animated line object
            app.VT1 = animatedline(app.UIAxes2, 'Color', 'b', 'LineWidth', 2);

            % (Voltage vs. position) LINE object
            t = linspace(0, 5, 5/.2);
            app.zData = zeros(1, length(t));
            app.vData = zeros(1, length(app.zData));
            app.Z1 = line(app.UIAxes3, 'xData', app.zData, 'yData', app.vData, 'Color', 'b', 'LineWidth', 2);

            % Initialize V_ref (V1+):
            app.V_ref = app.Vs * (app.Z0/(app.Z0 + app.RS));
            app.V_ref_ary(1) = app.V_ref;

            % Initialize V_tot:
            app.V_tot = 0;

            % Plot Z_des on bounce diagram:
            app.Zdp_x = ones(100) * app.z_des;
            app.Zdp_y = linspace(0, 40, size(app.Zdp_x, 1));
            app.Z_des_plot = plot(app.UIAxes, app.Zdp_x, app.Zdp_y);

            % Reflected voltages toward load (odd indices of array)
            % TODO test functionality with changing time and updating RL
            % and RS loads. 
            for n = 2:8
                % If even index:
                if (mod(n,2) == 0)
                    app.V_ref_ary(n) = app.V_ref_ary(n-1) * app.rC_L;
                else  % odd index condition:
                    app.V_ref_ary(n) = app.V_ref_ary(n-1) * app.rC_s;
                end
            end

            % TODO delete?
            disp("V_ref_ary:")
            disp(app.V_ref_ary)

            initializeProperties(app)
            

        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            if ~app.Play
                app.Play = true;
                disp("PLAY button pushed!")
                app.PLAYButton.Text = "PAUSE";
                % PlaySimulation(app);  % Adjust TODO adjust this
                drawBounce(app);
            else
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
                disp("PAUSE button pushed")
            end
        end

        % Value changed function: RLEditField
        function RLEditFieldValueChanged(app, event)
            app.RL = app.RLEditField.Value;
            
            % Recalulcate reflection coefficient at the load
            app.rC_L = (app.RL - app.Z0)/(app.RL + app.Z0);

            % Reflected voltages toward load (odd indices of array)
            % TODO test functionality with changing time and updating RL
            % and RS loads.
            for n = 2:8
                % If even index:
                if (mod(n,2) == 0)
                    app.V_ref_ary(n) = app.V_ref_ary(n-1) * app.rC_L;
                else  % odd index condition:
                    app.V_ref_ary(n) = app.V_ref_ary(n-1) * app.rC_s;
                end
            end

            % TODO delete?
            disp("NEW V_ref_ary:")
            disp(app.V_ref_ary)

            % Delete current ZT_ary:
            app.ZT_ary = [];

            initializeProperties(app)

            % TODO NOW

            % Track indices: TODO NOW EVENT.VALUE is different here
            new_idx = findClosestIndex(app, app.t_ary, app.t_des);

            % Update graphs:

            clearpoints(app.s1)
            clearpoints(app.VT1)

            % Update bounce diagram
            addpoints(app.s1, app.BD_ary(1:new_idx, 2), app.BD_ary(1:new_idx, 1));

            % Update V vs. T graph:
            z_ary = app.ZT_ary(:, 2, 1);
            z_idx = findClosestIndex(app, z_ary, app.z_des);
            x_ary = squeeze(app.ZT_ary(z_idx, 3, 1:new_idx));
            y_ary = squeeze(app.ZT_ary(z_idx, 1, 1:new_idx));
            addpoints(app.VT1, x_ary, y_ary)

            % Update V vs. Z graph:
            set(app.Z1, 'XData', app.ZT_ary(:, 2, new_idx), 'YData', app.ZT_ary(:, 3, new_idx))

            % Update graphics
            drawnow limitrate
        end

        % Value changed function: RSEditField
        function RSEditFieldValueChanged(app, event)
            app.RS = app.RSEditField.Value;
            
            % Recalculate source reflection coefficient:
            app.rC_s = (app.RS - app.Z0)/(app.RS + app.Z0);

            % Recalculate reflected voltages
            for n = 2:8
                % If even index:
                if (mod(n,2) == 0)
                    app.V_ref_ary(n) = app.V_ref_ary(n-1) * app.rC_L;
                else  % odd index condition:
                    app.V_ref_ary(n) = app.V_ref_ary(n-1) * app.rC_s;
                end
            end

            % TODO delete?
            disp("V_ref_ary:")
            disp(app.V_ref_ary)

            % Delete current ZT_ary:
            app.ZT_ary = [];

            initializeProperties(app)

            % TODO NOW

            % Track indices: TODO NOW EVENT.VALUE is different here
            new_idx = findClosestIndex(app, app.t_ary, app.t_des);

            % Update graphs:

            clearpoints(app.s1)
            clearpoints(app.VT1)

            % Update bounce diagram
            addpoints(app.s1, app.BD_ary(1:new_idx, 2), app.BD_ary(1:new_idx, 1));

            % Update V vs. T graph:
            z_ary = app.ZT_ary(:, 2, 1);
            z_idx = findClosestIndex(app, z_ary, app.z_des);
            x_ary = squeeze(app.ZT_ary(z_idx, 3, 1:new_idx));
            y_ary = squeeze(app.ZT_ary(z_idx, 1, 1:new_idx));
            addpoints(app.VT1, x_ary, y_ary)

            % Update V vs. Z graph:
            set(app.Z1, 'XData', app.ZT_ary(:, 2, new_idx), 'YData', app.ZT_ary(:, 3, new_idx))

            % Update graphics
            drawnow limitrate
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.t_des = 0;  % Reset time to zero
            app.TimeTnsSlider.Value = 40 - app.t_des;

            % option Graphs

            clearpoints(app.s1) % Clear points on Bounce diagram
            clearpoints(app.VT1)  % Clear points on V vs. T graph
            cla(app.UIAxes3)
            app.Z1 = line(app.UIAxes3, 'xData', app.zData, 'yData', app.vData, 'Color', 'b', 'LineWidth', 2);

            % Pause the simulation
            app.Play = false;
            app.PLAYButton.Text = "PLAY";
            

        end

        % Value changing function: TimeTnsSlider
        function TimeTnsSliderValueChanging(app, event)
            % Pause the simulation:
            app.Play = false;
            app.PLAYButton.Text = "PLAY";
            
            % Track indices:
            recent_t_des_idx = findClosestIndex(app, app.t_ary, app.t_des);
            new_idx = findClosestIndex(app, app.t_ary, 40 - event.Value);  % updates t_des to new value INDEX

            % TODO bug: when you let go of the time slier and re_adjust, it
            % connects dots in wierd places. 

            % Update the graphs:
            if 40 - event.Value > app.t_des
                % Update bounce diagram
                addpoints(app.s1, app.BD_ary(recent_t_des_idx:new_idx, 2), app.BD_ary(recent_t_des_idx:new_idx, 1));

                % Update V vs. T graph: (TODO optimize variables?)
                z_ary = app.ZT_ary(:, 2, 1);
                z_idx = findClosestIndex(app, z_ary, app.z_des);
                x_ary = squeeze(app.ZT_ary(z_idx, 3, recent_t_des_idx:new_idx));
                y_ary = squeeze(app.ZT_ary(z_idx, 1, recent_t_des_idx:new_idx));
                addpoints(app.VT1, x_ary, y_ary)

                % Update t_des
                app.t_des = 40 - event.Value;

                % Update graphics
                drawnow limitrate
            elseif 40 - event.Value < app.t_des
                clearpoints(app.s1)
                clearpoints(app.VT1)

                % Update bounce diagram
                addpoints(app.s1, app.BD_ary(1:new_idx, 2), app.BD_ary(1:new_idx, 1));

                % Update V vs. T graph: (TODO optimize variables?)
                z_ary = app.ZT_ary(:, 2, 1);
                z_idx = findClosestIndex(app, z_ary, app.z_des);
                x_ary = squeeze(app.ZT_ary(z_idx, 3, 1:new_idx));
                y_ary = squeeze(app.ZT_ary(z_idx, 1, 1:new_idx));
                addpoints(app.VT1, x_ary, y_ary)

                % Update t_des
                app.t_des = 40 - event.Value;

                % Update graphics
                drawnow limitrate
            end

            % Update V vs. Z graph:
            set(app.Z1, 'XData', app.ZT_ary(:, 2, new_idx), 'YData', app.ZT_ary(:, 3, new_idx))
            
        end

        % Value changing function: PositionZSlider
        function PositionZSliderValueChanging(app, event)
            % Pause the simulation:
            app.Play = false;
            app.PLAYButton.Text = "PLAY";
            
            app.z_des = event.Value;  % Updated designated Z-position

            % Update dotted line of where z_des is on bounce diagram
            app.Zdp_x = ones(100, 1) .* app.z_des;
            app.Zdp_y = linspace(0, 40, size(app.Zdp_x, 1));
            set(app.Z_des_plot, 'XData', app.Zdp_x, 'YData', app.Zdp_y);

            % TODO delete all "disp" in this app to improve performance.
            % leave some commented so that those who are curious can look
            % at a 3D array used in this app

            % as the slider changes, the V vs. T graph also changes up
            % until t_des


            % Track indices:
            t_idx = findClosestIndex(app, app.t_ary, app.t_des);  % updates t_des to new value INDEX

            % Update V vs. T graph:

            % clear the points on V vs. T graph
            clearpoints(app.VT1) 

            % Draw the new V vs. T graph until T_des

            z_ary = app.ZT_ary(:, 2, 1);
            z_idx = findClosestIndex(app, z_ary, app.z_des);
            x_ary = squeeze(app.ZT_ary(z_idx, 3, 1:t_idx));
            y_ary = squeeze(app.ZT_ary(z_idx, 1, 1:t_idx));
            addpoints(app.VT1, x_ary, y_ary)

            drawnow limitrate;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'V(t) at z = Z')
            xlabel(app.UIAxes2, 'Voltage (V)')
            ylabel(app.UIAxes2, 'Time (t)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YLim = [0 40];
            app.UIAxes2.YDir = 'reverse';
            app.UIAxes2.XAxisLocation = 'top';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Bounce Diagram')
            xlabel(app.UIAxes, 'Position (z) (m)')
            ylabel(app.UIAxes, 'Time (t) (ns)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 40];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'V(z) at t = T')
            xlabel(app.UIAxes3, 'Position (z)')
            ylabel(app.UIAxes3, 'Voltage (V)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YDir = 'reverse';
            app.UIAxes3.XAxisLocation = 'top';
            app.BOUNCEDIAGRAMSIMULATORLabel = uilabel(app.GridLayout);
            app.BOUNCEDIAGRAMSIMULATORLabel.Text = 'BOUNCE DIAGRAM SIMULATOR';
            app.PLAYButton = uibutton(app.GridLayout, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.TimeTnsLabel = uilabel(app.GridLayout);
            app.TimeTnsLabel.Text = 'Time (T) (ns):';
            app.TimeTnsSlider = uislider(app.GridLayout);
            app.TimeTnsSlider.Limits = [0 40];
            app.TimeTnsSlider.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.TimeTnsSlider.MajorTickLabels = {'40', '35', '30', '25', '20', '15', '10', '5', '0'};
            app.TimeTnsSlider.ValueChangingFcn = createCallbackFcn(app, @TimeTnsSliderValueChanging, true);
            app.TimeTnsSlider.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11 11.5 12 12.5 13 13.5 14 14.5 15 15.5 16 16.5 17 17.5 18 18.5 19 19.5 20 20.5 21 21.5 22 22.5 23 23.5 24 24.5 25 25.5 26 26.5 27 27.5 28 28.5 29 29.5 30 30.5 31 31.5 32 32.5 33 33.5 34 34.5 35 35.5 36 36.5 37 37.5 38 38.5 39 39.5 40];
            app.TimeTnsSlider.Value = 40;
            app.RLEditFieldLabel = uilabel(app.GridLayout);
            app.RLEditFieldLabel.Text = 'RL (Ω): ';
            app.RLEditField = uieditfield(app.GridLayout, 'numeric');
            app.RLEditField.Limits = [0 Inf];
            app.RLEditField.ValueChangedFcn = createCallbackFcn(app, @RLEditFieldValueChanged, true);
            app.RLEditField.Value = 100;
            app.RSEditFieldLabel = uilabel(app.GridLayout);
            app.RSEditFieldLabel.Text = 'RS (Ω): ';
            app.RSEditField = uieditfield(app.GridLayout, 'numeric');
            app.RSEditField.Limits = [0 Inf];
            app.RSEditField.ValueChangedFcn = createCallbackFcn(app, @RSEditFieldValueChanged, true);
            app.RSEditField.Value = 25;
            app.Z050Label = uilabel(app.GridLayout);
            app.Z050Label.Text = 'Z0 = 50 (Ω)';
            app.up23cLabel = uilabel(app.GridLayout);
            app.up23cLabel.Text = 'up = (2/3)*c';
            app.RESETButton = uibutton(app.GridLayout, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PositionZSliderLabel = uilabel(app.GridLayout2);
            app.PositionZSliderLabel.Text = 'Position (Z): ';
            app.PositionZSlider = uislider(app.GridLayout2);
            app.PositionZSlider.Limits = [0 1];
            app.PositionZSlider.ValueChangingFcn = createCallbackFcn(app, @PositionZSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BounceDiagramSimulationUNFINISHED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end