classdef FourierSeriesApp < matlab.apps.AppBase
    %FOURIERSERIESAPP Fourier Series Graphic Calculator
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2024
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia Furse
    %   (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   Showcases a Fourier series where the number of terms is controlled
    %   by the user. The upper plot shows the actual sum of terms and the
    %   bottom plot overlaps the individual sine waves in the series. 

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        EquationLabel                  matlab.ui.control.Label
        NumberofsinewavesnSlider       matlab.ui.control.Slider
        NumberofsinewavesnSliderLabel  matlab.ui.control.Label
        FourierSeriesLabel             matlab.ui.control.Label
        FourierSum                     matlab.ui.control.UIAxes
        SineWaves                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FourierSumFunction% Function for the Fourier Sum
        f = 1 % Spatial Frequency of Square Wave
        n = 1 % Number of sine waves
        FourierSumPlot % Plot handle for the Fourier Sum
        PlotHandles = []  % Stores the most recently added plot handle
    end
    
    methods (Access = private)
        
        function PlotFourierSum(app, n)
            syms x
            app.FourierSumFunction = 0; % Reset equation for recalculation
            delete(app.FourierSumPlot); % Reset Fourier Sum

            % Update FourierSumFunction: (SQUARE WAVE)
            for k = 1:n
               % Plot the Fourier Sum:
               app.FourierSumFunction = app.FourierSumFunction + (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1);
            end

            % Plot the Fourier Sum function:
            app.FourierSumPlot = fplot(app.FourierSum, app.FourierSumFunction, 'k');
            drawnow limitrate

            % Plot individual sine waves:
            % Ensures quick slider movements are accounted for. 
            numPlots = length(app.SineWaves.Children); % Get the number of plots currently on the Axes Object
            sliderDiff = n - numPlots;  % Difference between current slider n and number of plots
            if sliderDiff > 0
                for k = numPlots+1:n
                    app.PlotHandles(end+1) = fplot(app.SineWaves, (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1));
                end
            elseif sliderDiff < 0
                for k = n+1:numPlots
                    delete(app.PlotHandles(end));
                    app.PlotHandles(end) = [];
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.SineWaves, 'on') % Ensures multiple sine waves can be on the graph
            hold(app.FourierSum, 'on') % Ensures the +-1 reference is on Fourier Sum plot

            % Plot the first sine wave and Fourier Series:
            syms x
            k = 1;

            % Plot initial Fourier Series:
            app.FourierSumFunction = (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1);
            app.FourierSumPlot = fplot(app.FourierSum, app.FourierSumFunction, 'k');

            % Plot reference points:
            fplot(app.FourierSum, 1, 'k:')
            fplot(app.FourierSum, -1, 'k:')

            % Keep track of sine wave plot handles:
            app.PlotHandles(end+1) = fplot(app.SineWaves, (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1));

            drawnow


        end

        % Value changing function: NumberofsinewavesnSlider
        function NumberofsinewavesnSliderValueChanging(app, event)
            changingValue = event.Value;

            % Only plot the Fourier Sum if the slider has approached
            % another integer
            if round(changingValue) ~= app.n
                app.n = round(changingValue);
                PlotFourierSum(app, app.n);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SineWaves = uiaxes(app.GridLayout);
            title(app.SineWaves, 'Sine Waves')
            xlabel(app.SineWaves, 'x')
            ylabel(app.SineWaves, 'f(x)')
            zlabel(app.SineWaves, 'Z')
            app.SineWaves.XLim = [0 2];
            app.SineWaves.YLim = [-1.5 1.5];
            app.FourierSum = uiaxes(app.GridLayout);
            title(app.FourierSum, 'Sum of Sine Waves')
            xlabel(app.FourierSum, 'x')
            ylabel(app.FourierSum, 'f(x)')
            zlabel(app.FourierSum, 'Z')
            app.FourierSum.XLim = [0 2];
            app.FourierSum.YLim = [-1.5 1.5];
            app.FourierSeriesLabel = uilabel(app.GridLayout);
            app.FourierSeriesLabel.Text = 'Fourier Series';
            app.NumberofsinewavesnSliderLabel = uilabel(app.GridLayout);
            app.NumberofsinewavesnSliderLabel.Text = 'Number of sine waves (n):';
            app.NumberofsinewavesnSlider = uislider(app.GridLayout);
            app.NumberofsinewavesnSlider.Limits = [1 21];
            app.NumberofsinewavesnSlider.ValueChangingFcn = createCallbackFcn(app, @NumberofsinewavesnSliderValueChanging, true);
            app.NumberofsinewavesnSlider.MinorTicks = [1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10 10.2 10.4 10.6 10.8 11 11.2 11.4 11.6 11.8 12 12.2 12.4 12.6 12.8 13 13.2 13.4 13.6 13.8 14 14.2 14.4 14.6 14.8 15 15.2 15.4 15.6 15.8 16 16.2 16.4 16.6 16.8 17 17.2 17.4 17.6 17.8 18 18.2 18.4 18.6 18.8 19 19.2 19.4 19.6 19.8 20 20.2 20.4 20.6 20.8 21];
            app.NumberofsinewavesnSlider.Value = 1;
            app.EquationLabel = uilabel(app.GridLayout);
            app.EquationLabel.Interpreter = 'latex';
            app.EquationLabel.Text = '$$ f(x) = \frac{4}{\pi} \displaystyle\sum_{n=0}^{\infty} \frac{1}{2n+1} \sin\left((2n+1) \cdot 2\pi \cdot x\right) $$';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierSeriesApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end