classdef HalfWavelengthDipoleCalculator < matlab.apps.AppBase
    %HALFWAVELENGTHDIPOLECALCULATOR Calculates the dipole length for a
    %half-wavelength dipole
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2024 AND ???
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia Furse
    %   (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   Prototype for Intro app. Assuming perfect conductance (up = c)

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        DipolelengthLabel            matlab.ui.control.Label
        OperatingFrequencyEditField  matlab.ui.control.NumericEditField
        OperatingFrequencyEditFieldLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        HalfwavelengthDipoleAntennaCalculatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        OperatingFrequency % Frequency at which the antenna operates
        DipoleLen  % Length of the dipole
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OperatingFrequencyEditField
        function OpFreqChange(app, event)
            app.OperatingFrequency = app.OperatingFrequencyEditField.Value;
            app.DipoleLen = (3e8 / app.OperatingFrequency) / 2; 
            
            app.DipolelengthLabel.Text = "Dipole Length = " + app.DipoleLen + " m";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HalfwavelengthDipoleAntennaCalculatorLabel = uilabel(app.GridLayout);
            app.HalfwavelengthDipoleAntennaCalculatorLabel.Text = 'Half-wavelength Dipole Antenna Calculator';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'HalfWavelengthDipoleFigure.jpg');
            app.OperatingFrequencyEditFieldLabel = uilabel(app.GridLayout);
            app.OperatingFrequencyEditFieldLabel.Text = 'Operating Frequency';
            app.OperatingFrequencyEditField = uieditfield(app.GridLayout, 'numeric');
            app.OperatingFrequencyEditField.Limits = [0 Inf];
            app.OperatingFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @OpFreqChange, true);
            app.DipolelengthLabel = uilabel(app.GridLayout);
            app.DipolelengthLabel.Text = 'Dipole length = ???';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HalfWavelengthDipoleCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end