classdef AttenuationSimulationStill < matlab.apps.AppBase
    %ATTENUATIONSIMULATIONSTILL Transmission with variable attenuation and
    %frequency
    %   % CC BY-SA (Attribution Sharealike) Carsen Villegas 2024
    %   % Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia
    %   Furse
    %   % (cynthia.furse@utah.edu)
    %   % University of Utah Electrical and Computer Engineering
    %   
    %   TODO : names to add : Shaun Mckellar, Richard Nagel, James
    %   
    %   Simulations a sinusoidal signal on a transmission line. The user
    %   can change the attenuation constant and also the signal frequency.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        EnvelopeCheckBox  matlab.ui.control.CheckBox
        ResetButton       matlab.ui.control.Button
        PLAYButton        matlab.ui.control.Button
        Title             matlab.ui.control.Label
        lineVIncident     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Physical Constants
        eps_0 = 8.85418782E-12;  %  Permittivity of free space (F/m).
        mu_0 = 4.0*pi*1E-7;      %  Permeability of free space (H/m).

        % Simulation parameters
        dz = 0.002;                % Grid resolution (m)
        kmax = 500;              % Number of grid points to model 1 m of transmission line

        % Arrays for wave data
        % Incident wave:
        v
        i

        % Source parameters
        Vg = 1.0;                   % Amplitude (V)
        freq = 1.25e9;                 % Frequency for the source 1 GHz

        % Transmission line parameters for RG-58
        b   = (3.51e-3)/2;       % Outer conductor radius (m)
        a   = (0.91e-3)/2;       % Inner conductor radius (m)

        % Conductor information (copper)
        sigma_cond = 1E4; % Conductivity (S/m) of copper
        epsr_cond = 1.0;        % Relative permittivity of copper
        mu_cond = 4.0*pi*1E-7   % Permeability of copper

        % Insulator (dielectric) information (polyethylene)
        sigma_ins = 0.0001;     % Conductivity (S/m)
        epsr_ins = 2.25;        % Relative permittivity
        mu_ins = 4.0*pi*1E-7;   % Permeability of the insulator

        % RLGC constants of coaxial cable:
        Rs
        Rprime
        Lprime
        Gprime
        Cprime

        % Angular frequency
        w

        % Prop. constant
        gamma

        % Phase constant
        Beta

        % Attenuation constant
        alpha

        % Wave propagation speed
        up

        % Time step increment
        dt
        A = .9 % Correction factor for stability

        % FDTD coefficients:
        c1
        c2
        c3
        c4

        % Transmission Line Load Parameters
        Z0

        % Simulation Control
        Play = false; % State of the simulation (boolean)
        n = 1;  % Simulation counter
        m = 1;  % Enveloper counter
        % load = 'MATCHED';  % Load type

        % Plotting
        T_fr = 3;  % Time steps per plot update
        z_voltage
        z_current

        lineVIncidentPlot
        lineVEnvelopePlot
    end
    
    methods (Access = private)
        
        % Runs the simulation
        function PlaySimulation(app)

            while true

                % Simulation control
                if ~app.Play
                    break;
                end

                % Frequency of the source
                app.v(1) = sin(app.n * app.dt * app.freq * 2 * pi);

                % Right End Boundary Condition (MATCHED for "INCIDENT")
                app.v(app.kmax) = abs(app.Z0) * app.i(app.kmax - 1);
                app.i(app.kmax - 1) = app.v(app.kmax) / abs(app.Z0);

                % Vectorized current update
                k_range = 1:app.kmax - 1;

                app.i(k_range) = app.c1 * (app.v(k_range + 1) - app.v(k_range)) + app.c2 * app.i(k_range);

                % Vectorized voltage update
                k_range = 2:app.kmax - 1; 

                app.v(k_range) = app.c3 * (app.i(k_range) - app.i(k_range - 1)) + app.c4 * app.v(k_range);

                % Graphics updating loop
                if mod(app.n, app.T_fr) == 0
                    % Update plots:
                    set(app.lineVIncidentPlot, 'ydata', app.v);
                    pause(0.02)
                    drawnow limitrate;
                end
                app.n = app.n + 1;  % Simulation counter
                pause(0);
            end

        end

        function initializeProperties(app)
            % Initialize variables dependent on kmax

            % Incident wave data set:
            app.v = zeros(1, app.kmax);
            app.i = zeros(1, app.kmax - 1);

            % Calculate RLGC parameters
            app.Rs = sqrt(pi * app.freq * app.mu_cond / app.sigma_cond);
            app.Rprime = (app.Rs / (2 * pi)) * ((1 / app.a) + (1 / app.b));
            app.Lprime = (app.mu_ins / (2 * pi)) * log(app.b / app.a);
            app.Gprime = (2 * pi * app.sigma_ins) / log(app.b / app.a);
            app.Cprime = (2 * pi * app.epsr_ins * app.eps_0) / log(app.b / app.a);

            % Calculate angular frequency
            app.w = 2 * pi * app.freq;

            % Propagation constant
            app.gamma = sqrt((app.Rprime + 1i * app.w * app.Lprime) * (app.Gprime + 1i * app.w * app.Cprime));

            % Attenuation constant alpha:
            app.alpha = real(app.gamma);
            disp(app.alpha)

            % Phase constant Beta
            app.Beta = imag(app.gamma);

            % Wave propagation speed
            app.up = app.w / imag(app.gamma);

            % Time step incrementapp NEEDS TO BE LESS THAN EXACT VALUE
            % otherwise the simulation blows up
            app.dt = app.A * app.dz / app.up;

            % FDTD coefficients ALL CHECKED. 
            app.c1 = -(2 * app.dt) / (app.dt * app.dz * app.Rprime + 2 * app.dz * app.Lprime);
            app.c2 = (2 * app.Lprime - app.dt * app.Rprime) / (2 * app.Lprime + app.dt * app.Rprime);
            app.c3 = -(2 * app.dt) / ((app.dt * app.dz * app.Gprime) + (2 * app.dz * app.Cprime));
            app.c4 = (2 * app.Cprime - app.dt * app.Gprime) / (2 * app.Cprime + app.dt * app.Gprime);

            % Transmission Load parameters
            app.Z0 = sqrt((app.Rprime + 1i * app.w * app.Lprime) / (app.Gprime + 1i * app.w * app.Cprime));

            % Plotting:
            app.z_voltage  = (0:app.kmax-1) * app.dz;
            app.z_current  = (0:app.kmax-2) * app.dz + app.dz / 2;
        end
        
        % CHAT GPT:
        function freqString = formatFrequency(app, value)
            % Define magnitude prefixes and their corresponding powers of 10
            prefixes = {'', 'k', 'M', 'G', 'T'};  % '', kilo, mega, giga, tera
            exponent = floor(log10(abs(value)) / 3);  % Determine magnitude level
            exponent = min(exponent, length(prefixes) - 1);  % Cap at the largest prefix

            % Scale the value and round to two decimal places
            scaledValue = value / (10^(exponent * 3));
            roundedValue = round(scaledValue, 2);

            % Generate the output string with the appropriate prefix
            freqString = sprintf('%.2f %sHz', roundedValue, prefixes{exponent + 1});
        end

        % CHAT GPT
        function sigmaString = toScientificNotation(app, value)
            % Get the exponent and mantissa in scientific notation
            exponent = floor(log10(abs(value)));
            mantissa = value / (10^exponent);

            % Round the mantissa to two decimal places
            roundedMantissa = round(mantissa, 2);

            % Format the result as a string with scientific notation
            sigmaString = sprintf('%.2f × 10^%d', roundedMantissa, exponent);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the variable in the app
            initializeProperties(app)

            % Initialize the graphs to be zero:
            yData = zeros(1, length(app.z_voltage));

            % Initialize the Incident Plot
            app.lineVIncidentPlot = plot(app.lineVIncident, app.z_voltage, yData, 'Color', 'b', 'linewidth', 2);
            hold(app.lineVIncident, 'on');
            plot(app.lineVIncident, app.z_voltage, zeros(length(app.z_voltage)), 'k:'); % Draws a horizontile line through y = 0. 

            drawnow;
            
        end

        % Button pushed function: PLAYButton
        function PausePlaySimulation(app, event)
            if ~app.Play
                app.Play = true;
                disp("PLAY button pushed!")
                app.PLAYButton.Text = "PAUSE";
                PlaySimulation(app);
            else
                app.Play = false;
                app.PLAYButton.Text = "PLAY";
                disp("PAUSE button pushed")
            end
        end

        % Button pushed function: ResetButton
        function ResetSim(app, event)
            % Reset Voltages and currents:
            app.v = zeros(1,app.kmax);
            app.i = zeros(1, app.kmax - 1);
            
            % Plot reset values:
            set(app.lineVIncidentPlot, 'ydata', app.v);
            pause(0);
            drawnow limitrate;

            % Reset counters
            disp(app.n);
            app.n = 1;
            app.m = 1;
        end

        % Value changed function: EnvelopeCheckBox
        function EnvelopeCheckBoxValueChanged(app, event)
            if app.EnvelopeCheckBox.Value
                % Delete prior envelope plot:
                delete(app.lineVEnvelopePlot);

                % Calculate the new envelopeYData:
                envelopeYData = exp(-app.alpha.*app.z_voltage);
    
                % Plot the new envelopeData:
                app.lineVEnvelopePlot = plot(app.lineVIncident, app.z_voltage, envelopeYData);
            else
                set(app.lineVEnvelopePlot,'Visible', 'off');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Callback function: not associated with a component
        function FrequencylogfHzSliderValueChanged(app, event)
            % Get the slider value
            sliderValue = app.FrequencylogfHzSlider.Value;

            % Convert the slider's linear value to a logarithmic scale
            logValue = 10^sliderValue;  % Converts log10 slider value to actual scale

            % Update frequency with log value:
            app.freq = logValue;

            % Update Frequency label:
            frequencyString = formatFrequency(app, app.freq);
            app.Frequency1GHzLabel.Text = "Frequency: " + frequencyString;

            % Recalculate RLGC and FDTD constants:

            % Calculate RLGC parameters
            app.Rs = sqrt(pi * app.freq * app.mu_cond / app.sigma_cond);
            app.Rprime = (app.Rs / (2 * pi)) * ((1 / app.a) + (1 / app.b));
            app.Lprime = (app.mu_ins / (2 * pi)) * log(app.b / app.a);
            app.Gprime = (2 * pi * app.sigma_ins) / log(app.b / app.a);
            app.Cprime = (2 * pi * app.epsr_ins * app.eps_0) / log(app.b / app.a);

            % Calculate angular frequency
            app.w = 2 * pi * app.freq;

            % Propagation constant
            app.gamma = sqrt((app.Rprime + 1i * app.w * app.Lprime) * (app.Gprime + 1i * app.w * app.Cprime));

            % Attenuation constant alpha:
            app.alpha = real(app.gamma);

            % Phase constant Beta
            app.Beta = imag(app.gamma);

            % Wave propagation speed
            app.up = app.w / imag(app.gamma);

            % Time step incrementapp NEEDS TO BE LESS THAN EXACT VALUE
            % otherwise the simulation blows up
            app.dt = app.A * app.dz / app.up;

            % FDTD coefficients ALL CHECKED. 
            app.c1 = -(2 * app.dt) / (app.dt * app.dz * app.Rprime + 2 * app.dz * app.Lprime);
            app.c2 = (2 * app.Lprime - app.dt * app.Rprime) / (2 * app.Lprime + app.dt * app.Rprime);
            app.c3 = -(2 * app.dt) / ((app.dt * app.dz * app.Gprime) + (2 * app.dz * app.Cprime));
            app.c4 = (2 * app.Cprime - app.dt * app.Gprime) / (2 * app.Cprime + app.dt * app.Gprime);

            % Transmission Load parameters
            app.Z0 = sqrt((app.Rprime + 1i * app.w * app.Lprime) / (app.Gprime + 1i * app.w * app.Cprime));

            % Redraw the envelope if check box is on:
            if app.EnvelopeCheckBox.Value
                EnvelopeCheckBoxValueChanged(app);
            end
        end

        % Callback function: not associated with a component
        function ConductivitylogSmSliderValueChanged(app, event)
            value = app.ConductivitylogSmSlider.Value;

            % Convert the slider's linear value to a logarithmic scale
            logValue = 10^value;

            % Update Conductivity text:
            sigmaString = toScientificNotation(app, logValue);
            app.ConductivityLabel.Text = "Conductivity: " + sigmaString + " S/m";

            % Update conductivityu with log value:
            app.sigma_cond = logValue;

            % Recalculate RLGC and FDTD constants:

            % Calculate RLGC parameters
            app.Rs = sqrt(pi * app.freq * app.mu_cond / app.sigma_cond);
            app.Rprime = (app.Rs / (2 * pi)) * ((1 / app.a) + (1 / app.b));
            app.Lprime = (app.mu_ins / (2 * pi)) * log(app.b / app.a);
            app.Gprime = (2 * pi * app.sigma_ins) / log(app.b / app.a);
            app.Cprime = (2 * pi * app.epsr_ins * app.eps_0) / log(app.b / app.a);

            % Calculate angular frequency
            app.w = 2 * pi * app.freq;

            % Propagation constant
            app.gamma = sqrt((app.Rprime + 1i * app.w * app.Lprime) * (app.Gprime + 1i * app.w * app.Cprime));

            % Attenuation constant alpha:
            app.alpha = real(app.gamma);

            % Phase constant Beta
            app.Beta = imag(app.gamma);

            % Wave propagation speed
            app.up = app.w / imag(app.gamma);

            % Time step incrementapp NEEDS TO BE LESS THAN EXACT VALUE
            % otherwise the simulation blows up
            app.dt = app.A * app.dz / app.up;

            % FDTD coefficients ALL CHECKED.
            app.c1 = -(2 * app.dt) / (app.dt * app.dz * app.Rprime + 2 * app.dz * app.Lprime);
            app.c2 = (2 * app.Lprime - app.dt * app.Rprime) / (2 * app.Lprime + app.dt * app.Rprime);
            app.c3 = -(2 * app.dt) / ((app.dt * app.dz * app.Gprime) + (2 * app.dz * app.Cprime));
            app.c4 = (2 * app.Cprime - app.dt * app.Gprime) / (2 * app.Cprime + app.dt * app.Gprime);

            % Transmission Load parameters
            app.Z0 = sqrt((app.Rprime + 1i * app.w * app.Lprime) / (app.Gprime + 1i * app.w * app.Cprime));

            % redraw envelope if check box is on:
            if app.EnvelopeCheckBox.Value
                EnvelopeCheckBoxValueChanged(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.lineVIncident = uiaxes(app.GridLayout);
            title(app.lineVIncident, 'Incident Wave')
            xlabel(app.lineVIncident, 'Position z (m)')
            ylabel(app.lineVIncident, 'Incident (V)')
            zlabel(app.lineVIncident, 'Z')
            app.lineVIncident.YLim = [-1 1];
            app.lineVIncident.XTickLabel = {'1'; '0.9'; '0.8'; '0.7'; '0.6'; '0.5'; '0.4'; '0.3'; '0.2'; '0.1'; '0'};
            app.Title = uilabel(app.GridLayout);
            app.Title.Text = 'Attenuating Wave';
            app.PLAYButton = uibutton(app.GridLayout, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PausePlaySimulation, true);
            app.PLAYButton.Text = 'PLAY';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSim, true);
            app.ResetButton.Text = 'Reset';
            app.EnvelopeCheckBox = uicheckbox(app.GridLayout);
            app.EnvelopeCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnvelopeCheckBoxValueChanged, true);
            app.EnvelopeCheckBox.Text = 'Envelope';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AttenuationSimulationStill

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end