classdef FourierSeriesStills < matlab.apps.AppBase
    %FOURIERSERIESSTILLS Fourier Series Graphic Calculator (Stills)
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2024
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia Furse
    %   (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   Edited from Fourier Series Graphic Calculator to make still for the
    %   textbook. 

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        EquationLabel       matlab.ui.control.Label
        FourierSeriesLabel  matlab.ui.control.Label
        FourierSum          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FourierSumFunction% Function for the Fourier Sum
        f = 1 % Spatial Frequency of Square Wave
        n = 1 % Number of sine waves
        FourierSumPlot % Plot handle for the Fourier Sum
        PlotHandles = []  % Stores the most recently added plot handle
    end
    
    methods (Access = private)
        
        function PlotFourierSum(app, n)
            syms x
            app.FourierSumFunction = 0; % Reset equation for recalculation
            delete(app.FourierSumPlot); % Reset Fourier Sum

            % Update FourierSumFunction: (SQUARE WAVE)
            for k = 1:n
               % Plot the Fourier Sum:
               app.FourierSumFunction = app.FourierSumFunction + (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1);
            end

            % Plot the Fourier Sum function:
            app.FourierSumPlot = fplot(app.FourierSum, app.FourierSumFunction, 'k');
            drawnow limitrate

            % Plot individual sine waves:
            % Ensures quick slider movements are accounted for. 
            numPlots = length(app.SineWaves.Children); % Get the number of plots currently on the Axes Object
            sliderDiff = n - numPlots;  % Difference between current slider n and number of plots
            if sliderDiff > 0
                for k = numPlots+1:n
                    app.PlotHandles(end+1) = fplot(app.SineWaves, (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1));
                end
            elseif sliderDiff < 0
                for k = n+1:numPlots
                    delete(app.PlotHandles(end));
                    app.PlotHandles(end) = [];
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.FourierSum, 'on') % Ensures the +-1 reference is on Fourier Sum plot

            % Plot the first sine wave and Fourier Series:
            syms x
            k = 1;

            % Plot reference points:
            fplot(app.FourierSum, 1, 'k:')
            fplot(app.FourierSum, -1, 'k:')

            % Color definitions for contrast and universal accessibility
            darkBlue = [0.00, 0.45, 0.70];   % #0072B2
            tealGreen = [0.00, 0.62, 0.45];  % #009E73
            goldenYellow = [0.94, 0.89, 0.26]; % #F0E442
            warmOrange = [0.90, 0.62, 0.00]; % #E69F00


            % Plot initial Fourier Series n = 1:
            app.FourierSumFunction = (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1);
            app.FourierSumPlot = fplot(app.FourierSum, app.FourierSumFunction, 'Color', darkBlue);

            % n = 5
            app.n = 5;
            app.FourierSumFunction = 0; % Reset function
            for k = 1:app.n
                % Plot the Fourier Sum:
                app.FourierSumFunction = app.FourierSumFunction + (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1);
            end
            secondPlot = fplot(app.FourierSum, app.FourierSumFunction, 'Color', tealGreen);

            % n = 100
            app.n = 100;
            app.FourierSumFunction = 0; % Reset function
            for k = 1:app.n
                % Plot the Fourier Sum:
                app.FourierSumFunction = app.FourierSumFunction + (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1);
            end
            thirdPlot = fplot(app.FourierSum, app.FourierSumFunction, 'Color', warmOrange);

            legend([app.FourierSumPlot, secondPlot, thirdPlot], 'n = 1', 'n = 5', 'n = 100')

            drawnow

        end

        % Callback function: not associated with a component
        function NumberofsinewavesnSliderValueChanging(app, event)
            changingValue = event.Value;

            % Only plot the Fourier Sum if the slider has approached
            % another integer
            if round(changingValue) ~= app.n
                app.n = round(changingValue);
                PlotFourierSum(app, app.n);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FourierSum = uiaxes(app.GridLayout);
            title(app.FourierSum, 'Sum of Sine Waves')
            xlabel(app.FourierSum, 'x')
            ylabel(app.FourierSum, 'f(x)')
            zlabel(app.FourierSum, 'Z')
            app.FourierSum.XLim = [0 2];
            app.FourierSum.YLim = [-1.5 1.5];
            app.FourierSeriesLabel = uilabel(app.GridLayout);
            app.FourierSeriesLabel.Text = 'Fourier Series';
            app.EquationLabel = uilabel(app.GridLayout);
            app.EquationLabel.Interpreter = 'latex';
            app.EquationLabel.Text = '$$ f(x) = \frac{4}{\pi} \displaystyle\sum_{n=0}^{\infty} \frac{1}{2n+1} \sin\left((2n+1) \cdot 2\pi \cdot x\right) $$';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierSeriesStills

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end