classdef FourierMovie < matlab.apps.AppBase
    %FOURIERMOVIE Fourier Series Graphic Calculator
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2024
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia Furse
    %   (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   Showcases a Fourier series where the number of terms is controlled
    %   by the user. The upper plot shows the actual sum of terms and the
    %   bottom plot overlaps the individual sine waves in the series.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        NLabel              matlab.ui.control.Label
        EquationLabel       matlab.ui.control.Label
        FourierSeriesLabel  matlab.ui.control.Label
        FourierSum          matlab.ui.control.UIAxes
        SineWaves           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FourierSumFunction% Function for the Fourier Sum
        f = 1 % Spatial Frequency of Square Wave
        n = 1 % Number of sine waves
        FourierSumPlot % Plot handle for the Fourier Sum
        PlotHandles = []  % Stores the most recently added plot handle

        video  % Video Object for recording
    end
    
    methods (Access = private)
        
        function PlotFourierSum(app, n)
            syms x
            app.FourierSumFunction = 0; % Reset equation for recalculation
            delete(app.FourierSumPlot); % Reset Fourier Sum

            % Update FourierSumFunction: (SQUARE WAVE)
            for k = 1:n
               % Plot the Fourier Sum:
               app.FourierSumFunction = app.FourierSumFunction + (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1);
            end

            % Plot the Fourier Sum function:
            app.FourierSumPlot = fplot(app.FourierSum, app.FourierSumFunction, 'k');
            drawnow limitrate

            % Plot individual sine waves:
            % Ensures quick slider movements are accounted for. 
            numPlots = length(app.SineWaves.Children); % Get the number of plots currently on the Axes Object
            sliderDiff = n - numPlots;  % Difference between current slider n and number of plots
            if sliderDiff > 0
                for k = numPlots+1:n
                    app.PlotHandles(end+1) = fplot(app.SineWaves, (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1), 'MeshDensity', 500);
                end
            elseif sliderDiff < 0
                for k = n+1:numPlots
                    delete(app.PlotHandles(end));
                    app.PlotHandles(end) = [];
                end
            end

        end


        % Returns a corresponding number of frames for value 'x'. The
        % number of frames corresponds to an exponential function and is
        % dictated by ceil() and the starting frame rate per value 'x' and
        % ending frame rate for value 'x'.
        function y = frame_function(~, x, starting_frame_rate, ending_frame_rate, initial_n, final_n)
            syms A r
            eq1 = starting_frame_rate == A*exp(r*initial_n);
            eq2 = ending_frame_rate == A*exp(r*final_n);

            sol = vpasolve([eq1 eq2], [A, r]);
            A_val = double(sol.A)
            r_val = double(sol.r)
            
            y = ceil(A_val*exp(r_val*x));
        end
        
        % Creates a clip of several sine waves adding together to make a
        % square wave. Less time is attributed to higher n values in an
        % exponential fashion dictated by frame_function.
        function RecordVideo(app)
            % Establish recording parameters:
            % Define the name and format of the output video file
            app.video = VideoWriter('FourierMovie.mp4', 'MPEG-4');

            % Set the frame rate and video quality
            app.video.FrameRate = 30;
            app.video.Quality = 100;

            % Open the video writer
            open(app.video);

            n_start = 1;  % The starting n value for the movie
            n_end = 100;  % The ending n value for the movie

            hold_time_end = 5;  % how long the movie will hold at n_end
            hold_time_start = 1;  % How long the movie will hold at n_start
            hold_frames_end = hold_time_end * 30;  % The number of frames the movie will hold at n_end
            hold_frames_start = hold_time_start * 30;  % The number of frames the movie will hold at n_start

            % Hold the video on n_start for 1 second:
            for i = 1:hold_frames_start
                PlotFourierSum(app, 1)  % Plots the first sine wave
                frame = getframe(app.UIFigure);
                writeVideo(app.video, frame);
            end

            % This loop records all the n values going up. Accelerates in
            % exponential fashion
            for i = n_start:n_end
                app.n = i;
                PlotFourierSum(app, app.n)
                app.NLabel.Text = "Number of sine waves (n) = " + i;
                drawnow

                % VIDEO CAPTURING
                for x = 1:frame_function(app, i, 25, 1, n_start, n_end - 50)    
                    frame = getframe(app.UIFigure);
                    writeVideo(app.video,frame);
                end
            end

            % Hold the video on the n_end value:
            for i = 1:hold_frames_end
                frame = getframe(app.UIFigure);
                writeVideo(app.video, frame);
            end

            close(app.video)
            delete(app.UIFigure)

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.SineWaves, 'on') % Ensures multiple sine waves can be on the graph
            hold(app.FourierSum, 'on') % Ensures the +-1 reference is on Fourier Sum plot

            % Plot the first sine wave and Fourier Series:
            syms x
            k = 1;

            % Plot initial Fourier Series:
            app.FourierSumFunction = (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1);
            app.FourierSumPlot = fplot(app.FourierSum, app.FourierSumFunction, 'k');

            % Plot reference points:
            fplot(app.FourierSum, 1, 'k:')
            fplot(app.FourierSum, -1, 'k:')

            % Keep track of sine wave plot handles:
            app.PlotHandles(end+1) = fplot(app.SineWaves, (4/pi)*sin(2*pi*(2*k-1)* app.f *x)/(2*k-1), 'MeshDensity', 500);

            drawnow

            RecordVideo(app)


        end

        % Callback function
        function NumberofsinewavesnSliderValueChanging(app, event)
            changingValue = event.Value;

            % Only plot the Fourier Sum if the slider has approached
            % another integer
            if round(changingValue) ~= app.n
                app.n = round(changingValue);
                PlotFourierSum(app, app.n);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SineWaves = uiaxes(app.GridLayout);
            title(app.SineWaves, 'Sine Waves')
            xlabel(app.SineWaves, 'x')
            ylabel(app.SineWaves, 'f(x)')
            zlabel(app.SineWaves, 'Z')
            app.SineWaves.XLim = [0 2];
            app.SineWaves.YLim = [-1.5 1.5];
            app.FourierSum = uiaxes(app.GridLayout);
            title(app.FourierSum, 'Sum of Sine Waves')
            xlabel(app.FourierSum, 'x')
            ylabel(app.FourierSum, 'f(x)')
            zlabel(app.FourierSum, 'Z')
            app.FourierSum.XLim = [0 2];
            app.FourierSum.YLim = [-1.5 1.5];
            app.FourierSeriesLabel = uilabel(app.GridLayout);
            app.FourierSeriesLabel.Text = 'Fourier Series';
            app.EquationLabel = uilabel(app.GridLayout);
            app.EquationLabel.Interpreter = 'latex';
            app.EquationLabel.Text = '$$ f(x) = \frac{4}{\pi} \displaystyle\sum_{n=0}^{\infty} \frac{1}{2n+1} \sin\left((2n+1) \cdot 2\pi \cdot x\right) $$';
            app.NLabel = uilabel(app.GridLayout);
            app.NLabel.Text = 'Number of sine waves (n) = 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierMovie

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end