classdef MovieSinusoidLoadSim < matlab.apps.AppBase
    %MOVIESINUSOIDLOADSIM Simulates a transmission line with varying loads
    %   CC BY-SA (Attribution Sharealike) Carsen Villegas 2024
    %   Written by Carsen Villegas (carsen3117@gmail.com) for Cynthia
    %   Furse (cynthia.furse@utah.edu)
    %   University of Utah Electrical and Computer Engineering
    %   
    %   TODO : names to add : Shaun Mckellar, Richard Nagel, James

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        Image2          matlab.ui.control.Image
        Image           matlab.ui.control.Image
        Label           matlab.ui.control.Label
        Title           matlab.ui.control.Label
        lineVReflected  matlab.ui.control.UIAxes
        lineV           matlab.ui.control.UIAxes
        lineVIncident   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Physical Constants
        eps_0 = 8.85418782E-12;  %  Permittivity of free space (F/m).
        mu_0 = 4.0*pi*1E-7;      %  Permeability of free space (H/m).

        % Simulation parameters
        dz = 0.002;                % Grid resolution (m)
        kmax = 500;              % Number of grid points to model 1 m of transmission line

        % Arrays for wave data

        % Incident wave:
        v
        i

        % Reflected wave data set
        v_reflected
        i_reflected

        % Evenlope and total wave data set
        v_total
        envelope_data

        % Source parameters
        Vg = 1.0;                   % Amplitude (V)
        freq = 1e9;                 % Frequency for the source 1 GHz

        % Transmission line parameters for RG-58
        b   = (3.51e-3)/2;       % Outer conductor radius (m)
        a   = (0.91e-3)/2;       % Inner conductor radius (m)

        % Conductor information (copper)
        sigma_cond = 5.797*1E7; % Conductivity (S/m) of copper
        epsr_cond = 1.0;        % Relative permittivity of copper
        mu_cond = 4.0*pi*1E-7   % Permeability of copper

        % Insulator (dielectric) information (polyethylene)
        sigma_ins = 0.0001;     % Conductivity (S/m)
        epsr_ins = 2.25;        % Relative permittivity
        mu_ins = 4.0*pi*1E-7;          % Permeability of the insulator

        % RLGC constants of coaxial cable:
        Rs
        Rprime
        Lprime
        Gprime
        Cprime

        % Angular frequency
        w

        % Prop. constant
        gamma

        % Phase constant
        Beta

        % Wave propagation speed
        up

        % Time step increment
        dt

        % FDTD coefficients:
        c1
        c2
        c3
        c4

        % Transmission Line Load Parameters
        Z0

        % Simulation Control
        Play = false; % State of the simulation (boolean)
        n = 1;  % Simulation counter
        m = 1;  % Enveloper counter
        load = 'MATCHED';  % Load type

        % Plotting
        T_fr = 1;  % Time steps per plot update
        z_voltage
        z_current

        lineVIncidentPlot
        lineVReflectedPlot
        lineVPlot
        lineVEnvelopePlot
        currentEnvelope % Holds the data to plot the current envelope
        reflectionCoefficient % the current reflection coefficient

        % Video writing:
        video
    end
    
    methods (Access = private)
        
        % Runs the simulation
        function PlaySimulation(app)

            while true

                % Simulation control
                if ~app.Play
                    break;
                end

                % Frequency of the source
                app.v(1) = sin(app.n * app.dt * 1e9 * 2 * pi);

                % Right End Boundary Condition (MATCHED for "INCIDENT")
                app.v(app.kmax) = abs(app.Z0) * app.i(app.kmax - 1);
                app.i(app.kmax - 1) = app.v(app.kmax) / abs(app.Z0);

                % Left End boundary Condition (MATCHED for "REFLECTED")
                app.v_reflected(1) = app.v_reflected(2) - abs(app.Z0) * (app.i_reflected(1) - app.i_reflected(2));

                % Vectorized current update
                k_range = 1:app.kmax - 1;
                
                app.i(k_range) = app.c1 * (app.v(k_range + 1) - app.v(k_range)) + app.c2 * app.i(k_range);
                app.i_reflected(k_range) = app.c1 * (app.v_reflected(k_range + 1) - app.v_reflected(k_range)) + app.c2 * app.i_reflected(k_range);

                % Vectorized voltage update TODO delete?
                k_range = 2:app.kmax - 1;
                
                app.v(k_range) = app.c3 * (app.i(k_range) - app.i(k_range - 1)) + app.c4 * app.v(k_range);
                app.v_reflected(k_range) = app.c3 * (app.i_reflected(k_range) - app.i_reflected(k_range - 1)) + app.c4 * app.v_reflected(k_range);


                endValue = app.v(app.kmax);
                % Reflected wave boundary condition
                app.v_reflected(app.kmax) = app.vReflectedHandling(app.load, endValue); % update boundary according to boundary conditions

                app.v_total(:) = app.v + app.v_reflected;

                % n = 2000 for incident and reflected wave to reach steady state
                % n = 1000 for a wave to reach the other end


                % Graphics updating loop
                if mod(app.n, app.T_fr) == 0

                    % TODO see if animated line is faster
                    %                     % Update plots:
                    set(app.lineVIncidentPlot, 'ydata', app.v);
                    set(app.lineVReflectedPlot, 'ydata', app.v_reflected);
                    set(app.lineVPlot, 'ydata', app.v_total);

                    % clearpoints(app.lineVIncidentPlot);
                    % clearpoints(app.lineVReflectedPlot);
                    % clearpoints(app.lineVPlot);
                    %
                    % addpoints(app.lineVIncidentPlot, app.z_voltage, app.v);
                    % addpoints(app.lineVReflectedPlot, app.z_voltage, app.v_reflected);
                    % addpoints(app.lineVPlot, app.z_voltage, app.v_total);

                    % VIDEO CAPTURING
                    pause(0)  % TODO delete?
                    frame = getframe(app.UIFigure);
                    writeVideo(app.video,frame);

                    drawnow limitrate;
                end
                app.n = app.n + 1;  % Simulation counter
                app.m = app.m + 1;  % Envelope counter
                pause(0);

                % ENVELOPE TURNS ON CONDITION:
                if app.n == 2400
                    set(app.lineVEnvelopePlot, 'ydata', app.currentEnvelope, 'visible', 'on');
                end

                % 4800 is for 2m of video
                % VIDEO ENDING CONDITION
                if app.n == 4800
                    close(app.video);
                    beep;
                    break
                end
            end
        end

        function initializeProperties(app)
            % Initialize variables dependent on kmax

            % Incident wave data set:
            app.v = zeros(1, app.kmax);
            app.i = zeros(1, app.kmax - 1);

            % Reflected wave data set:
            app.v_reflected = zeros(1, app.kmax);
            app.i_reflected = zeros(1, app.kmax - 1);

            % Evenlope and total wave data set
            app.v_total = zeros(1, app.kmax);
            app.envelope_data = zeros(1, app.kmax);

            app.currentEnvelope = zeros(1, app.kmax);  % TODO

            % Calculate RLGC parameters
            app.Rs = sqrt(pi * app.freq * app.mu_cond / app.sigma_cond);
            app.Rprime = (app.Rs / (2 * pi)) * ((1 / app.a) + (1 / app.b));
            app.Lprime = (app.mu_ins / (2 * pi)) * log(app.b / app.a);
            app.Gprime = (2 * pi * app.sigma_ins) / log(app.b / app.a);
            app.Cprime = (2 * pi * app.epsr_ins * app.eps_0) / log(app.b / app.a);

            % Calculate angular frequency
            app.w = 2 * pi * app.freq;

            % Propagation constant
            app.gamma = sqrt((app.Rprime + 1i * app.w * app.Lprime) * (app.Gprime + 1i * app.w * app.Cprime));

            % Phase constant Beta
            app.Beta = imag(app.gamma);

            % Wave propagation speed
            app.up = app.w / imag(app.gamma);

            % Time step increment
            app.dt = app.dz / app.up;

            % FDTD coefficients
            app.c1 = -(2 * app.dt) / (app.dt * app.dz * app.Rprime + 2 * app.dz * app.Lprime);
            app.c2 = (2 * app.Lprime - app.dt * app.Rprime) / (2 * app.Lprime + app.dt * app.Rprime);
            app.c3 = -(2 * app.dt) / (app.dt * app.dz * app.Gprime + 2 * app.dz * app.Cprime);
            app.c4 = (2 * app.Cprime - app.dt * app.Gprime) / (2 * app.Cprime + app.dt * app.Gprime);

            % Transmission Load parameters
            app.Z0 = sqrt((app.Rprime + 1i * app.w * app.Lprime) / (app.Gprime + 1i * app.w * app.Cprime));

            % Plotting:
            app.z_voltage  = (0:app.kmax-1) * app.dz;
            app.z_current  = (0:app.kmax-2) * app.dz + app.dz / 2;
        end

        function vReflected = vReflectedHandling(app, loadType, incidentVoltage)

            switch loadType
                case 'MATCHED'
                    % Matched Load: vReflected should be 0 (no reflection)
                    vReflected = 0;

                case 'OPEN'
                    % Open Load: vReflected should be 0 (open circuit)
                    vReflected = 1 * incidentVoltage; 

                case 'SHORT'
                    % Short Load: vReflected should be -i * Z0 (short circuit reflection)
                    vReflected = -1 * incidentVoltage;

                case 'RESISTIVE (27 Ω)'
                    % Resistive Load: Example resistance value
                    vReflected = .33 * incidentVoltage;

                otherwise
                    % Handle unknown load types
                    error('Unknown load type: %s', loadType);
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the variable in the app
            initializeProperties(app)

            % Initialize the graphs to be zero:
            yData = zeros(1, length(app.z_voltage));

            % TODO evaluated animated line efficiency
            app.lineVIncidentPlot = plot(app.lineVIncident, app.z_voltage, yData, 'Color', 'b', 'linewidth', 2);
            app.lineVReflectedPlot = plot(app.lineVReflected, app.z_voltage, yData, 'Color', 'r', 'linewidth', 2);
            hold(app.lineV, 'on')
            app.lineVPlot = plot(app.lineV, app.z_voltage, yData, 'Color', 'k', 'linewidth', 2);

            % Initialize the Envelope:
            app.reflectionCoefficient = 0;  % app starts in MATCHED condition
            app.currentEnvelope = (1) .* sqrt(1 + app.reflectionCoefficient^2 + 2.*app.reflectionCoefficient.*cos(2 .* app.Beta .* app.z_voltage));
            app.lineVEnvelopePlot = plot(app.lineV, app.z_voltage, app.currentEnvelope, 'Color', 'k', 'linewidth', 2, 'LineStyle', ':', 'visible', 'off');
            drawnow;

            % Establish recording parameters:
            % Define the name and format of the output video file
            app.video = VideoWriter('ShortLoadMovie.mp4', 'MPEG-4');

            % Set the frame rate and video quality
            app.video.FrameRate = 40;
            app.video.Quality = 100;

            % Open the video writer
            open(app.video);

            % START SIMULATION:
            app.Play = true;
            app.load = 'SHORT';
            app.Title.Text = "Transmission for " + app.load + " Load (1GHz)";

            switch app.load
                case 'MATCHED'
                    % Matched Load: vReflected should be 0 (no reflection)
                    app.reflectionCoefficient = 0;

                case 'OPEN'
                    % Open Load: vReflected should be 0 (open circuit)
                    app.reflectionCoefficient = 1;

                case 'SHORT'
                    % Short Load: vReflected should be -i * Z0 (short circuit reflection)
                    app.reflectionCoefficient = -1;

                case 'RESISTIVE (27 Ω)'
                    % Resistive Load: Example resistance value
                    app.reflectionCoefficient = .33;

                otherwise
                    % Handle unknown load types
                    error('Unknown load type: %s', loadType);
            end
 
            % Update envelope data
            app.currentEnvelope = (1) .* sqrt(1 + app.reflectionCoefficient^2 + 2.*app.reflectionCoefficient.*cos(2 .* app.Beta .* app.z_voltage));

            % PLAY THE SIMULATION
            PlaySimulation(app);
            
        end

        % Callback function
        function PausePlaySimulation(app, event)
            if ~app.Play
                app.Play = true;
                disp("PLAY button pushed!")
                PlaySimulation(app);
            else
                app.Play = false;
                disp("PAUSE button pushed")
                close(app.video);
            end
        end

        % Callback function
        function ResetSim(app, event)
            app.v = zeros(1,app.kmax);
            app.i = zeros(1, app.kmax - 1);
            app.v_reflected = zeros(1, app.kmax);
            app.i_reflected = zeros(1, app.kmax - 1);

            % Reset counters
            app.n = 1;
            app.m = 1;
        end

        % Callback function
        function ChangeLoad(app, event)
            value = app.LoadTypeDropDown.Value;
            app.load = value;
            app.Title.Text = "Transmission for " + value + " Load (1GHz)";

            switch value
                case 'MATCHED'
                    % Matched Load: vReflected should be 0 (no reflection)
                    app.reflectionCoefficient = 0;

                case 'OPEN'
                    % Open Load: vReflected should be 0 (open circuit)
                    app.reflectionCoefficient = 1;

                case 'SHORT'
                    % Short Load: vReflected should be -i * Z0 (short circuit reflection)
                    app.reflectionCoefficient = -1;

                case 'RESISTIVE (27 Ω)'
                    % Resistive Load: Example resistance value
                    app.reflectionCoefficient = .33;

                otherwise
                    % Handle unknown load types
                    error('Unknown load type: %s', loadType);
            end
 
            % Update envelope data
            app.currentEnvelope = (1) .* sqrt(1 + app.reflectionCoefficient^2 + 2.*app.reflectionCoefficient.*cos(2 .* app.Beta .* app.z_voltage));

            % Update plot if the Envelope check box is 'on'
            EnvelopeCheckBoxValueChanged(app);
        end

        % Callback function
        function EnvelopeCheckBoxValueChanged(app, event)
            % TODO : delete lineVEnvelopePlot
            
            value = app.EnvelopeCheckBox.Value;

            if value
                set(app.lineVEnvelopePlot, 'ydata', app.currentEnvelope, 'visible', 'on');
            else
                set(app.lineVEnvelopePlot, 'visible', 'off');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.lineVIncident = uiaxes(app.GridLayout);
            title(app.lineVIncident, 'Incident Wave')
            xlabel(app.lineVIncident, 'Position (m)')
            ylabel(app.lineVIncident, 'Incident (V)')
            zlabel(app.lineVIncident, 'Z')
            app.lineVIncident.YLim = [-1 1];
            app.lineVIncident.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.lineVIncident.XTickLabel = {'1'; '0.9'; '0.8'; '0.7'; '0.6'; '0.5'; '0.4'; '0.3'; '0.2'; '0.1'; '0'};
            app.lineV = uiaxes(app.GridLayout);
            title(app.lineV, 'Incident + Reflected')
            xlabel(app.lineV, 'Position (m)')
            ylabel(app.lineV, 'Total (V)')
            zlabel(app.lineV, 'Z')
            app.lineV.YLim = [-2 2];
            app.lineV.XTickLabel = {'1'; '0.9'; '0.8'; '0.7'; '0.6'; '0.5'; '0.4'; '0.3'; '0.2'; '0.1'; '0'};
            app.lineVReflected = uiaxes(app.GridLayout);
            title(app.lineVReflected, 'Reflected Wave')
            xlabel(app.lineVReflected, 'Position (m)')
            ylabel(app.lineVReflected, 'Reflected (V)')
            zlabel(app.lineVReflected, 'Z')
            app.lineVReflected.YLim = [-1 1];
            app.lineVReflected.XTickLabel = {'1'; '0.9'; '0.8'; '0.7'; '0.6'; '0.5'; '0.4'; '0.3'; '0.2'; '0.1'; '0'};
            app.Title = uilabel(app.GridLayout);
            app.Title.Text = 'Transmission for MATCHED Load (1GHz)';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Resistor.png';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = 'ACVoltage.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MovieSinusoidLoadSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end